classdef SLOSH_ML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLOSHMLv0103UIFigure            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        VersionMenu                     matlab.ui.container.Menu
        WelcomeImage                    matlab.ui.control.Image
        DisplayMechanicalPanel          matlab.ui.container.Panel
        MechanicalAnalogyCountSpinner   matlab.ui.control.Spinner
        NumberofModesSpinner_2Label_2   matlab.ui.control.Label
        MechanicalAnalogyDropDown       matlab.ui.control.DropDown
        MechanicalAnalogyDropDownLabel  matlab.ui.control.Label
        DisplayModesPanel               matlab.ui.container.Panel
        NormalizeCheckBox               matlab.ui.control.CheckBox
        Label_9                         matlab.ui.control.Label
        MultiplicationFactorEditField   matlab.ui.control.NumericEditField
        MultiplicationFactorLabel       matlab.ui.control.Label
        NumberofModesSpinner            matlab.ui.control.Spinner
        NumberofModesSpinner_2Label     matlab.ui.control.Label
        DisplayGeometryPanel            matlab.ui.container.Panel
        LiquidFillLineCheckBox          matlab.ui.control.CheckBox
        CenterofMassCheckBox            matlab.ui.control.CheckBox
        TankGeometryCheckBox            matlab.ui.control.CheckBox
        CALCULATE                       matlab.ui.control.Button
        OutputTabGroup                  matlab.ui.container.TabGroup
        BulkOutputTab                   matlab.ui.container.Tab
        msLabel                         matlab.ui.control.Label
        VcEditField                     matlab.ui.control.NumericEditField
        VcEditFieldLabel                matlab.ui.control.Label
        FrEditField                     matlab.ui.control.NumericEditField
        FrEditFieldLabel                matlab.ui.control.Label
        BoEditField                     matlab.ui.control.NumericEditField
        BoEditFieldLabel                matlab.ui.control.Label
        WeEditField                     matlab.ui.control.NumericEditField
        WeEditFieldLabel                matlab.ui.control.Label
        RegimeLabel                     matlab.ui.control.Label
        NaturalFrequenciesLabel         matlab.ui.control.Label
        EigenvectorsLabel               matlab.ui.control.Label
        freqtable                       matlab.ui.control.Table
        eigfntable                      matlab.ui.control.Table
        ModesTab                        matlab.ui.container.Tab
        RadsLabel                       matlab.ui.control.Label
        modes_nondfreq                  matlab.ui.control.Label
        modes_natfreq                   matlab.ui.control.Label
        NondimFrequencyLabel            matlab.ui.control.Label
        NaturalFrequencyLabel           matlab.ui.control.Label
        ModeSpinner                     matlab.ui.control.Spinner
        ModeLabel                       matlab.ui.control.Label
        ModalCoefficientsLabel          matlab.ui.control.Label
        freqtable_2                     matlab.ui.control.Table
        PendulumTab                     matlab.ui.container.Tab
        PendulumTable                   matlab.ui.control.Table
        SpringTab                       matlab.ui.container.Tab
        SpringTable                     matlab.ui.control.Table
        Tab                             matlab.ui.container.Tab
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        ModesButton                     matlab.ui.control.ToggleButton
        MechanicalButton                matlab.ui.control.ToggleButton
        GeometryButton                  matlab.ui.control.ToggleButton
        SettingsTabGroup                matlab.ui.container.TabGroup
        GeometryTab                     matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ZF                              matlab.ui.control.Spinner
        ZFactorLabel                    matlab.ui.control.Label
        RF                              matlab.ui.control.Spinner
        RFactorLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LiquidFillRatio                 matlab.ui.control.Spinner
        FillRatioLabel                  matlab.ui.control.Label
        LoadContourButton               matlab.ui.control.Button
        PhysicsTab                      matlab.ui.container.Tab
        rhoEditField                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Kgm3Label                       matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        mNmLabel                        matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        gEditField                      matlab.ui.control.NumericEditField
        gEditFieldLabel                 matlab.ui.control.Label
        ParamAnTab                      matlab.ui.container.Tab
        ParametricAnalysisExportButton  matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        InputfilewithaccelerationandfillingratiodataLabel  matlab.ui.control.Label
        ParametricAnalysisComputeButton  matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        RestoreDefaultSettingsButton    matlab.ui.control.Button
        SpringModeSpinner               matlab.ui.control.Spinner
        ShowModesLabel                  matlab.ui.control.Label
        nSpinner                        matlab.ui.control.Spinner
        nSpinnerLabel                   matlab.ui.control.Label
        MSpinner                        matlab.ui.control.Spinner
        MSpinnerLabel                   matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        NSpinnerLabel                   matlab.ui.control.Label
        mainaxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % contours
        IC_original         % inner contour function handle, original contour file scale and origin
        OC_original         % outer contour function handle, original contour file scale and origin
        IC_scaled_origin    % inner contour, scaled, centered at file origin
        OC_scaled_origin    % outer contour, scaled, centered at file origin
        IC_scaled_CM        % inner contour, scaled, centered at CM
        OC_scaled_CM        % outer contour, scaled, centered at CM
        IC_dimensionless    % Dimensionless inner contour function handle
        OC_dimensionless    % Dimensionless outer contour function handle

        % geometry
        bIC                 % Boundaries of IC data ([rmin,rmax,zmin,zmax])
        bOC                 % Boundaries of OC data ([rmin,rmax,zmin,zmax])
        epsilon             % Dimensionless inner surface contour (epsilon)
        L_a                 % Dimensionless fluid surface height wrt CoM (L/a)
        L                   % Dimensional fluid surface height wrt CoM
        a                   % Fluid surface external radius
        bens                % Region boundaries
        flvol               % Fluid Volume
        H_origin            % Fluid Height above original contour file origin
        H_tank              % Fluid height from the bottom of the tank
        CM_pos              % Fluid Center of Mass position (z)

        % eigenvalue calculation
        Nm                  % N employed during last calculation
        Mm                  % M employed during last calculation
        gm                  % g employed during last calculation
        c_k                 % Eigenvectors
        lambda_k            % Eigenvalues
        Km                  % Dimensionless natural frequencies
        omega_k             % Natura frequencies
        s_n                 % Intermediate parameter for the calculations
        phi_a               % Intermediate parameter for the calculations
        Bm                  % Intermediate parameter for the calculations
        Bzerosm             % dBessel zeros
        I_modeSorting       % order of mode sorting after last calculation

        % mechanical analogy
        H_k                 % Modal mass heights
        K_k                 % Modal sring stiffnesses
        H_k_spring          % Spring mass hinge point
        M_k                 % modal masses
        L_k                 % pendulum length
        CM_k                % ratio of slosh wave amplitude to mass amplitude
        characteristic_velocity % characteristic velocity
        modes_calculated    % true if calculate() has run at least once since last call to get_geometry()

        % parametric analysis
        parametric_analysis_fill_ratios
        parametric_analysis_accelerations
        parametric_analysis_times
        parametric_analysis_output_matrix

        % misc
        app_file_directory
    end

    methods (Access = private)

        function get_geometry (app)
            r_ic_file = app.IC_original; % inner contour, unscaled, in file coordinate system
            r_oc_file = app.OC_original; % outer contour, unscaled, in file coordinate system
            b_ic_file = app.bIC; % inner contour r and z boundaries, unscaled, in file coordinate system
            b_oc_file = app.bOC; % outer contour r and z boundaries, unscaled, in file coordinate system
            LFR = app.LiquidFillRatio.Value/100;
            RFac = app.RF.Value/100;
            ZFac = app.ZF.Value/100;
            if isa(r_ic_file, 'function_handle') && isa(r_oc_file, 'function_handle') && numel(b_ic_file) == 4 && numel(b_oc_file) == 4
                % Scale geometry by ZFac and RFac
                b_ens_scaled_origin = [min(b_ic_file(1),b_oc_file(1))*RFac, max(b_ic_file(2),b_oc_file(2))*RFac, ...
                                       max(b_ic_file(3),b_oc_file(3))*ZFac, min(b_ic_file(4),b_oc_file(4))*ZFac]; % Tank boundaries, scaled, wrt original origin
                r_ic_scaled_origin  = @(z) r_ic_file(z/ZFac)*RFac; % Inner contour, scaled, wrt original origin
                r_oc_scaled_origin  = @(z) r_oc_file(z/ZFac)*RFac; % Inner contour, scaled, wrt original origin
    
                H_tank   = LFR*(b_ens_scaled_origin(4)-b_ens_scaled_origin(3)); % Fluid height, wrt tank bottom
                H_origin = LFR*(b_ens_scaled_origin(4)-b_ens_scaled_origin(3)) + b_ens_scaled_origin(3); % Fluid height, wrt original origin
    
                % Calculate fluid volume and center of mass location
                fluid_volume = integral(@(z) pi*((r_oc_scaled_origin(z).^2)-(r_ic_scaled_origin(z).^2)),b_ens_scaled_origin(3),H_origin);
                z_CM         = integral(@(z) z.*((r_oc_scaled_origin(z).^2) -(r_ic_scaled_origin(z).^2)),b_ens_scaled_origin(3),H_origin) ...
                               / integral(@(z) r_oc_scaled_origin(z).^2 -r_ic_scaled_origin(z).^2 ,b_ens_scaled_origin(3),H_origin);
    
                % Shift geometry to new origin (centered on the center of mass)
                b_ens_scaled_CM    = b_ens_scaled_origin - [0,0,z_CM,z_CM];
                r_ic_scaled_CM     = @(z) r_ic_scaled_origin(z + z_CM);
                r_oc_scaled_CM     = @(z) r_oc_scaled_origin(z + z_CM);
    
                L = H_tank-z_CM; % Fluid level (wrt CM)
                
                % Generate relevant geometrical parameters
                a        = r_oc_scaled_CM(L);
                epsilon  = r_ic_scaled_CM(L)/a;
                L_a      = L/a;

                % compute nondimensional contours
                R_ic_CM  = @(Z) r_ic_scaled_CM(Z*a)/a; % inner contour, nondimensional, using CM origin
                R_oc_CM  = @(Z) r_oc_scaled_CM(Z*a)/a; % inner contour, nondimensional, using CM origin
                
                % save results
                app.IC_dimensionless = R_ic_CM;
                app.OC_dimensionless = R_oc_CM;
                app.IC_scaled_CM     = r_ic_scaled_CM;
                app.OC_scaled_CM     = r_oc_scaled_CM;
                app.IC_scaled_origin = r_ic_scaled_origin;
                app.OC_scaled_origin = r_oc_scaled_origin;
                app.epsilon          = epsilon;
                app.a                = a;
                app.flvol            = fluid_volume;
                app.L_a              = L_a;
                app.L                = L;
                app.bens             = b_ens_scaled_CM;
                app.H_tank           = H_tank;
                app.H_origin         = H_origin;
                app.CM_pos           = z_CM;
                
                % enable calculate and geometry plot settings
                app.CALCULATE.Enable              = true;
                app.LiquidFillLineCheckBox.Enable = true;
                app.CenterofMassCheckBox.Enable   = true;
                app.TankGeometryCheckBox.Enable   = true;

                % disable post-calculation controls
                app.MechanicalAnalogyDropDown.Enable     = false;
                app.MechanicalAnalogyCountSpinner.Enable = false;
                app.MultiplicationFactorEditField.Enable = false;
                app.NumberofModesSpinner.Enable          = false;
                app.NormalizeCheckBox.Enable             = false;
                app.modes_calculated                     = false;

                % plot
                change_plot(app);
            end
        end

        % Plot fuel tank geometry and fluid level
        function plot_geometry (app)
            % Plot geometry
            % the origin of the plot is now centered at the center of mass
            if check_consistency(app)
                zv    = linspace(app.bens(3),app.bens(4),1000);
                hold(app.mainaxes, 'on')
                axis(app.mainaxes,'equal')
                % add 10% margins around the contour
                gapr  = (app.bens(2)-app.bens(1))/10;
                gapz  = (app.bens(4)-app.bens(3))/10;
                gap   = mean([gapr,gapz]);
                axis(app.mainaxes, app.bens + [-app.bens(1) gap -gap gap])
    
                % Plot fuel tank with saved results from get_geometry
                if app.TankGeometryCheckBox.Value
                    plot(app.mainaxes,app.IC_scaled_CM(zv),zv,'k','LineWidth',2) % plot inner contour
                    plot(app.mainaxes,app.OC_scaled_CM(zv),zv,'k','LineWidth',2) % plot outer contour
                    plot(app.mainaxes,[app.IC_scaled_CM(app.bens(3)*0.99999),app.OC_scaled_CM(app.bens(3)*0.99999)],app.bens(3)*[1,1],'k','LineWidth',2)
                    plot(app.mainaxes,[app.IC_scaled_CM(app.bens(4)*0.99999),app.OC_scaled_CM(app.bens(4)*0.99999)],app.bens(4)*[1,1],'k','LineWidth',2)
                end
    
                % Fluid level
                if app.LiquidFillLineCheckBox.Value
                    plot(app.mainaxes,[app.IC_scaled_CM(app.L),app.OC_scaled_CM(app.L)],[app.L,app.L],'b','LineWidth',1)
                end
    
                % center of mass height
                if app.CenterofMassCheckBox.Value
                    plot(app.mainaxes,0,0,'ko','Markersize',10)
                    plot(app.mainaxes,[0,1.5*app.OC_scaled_CM(0)], [0,0],'g--','LineWidth',1)
                    plot(app.mainaxes,[app.IC_scaled_CM(0),app.OC_scaled_CM(0)], [0,0],'g','LineWidth',1)
                end
            end
        end

        % Checks that inner and outer countours are consistent
        function [bool] = check_consistency(app)
            bool = 0;
            c1 = isa(app.IC_original, 'function_handle');
            c2 = isa(app.OC_original, 'function_handle');
            if c1 && c2 && length(app.bIC) == 4 && length(app.bOC) == 4
                c3 = max(app.bIC(3),app.bOC(3)) < min(app.bIC(4),app.bOC(4));
                if c1 && c2 && c3
                    bool = 1;
                end
            end
        end

        function get_contour (app)
            [file,path] = uigetfile('*.txt', 'Select contour file', [app.app_file_directory '\contours']);
            if max(path ~= 0)
                % Process file (read columns, remove NaN elements, sort)
                try
                    ZRic_ZRoc   = dlmread([path,file]); 
                    if size(ZRic_ZRoc,2) ~= 4
                        error('');
                    end
                    Z_ic         = ZRic_ZRoc(:,1);
                    Z_ic         = Z_ic(~isnan(Z_ic));
                    R_ic         = ZRic_ZRoc(:,2);
                    R_ic         = R_ic(~isnan(R_ic));
                    [Z_ic,I]     = sort(Z_ic);
                    R_ic         = R_ic(I);
                    Z_oc         = ZRic_ZRoc(:,3);
                    Z_oc         = Z_oc(~isnan(Z_oc));
                    R_oc         = ZRic_ZRoc(:,4);
                    R_oc         = R_oc(~isnan(R_oc));
                    [Z_oc,I]     = sort(Z_oc);
                    R_oc         = R_oc(I);
                catch
                    uialert(app.SLOSHMLv0103UIFigure,'Failed to read contour data from file', 'Failed', 'Icon','Error');
                    return;
                end

                % Interpolation function
                r_ic             = @(z) interp1(Z_ic,R_ic,z);
                inner_boundaries = [min(R_ic),max(R_ic),min(Z_ic),max(Z_ic)];
                r_oc             = @(z) interp1(Z_oc,R_oc,z);
                outer_boundaries = [min(R_oc),max(R_oc),min(Z_oc),max(Z_oc)];
                
                % save to app
                app.IC_original  = r_ic;
                app.bIC          = inner_boundaries;
                app.OC_original  = r_oc;
                app.bOC          = outer_boundaries;
                get_geometry(app);
            end
        end
        
        % Plot springs on graph (need to verify this works)
        function plot_springs(app)
            if strcmpi(app.MechanicalAnalogyDropDown.Value, 'spring') && app.modes_calculated
                number_of_springs_to_plot = app.MechanicalAnalogyCountSpinner.Value;
                H_k_spring  = app.H_k_spring;
                b_ens       = app.bens;
                for n = 1:number_of_springs_to_plot
                    H_spring = H_k_spring(n)+b_ens(3);
                    if H_spring >= b_ens(4) || H_spring <= b_ens(3)
                        continue
                    end
                    left = 0;
                    right              = app.OC_scaled_CM(H_spring);
                    mid                = (left + right) ./ 2;
                    xunit              = abs(b_ens(2) - b_ens(1)) ./ 10;
                    yunit              = abs(b_ens(4) - b_ens(3)) ./ 25;
                    [springx, springy] = horizspring(mid./4, H_spring, mid./4);
                    plot(app.mainaxes, [left,mid./4], [H_spring, H_spring], 'Color', [0.2, 0.2, 0.2]);
                    plot(app.mainaxes, springx, springy, 'Color', [0.2, 0.2, 0.2]);
                    plot(app.mainaxes, [mid./2, right], [H_spring, H_spring], 'Color', [0.2, 0.2, 0.2]);
                    rectangle(app.mainaxes, 'Position', [mid-xunit, H_spring-yunit, xunit.*2, yunit.*2], 'FaceColor',[0.8, 0.8, 0.8])
                    text(app.mainaxes, mid, H_spring, ['m_', num2str(n-1)], 'HorizontalAlignment','center')
                end
            end
        end

        function compute_mechanical_analogies(app)
            % Load parameters
            epsilon      = app.epsilon;
            L_a          = app.L_a;
            a            = app.a;
            R_ic         = app.IC_dimensionless;
            R_oc         = app.OC_dimensionless;
            intb         = app.bens;
            N            = app.NSpinner.Value;
            M            = app.MSpinner.Value;
            g            = app.gEditField.Value;
            rho          = app.rhoEditField.Value;
            flvol        = app.flvol;
            H            = app.H_tank;
            selm         = app.SpringModeSpinner.Value;
            lambda_k     = app.lambda_k;
            c_k          = app.c_k;
            Bzeros       = app.Bzerosm;

            % Derived parameters pre-calculation
            B            = zeros(N,N);
            s_n          = zeros(N,1);
            phi_a        = zeros(N,1);
            for n = 1:N
                s_n(n,1)   = integral(@(z) (z-intb(3)/a).*R_oc(z).*phi(n,M,R_oc(z),z,Bzeros,L_a),intb(3)/a,L_a) - ...
                    integral(@(z) (z-intb(3)/a).*R_ic(z).*phi(n,M,R_ic(z),z,Bzeros,L_a),intb(3)/a,L_a);
                phi_a(n,1) = 0;
                for k=1:N
                    phi_a(n,1) =  phi_a(n,1) + c_k(k,n)*phi(k,M,R_oc(L_a),L_a,Bzeros,L_a);
                end

                for m = n:N
                    B(n,m) = Bint(n,m,M,Bzeros,epsilon);
                end
            end

            % Symmetry of B
            for m = 1:N
                for n = 1:N
                    B(n,m) = B(m,n);
                end
            end

            % Derived pendulum parameters
            [K,omega_k,Vc,M_k,L_k,H_k,CM_k,fluid_mass,c_k,lambda_k,I_sort] = derivedparams(rho,flvol,H,c_k,lambda_k,g,L_a*a,a,B,s_n,phi_a,intb,selm);

            % Derived spring parameters
            [K_k,H_k_spring] = pendulum2spring(M_k, L_k, g, H_k);

            % Save
            app.c_k           = c_k;
            app.lambda_k      = lambda_k;
            app.Km            = K;
            app.omega_k       = omega_k;
            app.Bzerosm       = Bzeros;
            app.Nm            = N;
            app.Mm            = M;
            app.gm            = g;
            app.s_n           = s_n;
            app.phi_a         = phi_a;
            app.Bm            = B;
            app.H_k           = H_k;
            app.K_k           = K_k;
            app.M_k           = M_k;
            app.L_k           = L_k;
            app.H_k_spring    = H_k_spring;
            app.CM_k          = CM_k;
            app.characteristic_velocity = Vc;
            app.I_modeSorting           = I_sort;

            % set maximum springs to plot based on length of Hmods
            if app.MechanicalAnalogyCountSpinner.Value > length(H_k)
                app.MechanicalAnalogyCountSpinner.Value = length(H_k);
            end
            app.MechanicalAnalogyCountSpinner.Limits = [0, length(H_k)];
        end

        function display_tables (app)
            Vc             = app.characteristic_velocity;
            K              = app.Km;
            omega_k        = app.omega_k;
            H_k            = app.H_k;
            H_k_spring     = app.H_k_spring;
            K_k            = app.K_k;
            M_k            = app.M_k;
            L_k            = app.L_k;
            CM_k           = app.CM_k;
            rho            = app.rhoEditField.Value;
            sigma          = 1e-3 * app.sigmaEditField.Value;
            a              = app.a;
            g              = app.gEditField.Value;
            pendulum_count = app.SpringModeSpinner.Value;
            lambda_k       = app.lambda_k;
            c_k            = app.c_k;

            % Dimensionless numbers and regimes
            We             = rho*Vc^2*a/sigma;
            Fr             = Vc^2/g/a;
            Bo             = We/Fr;

            if We >= 1 && We >= Bo
                regime = 'Inertia D.';
                color  = [0,1,0];
            elseif Bo >= 1 && Bo > We
                regime = 'Gravity D.';
                color  = [0,1,0];
            else
                regime = 'Capillary D.';
                color  = [1,0,0];
            end


            % Display Regime information
            app.VcEditField.Value      = (Vc);
            app.WeEditField.Value      = (We);
            app.FrEditField.Value      = (Fr);
            app.BoEditField.Value      = (Bo);
            app.RegimeLabel.Text       = regime;
            app.RegimeLabel.FontColor  = color;

            % Display Frequencies
            data                       = [diag(lambda_k)';K';omega_k'];
            app.freqtable.Data         = data;
            app.freqtable.ColumnName   = 'numbered';
            app.freqtable.ColumnWidth  = repmat({60},1,length(K));
            app.freqtable.ColumnFormat = repmat({'short'},1,length(K));
            app.freqtable.RowName      = {'lambda';'K';'wn'};

            % Display Eigenvectors
            app.eigfntable.Data        = c_k;
            app.eigfntable.RowName     = 'numbered';
            app.eigfntable.ColumnName  = 'numbered';
            app.eigfntable.ColumnWidth = repmat({60},1,length(K));
            app.eigfntable.ColumnFormat= repmat({'short'},1,length(K));

            mechanical_analogy_row_names = {'Fixed Mass'};
            for i = 1:pendulum_count+1
                mechanical_analogy_row_names{end+1} = num2str(i);
            end
                
            % Display pendulum properties
            app.PendulumTable.Data        = [M_k(1:pendulum_count+1),L_k(1:pendulum_count+1),H_k(1:pendulum_count+1),CM_k(1:pendulum_count+1)];
            app.PendulumTable.RowName     = mechanical_analogy_row_names;
            app.PendulumTable.ColumnWidth = repmat({80},1,length(K));
            app.PendulumTable.ColumnFormat= repmat({'short'},1,length(K));

            % Display spring properties
            app.SpringTable.Data        = [M_k(1:pendulum_count+1),H_k_spring(1:pendulum_count+1),K_k(1:pendulum_count+1)];
            app.SpringTable.RowName     = mechanical_analogy_row_names;
            app.SpringTable.ColumnWidth = repmat({80},1,length(K));
            app.SpringTable.ColumnFormat= repmat({'short'},1,length(K));
        end

        function plot_modes(app)
            numberOfModesToPlot  = app.NumberofModesSpinner.Value;
            if check_consistency(app) && app.modes_calculated
                for i_mode = 1:numberOfModesToPlot
                    L               = app.L;
                    L_a             = app.L_a;
                    a               = app.a;
                    Bzeros          = app.Bzerosm;
                    r_ic_scaled_CM  = app.IC_scaled_CM;
                    r_oc_scaled_CM  = app.OC_scaled_CM;
                    omega_k         = app.omega_k;
                    g               = app.gEditField.Value;
                    N               = app.Nm;
                    M               = app.Mm;
                    c_k             = app.c_k;
                    multiplication_factor = app.MultiplicationFactorEditField.Value/100;
                    z_vec           = zeros(1,200);
                    r_vec           = linspace(r_ic_scaled_CM(L),r_oc_scaled_CM(L),200);
                    for  n = 1:N
                        z_vec = z_vec + c_k(n,i_mode)*phi(n,M,r_vec/a,L_a,Bzeros,L_a);
                    end
                    z_vec = z_vec*omega_k(i_mode)*a/g;
                    if app.NormalizeCheckBox.Value
                        z_vec = z_vec/max(abs(z_vec)) * L/2;
                    end
                    plot(app.mainaxes,r_vec,z_vec*multiplication_factor+L,'r');
                end
            end
        end

        function change_plot(app)
            app.WelcomeImage.Visible = false;
            app.mainaxes.Visible = true;
            cla(app.mainaxes); % clear plot
            plot_geometry(app);
            plot_springs(app);
            % plot_pendulums(app); % will be implemented in a later version
            plot_modes(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~ismember('Optimization Toolbox', {ver().Name})
                uialert(app.SLOSHMLv0103UIFigure,'Missing Required Dependency: Optimization Toolbox', 'Warning', 'Icon', 'warning');
            end

            [app_file_directory,~,~] = fileparts(mfilename('fullpath')); 
            app.app_file_directory = app_file_directory;
            addpath([app.app_file_directory '/functions'])
            movegui(app.SLOSHMLv0103UIFigure,'center')
            app.modes_calculated = false;
            DisplayButtonChanged(app);
        end

        % Button pushed function: CALCULATE
        function calculate(app, event)
           
            % Load parameters
            e      = app.epsilon;
            L_a    = app.L_a;
            a      = app.a;
            R_ic   = app.IC_dimensionless;
            R_oc   = app.OC_dimensionless;
            intb   = app.bens;
            nsteps = app.nSpinner.Value;
            N      = app.NSpinner.Value;
            M      = app.MSpinner.Value;

            % Calculation
            [c_k,lambda_k,Bzeros]  = core(N,M,nsteps,intb/a,e,L_a,R_ic,R_oc,event);
            app.c_k                = c_k;
            app.lambda_k           = lambda_k;
            app.Bzerosm            = Bzeros;

            % compute spring and pendulum parameters
            compute_mechanical_analogies(app);

            % update maximum modes to plot
            if app.NumberofModesSpinner.Value > length(c_k)
                app.NumberofModesSpinner.Value = length(c_k);
            end
            app.NumberofModesSpinner.Limits = [0 length(c_k)];
            
            % enable post-calculation controls
            app.modes_calculated                     = true;
            app.MechanicalAnalogyCountSpinner.Enable = true;
            % app.MechanicalAnalogyDropDown.Enable = true;
            app.MultiplicationFactorEditField.Enable = true;
            app.NumberofModesSpinner.Enable          = true;
            app.NormalizeCheckBox.Enable             = true;

            % display tables
            display_tables(app);
            displayModeData(app,0);
            app.ModeSpinner.Enable = true;

            % plot
            change_plot(app);
        end

        % Value changed function: LiquidFillRatio
        function LiquidFillRationChanged(app, event)
            get_geometry(app);
        end

        % Value changed function: NSpinner
        function changeN(app, event)
            N                       = app.NSpinner.Value;
            app.MSpinner.Limits     = [5,N-1];
            app.ModeSpinner.Limits  = [1,N];
        end

        % Value changed function: RF
        function changeRF(app, event)
            get_geometry(app);
        end

        % Value changed function: ZF
        function changeZF(app, event)
            get_geometry(app);
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonChanged(app, event)
            % Detect which button was selected and make the corresponding
            % settings panel visible
            selectedButton                      = app.DisplayButtonGroup.SelectedObject;
            app.DisplayGeometryPanel.Visible    = false;
            app.DisplayMechanicalPanel.Visible  = false;
            app.DisplayModesPanel.Visible       = false;
            if strcmp(selectedButton.Text, app.GeometryButton.Text)
                app.DisplayGeometryPanel.Visible = true;
            elseif strcmp(selectedButton.Text, app.MechanicalButton.Text)
                app.DisplayMechanicalPanel.Visible  = true;
            elseif strcmp(selectedButton.Text, app.ModesButton.Text)
                app.DisplayModesPanel.Visible       = true;
            end
        end

        % Value changed function: TankGeometryCheckBox
        function changeTankGeometryCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: CenterofMassCheckBox
        function changeCenterofMassCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: LiquidFillLineCheckBox
        function changeLiquidFillLineCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: MechanicalAnalogyCountSpinner
        function changeMechanicalAnalogyCountSpinner(app, event)
            change_plot(app);
        end

        % Value changed function: MechanicalAnalogyDropDown
        function changeMechanicalAnalogyDropDown(app, event)
            change_plot(app);
        end

        % Value changed function: MultiplicationFactorEditField
        function changeMultiplicationFactorEditField(app, event)
            change_plot(app);
        end

        % Value changed function: NumberofModesSpinner
        function changeNumberofModesSpinner(app, event)
            change_plot(app);
        end

        % Button pushed function: RestoreDefaultSettingsButton
        function restoreDefaultAdvancedSettings(app, event)
            app.SpringModeSpinner.Value = 3;
            app.nSpinner.Value          = 200;
            app.NSpinner.Value          = 10;
            app.MSpinner.Value          = 5;
        end

        % Value changed function: ModeSpinner
        function displayModeData(app, event)
            % Load parameters
            c_k          = app.c_k;
            omega_k      = app.omega_k;
            mode_number  = app.ModeSpinner.Value;
            K = app.Km;

            if ~isempty(c_k)
                % Update interface
                app.modes_natfreq.Text      = num2str(omega_k(mode_number));
                app.modes_nondfreq.Text     = num2str(K(mode_number));
                app.freqtable_2.Data = c_k(:,mode_number)';
                app.freqtable_2.ColumnWidth = repmat({60},1,length(K));
                app.freqtable_2.ColumnName  = 'numbered';
                app.freqtable_2.ColumnFormat= repmat({'short'},1,length(K));
            end
        end

        % Menu selected function: SaveMenu
        function SaveSessionClicked(app, event)
            [file, path] = uiputfile('*.SLOSHML', 'Save session to file', [app.app_file_directory '\saves']);
            if max(path ~= 0)
                try
                    varnames = readcell("resources\appSaveSessionVariableList.csv");
                    save_data = struct();
                    save_data.IC_original = app.IC_original;
                    save_data.OC_original = app.IC_original;
                    save_data.IC_scaled_origin = app.IC_scaled_origin;
                    save_data.OC_scaled_origin = app.OC_scaled_origin;
                    save_data.IC_scaled_CM = app.IC_scaled_CM;
                    save_data.OC_scaled_CM = app.OC_scaled_CM;
                    save_data.IC_dimensionless = app.IC_dimensionless;
                    save_data.OC_dimensionless = app.OC_dimensionless;
                    save_data.bIC = app.bIC;
                    save_data.bOC = app.bOC;
                    save_data.epsilon = app.epsilon;
                    save_data.L_a = app.L_a;
                    save_data.L = app.L;
                    save_data.a = app.a;
                    save_data.bens = app.bens;
                    save_data.flvol = app.flvol;
                    save_data.H_origin = app.H_origin;
                    save_data.H_tank = app.H_tank;
                    save_data.CM_pos = app.CM_pos;
                    save_data.Nm = app.Nm;
                    save_data.Mm = app.Mm;
                    save_data.gm = app.gm;
                    save_data.c_k = app.c_k;
                    save_data.lambda_k = app.lambda_k;
                    save_data.Km = app.Km;
                    save_data.omega_k = app.omega_k;
                    save_data.s_n = app.s_n;
                    save_data.phi_a = app.phi_a;
                    save_data.Bm = app.Bm;
                    save_data.Bzerosm = app.Bzerosm;
                    save_data.I_modeSorting = app.I_modeSorting;
                    save_data.H_k = app.H_k;
                    save_data.K_k = app.K_k;
                    save_data.H_k_spring = app.H_k_spring;
                    save_data.M_k = app.M_k;
                    save_data.L_k = app.L_k;
                    save_data.CM_k = app.CM_k;
                    save_data.characteristic_velocity = app.characteristic_velocity;
                    save_data.modes_calculated = app.modes_calculated;
                    save_data.parametric_analysis_fill_ratios = app.parametric_analysis_fill_ratios;
                    save_data.parametric_analysis_accelerations = app.parametric_analysis_accelerations;
                    save_data.parametric_analysis_times = app.parametric_analysis_times;
                    save_data.parametric_analysis_output_matrix = app.parametric_analysis_output_matrix;
                    save_data.app_file_directory = app.app_file_directory;
                    save_data.DisplayMechanicalPanel.Visible = app.DisplayMechanicalPanel.Visible;
                    save_data.MechanicalAnalogyCountSpinner.Enable = app.MechanicalAnalogyCountSpinner.Enable;
                    save_data.MechanicalAnalogyCountSpinner.Value = app.MechanicalAnalogyCountSpinner.Value;
                    save_data.MechanicalAnalogyCountSpinner.Limits = app.MechanicalAnalogyCountSpinner.Limits;
                    save_data.MechanicalAnalogyDropDown.Enable = app.MechanicalAnalogyDropDown.Enable;
                    save_data.MechanicalAnalogyDropDown.Value = app.MechanicalAnalogyDropDown.Value;
                    save_data.DisplayModesPanel.Visible = app.DisplayModesPanel.Visible;
                    save_data.NormalizeCheckBox.Value = app.NormalizeCheckBox.Value;
                    save_data.NormalizeCheckBox.Enable = app.NormalizeCheckBox.Enable;
                    save_data.MultiplicationFactorEditField.Enable = app.MultiplicationFactorEditField.Enable;
                    save_data.MultiplicationFactorEditField.Value = app.MultiplicationFactorEditField.Value;
                    save_data.NumberofModesSpinner.Enable = app.NumberofModesSpinner.Enable;
                    save_data.NumberofModesSpinner.Value = app.NumberofModesSpinner.Value;
                    save_data.NumberofModesSpinner.Limits = app.NumberofModesSpinner.Limits;
                    save_data.DisplayGeometryPanel.Visible = app.DisplayGeometryPanel.Visible;
                    save_data.LiquidFillLineCheckBox.Enable = app.LiquidFillLineCheckBox.Enable;
                    save_data.LiquidFillLineCheckBox.Value = app.LiquidFillLineCheckBox.Value;
                    save_data.CenterofMassCheckBox.Enable = app.CenterofMassCheckBox.Enable;
                    save_data.CenterofMassCheckBox.Value = app.CenterofMassCheckBox.Value;
                    save_data.TankGeometryCheckBox.Enable = app.TankGeometryCheckBox.Enable;
                    save_data.TankGeometryCheckBox.Value = app.TankGeometryCheckBox.Value;
                    save_data.CALCULATE.Enable = app.CALCULATE.Enable;
                    save_data.VcEditField.Value = app.VcEditField.Value;
                    save_data.FrEditField.Value = app.FrEditField.Value;
                    save_data.BoEditField.Value = app.BoEditField.Value;
                    save_data.WeEditField.Value = app.WeEditField.Value;
                    save_data.RegimeLabel.Text = app.RegimeLabel.Text;
                    save_data.RegimeLabel.FontColor = app.RegimeLabel.FontColor;
                    save_data.freqtable.Data = app.freqtable.Data;
                    save_data.freqtable.ColumnName = app.freqtable.ColumnName;
                    save_data.freqtable.ColumnWidth = app.freqtable.ColumnWidth;
                    save_data.freqtable.ColumnFormat = app.freqtable.ColumnFormat;
                    save_data.freqtable.RowName = app.freqtable.RowName;
                    save_data.eigfntable.Data = app.eigfntable.Data;
                    save_data.eigfntable.RowName = app.eigfntable.RowName;
                    save_data.eigfntable.ColumnName = app.eigfntable.ColumnName;
                    save_data.eigfntable.ColumnWidth = app.eigfntable.ColumnWidth;
                    save_data.eigfntable.ColumnFormat = app.eigfntable.ColumnFormat;
                    save_data.modes_nondfreq.Text = app.modes_nondfreq.Text; 
                    save_data.modes_natfreq.Text = app.modes_natfreq.Text;
                    save_data.ModeSpinner.Value = app.ModeSpinner.Value;
                    save_data.ModeSpinner.Enable = app.ModeSpinner.Enable;
                    save_data.ModeSpinner.Limits = app.ModeSpinner.Limits;
                    save_data.freqtable_2.Data = app.freqtable_2.Data;
                    save_data.freqtable_2.ColumnWidth = app.freqtable_2.ColumnWidth;
                    save_data.freqtable_2.ColumnName = app.freqtable_2.ColumnName;
                    save_data.freqtable_2.ColumnFormat = app.freqtable_2.ColumnFormat;
                    save_data.PendulumTable.Data = app.PendulumTable.Data;
                    save_data.PendulumTable.RowName = app.PendulumTable.RowName;
                    save_data.PendulumTable.ColumnWidth = app.PendulumTable.ColumnWidth;
                    save_data.PendulumTable.ColumnFormat = app.PendulumTable.ColumnFormat;
                    save_data.SpringTable.Data = app.SpringTable.Data;
                    save_data.SpringTable.RowName = app.SpringTable.RowName;
                    save_data.SpringTable.ColumnWidth = app.SpringTable.ColumnWidth;
                    save_data.SpringTable.ColumnFormat = app.SpringTable.ColumnFormat;
                    save_data.ModesButton.Value = app.ModesButton.Value;
                    save_data.MechanicalButton.Value = app.MechanicalButton.Value;
                    save_data.GeometryButton.Value = app.GeometryButton.Value; 
                    save_data.ZF.Value = app.ZF.Value;
                    save_data.RF.Value = app.RF.Value;
                    save_data.LiquidFillRatio.Value = app.LiquidFillRatio.Value;
                    save_data.rhoEditField.Value = app.rhoEditField.Value;
                    save_data.sigmaEditField.Value = app.sigmaEditField.Value;
                    save_data.gEditField.Value = app.gEditField.Value;
                    save_data.ParametricAnalysisExportButton.Enable = app.ParametricAnalysisExportButton.Enable;
                    save_data.ParametricAnalysisComputeButton.Enable = app.ParametricAnalysisComputeButton.Enable;
                    save_data.SpringModeSpinner.Value = app.SpringModeSpinner.Value;
                    save_data.nSpinner.Value = app.nSpinner.Value;
                    save_data.MSpinner.Value = app.MSpinner.Value;
                    save_data.NSpinner.Value = app.NSpinner.Value;
                    save_data.WelcomeImage.Visible = app.WelcomeImage.Visible;
                    save_data.mainaxes.Visible = app.mainaxes.Visible;
                    save([path, file], "save_data", "-mat");
                catch
                    uialert(app.SLOSHMLv0103UIFigure, 'Failed to save data', '', 'Icon', 'error');
                end
            end
        end

        % Menu selected function: LoadMenu
        function LoadSessionClicked(app, event)
            [file,path] = uigetfile('*.SLOSHML', 'Select App Session', [app.app_file_directory '\saves']);
            if max(path ~= 0)
                try
                    temp = load([path, file], "-mat", "save_data");
                    save_data = temp.save_data;
                    app.IC_original = save_data.IC_original;
                    app.OC_original = save_data.IC_original;
                    app.IC_scaled_origin = save_data.IC_scaled_origin;
                    app.OC_scaled_origin = save_data.OC_scaled_origin;
                    app.IC_scaled_CM = save_data.IC_scaled_CM;
                    app.OC_scaled_CM = save_data.OC_scaled_CM;
                    app.IC_dimensionless = save_data.IC_dimensionless;
                    app.OC_dimensionless = save_data.OC_dimensionless;
                    app.bIC = save_data.bIC;
                    app.bOC = save_data.bOC;
                    app.epsilon = save_data.epsilon;
                    app.L_a = save_data.L_a;
                    app.L = save_data.L;
                    app.a = save_data.a;
                    app.bens = save_data.bens;
                    app.flvol = save_data.flvol;
                    app.H_origin = save_data.H_origin;
                    app.H_tank = save_data.H_tank;
                    app.CM_pos = save_data.CM_pos;
                    app.Nm = save_data.Nm;
                    app.Mm = save_data.Mm;
                    app.gm = save_data.gm;
                    app.c_k = save_data.c_k;
                    app.lambda_k = save_data.lambda_k;
                    app.Km = save_data.Km;
                    app.omega_k = save_data.omega_k;
                    app.s_n = save_data.s_n;
                    app.phi_a = save_data.phi_a;
                    app.Bm = save_data.Bm;
                    app.Bzerosm = save_data.Bzerosm;
                    app.I_modeSorting = save_data.I_modeSorting;
                    app.H_k = save_data.H_k;
                    app.K_k = save_data.K_k;
                    app.H_k_spring = save_data.H_k_spring;
                    app.M_k = save_data.M_k;
                    app.L_k = save_data.L_k;
                    app.CM_k = save_data.CM_k;
                    app.characteristic_velocity = save_data.characteristic_velocity;
                    app.modes_calculated = save_data.modes_calculated;
                    app.parametric_analysis_fill_ratios = save_data.parametric_analysis_fill_ratios;
                    app.parametric_analysis_accelerations = save_data.parametric_analysis_accelerations;
                    app.parametric_analysis_times = save_data.parametric_analysis_times;
                    app.parametric_analysis_output_matrix = save_data.parametric_analysis_output_matrix;
                    app.app_file_directory = save_data.app_file_directory;
                    app.DisplayMechanicalPanel.Visible = save_data.DisplayMechanicalPanel.Visible;
                    app.MechanicalAnalogyCountSpinner.Enable = save_data.MechanicalAnalogyCountSpinner.Enable;
                    app.MechanicalAnalogyCountSpinner.Value = save_data.MechanicalAnalogyCountSpinner.Value;
                    app.MechanicalAnalogyCountSpinner.Limits = save_data.MechanicalAnalogyCountSpinner.Limits;
                    app.MechanicalAnalogyDropDown.Enable = save_data.MechanicalAnalogyDropDown.Enable;
                    app.MechanicalAnalogyDropDown.Value = save_data.MechanicalAnalogyDropDown.Value;
                    app.DisplayModesPanel.Visible = save_data.DisplayModesPanel.Visible;
                    app.NormalizeCheckBox.Value = save_data.NormalizeCheckBox.Value;
                    app.NormalizeCheckBox.Enable = save_data.NormalizeCheckBox.Enable;
                    app.MultiplicationFactorEditField.Enable = save_data.MultiplicationFactorEditField.Enable;
                    app.MultiplicationFactorEditField.Value = save_data.MultiplicationFactorEditField.Value;
                    app.NumberofModesSpinner.Enable = save_data.NumberofModesSpinner.Enable;
                    app.NumberofModesSpinner.Value = save_data.NumberofModesSpinner.Value;
                    app.NumberofModesSpinner.Limits = save_data.NumberofModesSpinner.Limits;
                    app.DisplayGeometryPanel.Visible = save_data.DisplayGeometryPanel.Visible;
                    app.LiquidFillLineCheckBox.Enable = save_data.LiquidFillLineCheckBox.Enable;
                    app.LiquidFillLineCheckBox.Value = save_data.LiquidFillLineCheckBox.Value;
                    app.CenterofMassCheckBox.Enable = save_data.CenterofMassCheckBox.Enable;
                    app.CenterofMassCheckBox.Value = save_data.CenterofMassCheckBox.Value;
                    app.TankGeometryCheckBox.Enable = save_data.TankGeometryCheckBox.Enable;
                    app.TankGeometryCheckBox.Value = save_data.TankGeometryCheckBox.Value;
                    app.CALCULATE.Enable = save_data.CALCULATE.Enable;
                    app.VcEditField.Value = save_data.VcEditField.Value;
                    app.FrEditField.Value = save_data.FrEditField.Value;
                    app.BoEditField.Value = save_data.BoEditField.Value;
                    app.WeEditField.Value = save_data.WeEditField.Value;
                    app.RegimeLabel.Text = save_data.RegimeLabel.Text;
                    app.RegimeLabel.FontColor = save_data.RegimeLabel.FontColor;
                    app.freqtable.Data = save_data.freqtable.Data;
                    app.freqtable.ColumnName = save_data.freqtable.ColumnName;
                    app.freqtable.ColumnWidth = save_data.freqtable.ColumnWidth;
                    app.freqtable.ColumnFormat = save_data.freqtable.ColumnFormat;
                    app.freqtable.RowName = save_data.freqtable.RowName;
                    app.eigfntable.Data = save_data.eigfntable.Data;
                    app.eigfntable.RowName = save_data.eigfntable.RowName;
                    app.eigfntable.ColumnName = save_data.eigfntable.ColumnName;
                    app.eigfntable.ColumnWidth = save_data.eigfntable.ColumnWidth;
                    app.eigfntable.ColumnFormat = save_data.eigfntable.ColumnFormat;
                    app.modes_nondfreq.Text = save_data.modes_nondfreq.Text; 
                    app.modes_natfreq.Text = save_data.modes_natfreq.Text;
                    app.ModeSpinner.Value = save_data.ModeSpinner.Value;
                    app.ModeSpinner.Enable = save_data.ModeSpinner.Enable;
                    app.ModeSpinner.Limits = save_data.ModeSpinner.Limits;
                    app.freqtable_2.Data = save_data.freqtable_2.Data;
                    app.freqtable_2.ColumnWidth = save_data.freqtable_2.ColumnWidth;
                    app.freqtable_2.ColumnName = save_data.freqtable_2.ColumnName;
                    app.freqtable_2.ColumnFormat = save_data.freqtable_2.ColumnFormat;
                    app.PendulumTable.Data = save_data.PendulumTable.Data;
                    app.PendulumTable.RowName = save_data.PendulumTable.RowName;
                    app.PendulumTable.ColumnWidth = save_data.PendulumTable.ColumnWidth;
                    app.PendulumTable.ColumnFormat = save_data.PendulumTable.ColumnFormat;
                    app.SpringTable.Data = save_data.SpringTable.Data;
                    app.SpringTable.RowName = save_data.SpringTable.RowName;
                    app.SpringTable.ColumnWidth = save_data.SpringTable.ColumnWidth;
                    app.SpringTable.ColumnFormat = save_data.SpringTable.ColumnFormat;
                    app.ModesButton.Value = save_data.ModesButton.Value;
                    app.MechanicalButton.Value = save_data.MechanicalButton.Value;
                    app.GeometryButton.Value = save_data.GeometryButton.Value; 
                    app.ZF.Value = save_data.ZF.Value;
                    app.RF.Value = save_data.RF.Value;
                    app.LiquidFillRatio.Value = save_data.LiquidFillRatio.Value;
                    app.rhoEditField.Value = save_data.rhoEditField.Value;
                    app.sigmaEditField.Value = save_data.sigmaEditField.Value;
                    app.gEditField.Value = save_data.gEditField.Value;
                    app.ParametricAnalysisExportButton.Enable = save_data.ParametricAnalysisExportButton.Enable;
                    app.ParametricAnalysisComputeButton.Enable = save_data.ParametricAnalysisComputeButton.Enable;
                    app.SpringModeSpinner.Value = save_data.SpringModeSpinner.Value;
                    app.nSpinner.Value = save_data.nSpinner.Value;
                    app.MSpinner.Value = save_data.MSpinner.Value;
                    app.NSpinner.Value = save_data.NSpinner.Value;
                    app.WelcomeImage.Visible = save_data.WelcomeImage.Visible;
                    app.mainaxes.Visible = save_data.mainaxes.Visible;
                    change_plot(app); % plots not saved to file, plot everything again
                catch
                    uialert(app.SLOSHMLv0103UIFigure, 'Failed to load data', '', 'Icon', 'error');
                end
            end
        end

        % Button pushed function: LoadContourButton
        function loadContourButtonClicked(app, event)
            get_contour(app);
        end

        % Button pushed function: SelectFileButton
        function ParametricAnalysiFileSelectPushed(app, event)
            [file,path] = uigetfile('*', 'Select g/FR data');
            if ~(max(path~=0))
                return;
            end
            app.ParametricAnalysisComputeButton.Enable = false;
            app.ParametricAnalysisExportButton.Enable = false;
            try
                data = readcell([path, file]);
                if ischar(data{1,1}) % remove optional header row from data
                    data = data(2:end, :);
                end
                data = cell2mat(data);
                app.parametric_analysis_accelerations = data(:,1);
                app.parametric_analysis_fill_ratios = data(:,2);
                app.parametric_analysis_times = [];
                if size(data,2) > 2
                    app.parametric_analysis_times = data(:,3);
                end
                app.ParametricAnalysisComputeButton.Enable = true;
                if max(data(:,2)) >= 100 || min(data(:,2)) <= 0 || min(data(:,1)) <= 0
                    uialert(app.SLOSHMLv0103UIFigure, 'Invalid parameters for some inputs. Liquid fill ratio ranges from 0 to 100 (exclusive), and acceleration must be greater than 0. Invalid rows will be set to NaN.','Warning','Icon','warning');
                end
                uialert(app.SLOSHMLv0103UIFigure,'Inputs loaded successfully', '', 'Icon', 'success');
            catch
                uialert(app.SLOSHMLv0103UIFigure, 'Failed to load inputs', '', 'Icon', 'error');
            end
        end

        % Button pushed function: ParametricAnalysisComputeButton
        function ParametricAnalysisComputeButtonPushed(app, event)
            if ~app.CALCULATE.Enable
                uialert(app.SLOSHMLv0103UIFigure, 'Tank geometry not specified', '', 'Icon', 'error');
                return;
            end
            
            g_vec = app.parametric_analysis_accelerations;
            FR_vec = app.parametric_analysis_fill_ratios;
            t_vec = app.parametric_analysis_times;
            num_modes = app.SpringModeSpinner.Value;
            
            % generate header row
            empty_cells = {};
            for i = 1:(num_modes)
                empty_cells{i} = '';
            end
            first_row = [{'acceleration', 'liquid fill ratio (%)', 'We', 'Fr', 'Bo', 'frequencies'} empty_cells(1:end-1) ...
                             {'modal masses'} empty_cells {'pendulum lengths'} empty_cells ...
                             {'pendulum mass heights'} empty_cells {'amplitude ratio'} empty_cells ... 
                             {'spring mass hinge points'} empty_cells {'modal spring stiffnesses'} empty_cells];
            if ~isempty(t_vec)
                first_row = [{'time'} first_row];
            end

            % for each data point, run get_geometry and calculate
            output_matrix = first_row;
            tic;
            loading_bar = uiprogressdlg(app.SLOSHMLv0103UIFigure,'Title','Running Parametric Analysis','Message',' ','Value',0,'Cancelable','on','ShowPercentage','on');
            for i = 1:length(g_vec)
                if loading_bar.CancelRequested
                    close(loading_bar);
                    return;
                end
                if FR_vec(i) > 1 && FR_vec(i) < 100 && g_vec(i) > 0
                    app.gEditField.Value = g_vec(i);
                    app.LiquidFillRatio.Value = FR_vec(i);
                    get_geometry(app);
                    calculate(app,'suppress waitbar');
                    row = [g_vec(i) FR_vec(i) app.WeEditField.Value app.FrEditField.Value app.BoEditField.Value ...
                           app.omega_k(1:num_modes)' app.M_k(1:num_modes+1)' app.L_k(1:num_modes+1)' app.H_k(1:num_modes+1)' ...
                           app.CM_k(1:num_modes+1)' app.H_k_spring(1:num_modes+1)' app.K_k(1:num_modes+1)'];
                    if ~isempty(t_vec)
                        row = [t_vec(i) row];
                    end
                    
                else
                    row = NaN(size(first_row));
                    if isempty(t_vec)
                        row(1) = g_vec(i); row(2) = FR_vec(i);
                    else
                        row(1) = t_vec(i); row(2) = g_vec(i); row(3) = FR_vec(i);
                    end
                end
                output_matrix = [output_matrix; num2cell(row)];

                % display time remaining/progress
                time_per_step = toc/i;
                time_remaining = time_per_step*(length(g_vec) - i);
                progress_string = ['Generating Data ... (' num2str(i) '/' num2str(length(g_vec)) ')   est. time remaining: ' datestr(time_remaining/3600/24, 'HH:MM:SS')];
                loading_bar.Message = progress_string;
                loading_bar.Value = i/length(g_vec);
                drawnow;
            end
            close(loading_bar);
            app.parametric_analysis_output_matrix = output_matrix;
            app.ParametricAnalysisExportButton.Enable = true;
        end

        % Button pushed function: ParametricAnalysisExportButton
        function ParametricAnalysisExportButtonPushed(app, event)
            % write results to file
            [file,path] = uiputfile('*.csv', 'Select output file');
            if max(path ~= 0)
                try
                    writecell(app.parametric_analysis_output_matrix, [path, file]);
                    uialert(app.SLOSHMLv0103UIFigure, 'Results exported successfully', 'Success', 'Icon', 'Success');
                catch
                    uialert(app.SLOSHMLv0103UIFigure,'Write to file failed', 'Failed', 'Icon','Error');
                end
            end
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxChanged(app, event)
            change_plot(app);
        end

        % Menu selected function: HelpMenu
        function HelpClicked(app, event)
            web('https://github.com/LGST-LAB/slosh_ml');
        end

        % Menu selected function: VersionMenu
        function VersionClicked(app, event)
            try
                installed_version = 'v0.103_beta';
                tags = webread('https://api.github.com/repos/LGST-LAB/slosh_ml/tags');
                most_recent_version = [0 0];
                major_latest = 0;
                minor_latest = 0;
                for i = 1:length(tags)
                    if length(regexp(tags(i).name,"v\d+[.]\d+.*")) == 0 % version numbers must be in this format: "vX.XX" or "vX.XXothertext" 
                        continue;
                    end
                    split_string = strsplit(tags(i).name, '.');
                    major        = str2num(split_string{1}(2:end));
                    minor        = strsplit(split_string{2},'_');
                    minor        = str2num(minor{1});
                    if major > major_latest || (major == major_latest && minor > minor_latest)
                        major_latest    = major;
                        minor_latest    = minor;
                        latest_version  = tags(i).name;
                    end
                end
                split_string = strsplit(installed_version,'.');
                major_installed = str2num(split_string{1}(2:end));
                minor_installed = strsplit(split_string{2},'_');
                minor_installed = str2num(minor_installed{1});
                if major_latest > major_installed || (major_latest == major_installed && minor_latest > minor_installed)
                    message = ['update available: ' latest_version ' at https://github.com/LGST-LAB/slosh_ml'];
                else
                    message = 'up to date';
                end
                uialert(app.SLOSHMLv0103UIFigure, ['Currently Installed Version: ' installed_version ' ... ' message], 'Version', 'Icon', 'info');
            catch
                uialert(app.SLOSHMLv0103UIFigure, ['Currently Installed Version: ' installed_version ' ... FAILED TO RETRIEVE MOST RECENT VERSION'], 'Version', 'Icon', 'info');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SLOSHMLv0103UIFigure = uifigure('Visible', 'off');
            app.SLOSHMLv0103UIFigure.Name = 'SLOSH-ML v. 0.103';
            app.SLOSHMLv0103UIFigure.Icon = fullfile(pathToMLAPP, 'SLOSH-ML_resources', 'icon_32.png');
            app.FileMenu = uimenu(app.SLOSHMLv0103UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionClicked, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionClicked, true);
            app.LoadMenu.Text = 'Load';
            app.AboutMenu = uimenu(app.SLOSHMLv0103UIFigure);
            app.AboutMenu.Text = 'About';
            app.HelpMenu = uimenu(app.AboutMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpClicked, true);
            app.HelpMenu.Text = 'Help';
            app.VersionMenu = uimenu(app.AboutMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionClicked, true);
            app.VersionMenu.Text = 'Version';
            app.mainaxes = uiaxes(app.SLOSHMLv0103UIFigure);
            xlabel(app.mainaxes, 'r (m)')
            ylabel(app.mainaxes, 'z (m)')
            app.mainaxes.Visible = 'off';
            app.SettingsTabGroup = uitabgroup(app.SLOSHMLv0103UIFigure);
            app.GeometryTab = uitab(app.SettingsTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.LoadContourButton = uibutton(app.GeometryTab, 'push');
            app.LoadContourButton.ButtonPushedFcn = createCallbackFcn(app, @loadContourButtonClicked, true);
            app.LoadContourButton.Text = 'Load Contour';
            app.FillRatioLabel = uilabel(app.GeometryTab);
            app.FillRatioLabel.Text = 'Fill Ratio';
            app.LiquidFillRatio = uispinner(app.GeometryTab);
            app.LiquidFillRatio.LowerLimitInclusive = 'off';
            app.LiquidFillRatio.UpperLimitInclusive = 'off';
            app.LiquidFillRatio.Limits = [0 100];
            app.LiquidFillRatio.ValueChangedFcn = createCallbackFcn(app, @LiquidFillRationChanged, true);
            app.LiquidFillRatio.Value = 65;
            app.Label = uilabel(app.GeometryTab);
            app.Label.Text = '%';
            app.RFactorLabel = uilabel(app.GeometryTab);
            app.RFactorLabel.Text = 'R Factor ';
            app.RF = uispinner(app.GeometryTab);
            app.RF.LowerLimitInclusive = 'off';
            app.RF.UpperLimitInclusive = 'off';
            app.RF.Limits = [0 Inf];
            app.RF.ValueChangedFcn = createCallbackFcn(app, @changeRF, true);
            app.RF.Value = 100;
            app.ZFactorLabel = uilabel(app.GeometryTab);
            app.ZFactorLabel.Text = 'Z Factor';
            app.ZF = uispinner(app.GeometryTab);
            app.ZF.LowerLimitInclusive = 'off';
            app.ZF.UpperLimitInclusive = 'off';
            app.ZF.Limits = [0 Inf];
            app.ZF.ValueChangedFcn = createCallbackFcn(app, @changeZF, true);
            app.ZF.Value = 100;
            app.Label_4 = uilabel(app.GeometryTab);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.GeometryTab);
            app.Label_5.Text = '%';
            app.PhysicsTab = uitab(app.SettingsTabGroup);
            app.PhysicsTab.Title = 'Physics';
            app.gEditFieldLabel = uilabel(app.PhysicsTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.gEditField.Limits = [0 Inf];
            app.gEditField.ValueDisplayFormat = '%.3f';
            app.gEditField.Value = 9.81;
            app.ms2Label = uilabel(app.PhysicsTab);
            app.ms2Label.Text = 'm/s2';
            app.mNmLabel = uilabel(app.PhysicsTab);
            app.mNmLabel.Text = 'mN/m';
            app.EditFieldLabel = uilabel(app.PhysicsTab);
            app.EditFieldLabel.Text = 'σ';
            app.sigmaEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.sigmaEditField.Limits = [0 Inf];
            app.sigmaEditField.ValueDisplayFormat = '%.3f';
            app.sigmaEditField.Value = 72.75;
            app.Kgm3Label = uilabel(app.PhysicsTab);
            app.Kgm3Label.Text = 'Kg/m3';
            app.Label_2 = uilabel(app.PhysicsTab);
            app.Label_2.Text = 'ρ';
            app.rhoEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.rhoEditField.Limits = [0 Inf];
            app.rhoEditField.ValueDisplayFormat = '%.3f';
            app.rhoEditField.Value = 997;
            app.ParamAnTab = uitab(app.SettingsTabGroup);
            app.ParamAnTab.Title = 'Param. An.';
            app.Label_8 = uilabel(app.ParamAnTab);
            app.Label_8.Text = 'Compute and save results';
            app.ParametricAnalysisComputeButton = uibutton(app.ParamAnTab, 'push');
            app.ParametricAnalysisComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysisComputeButtonPushed, true);
            app.ParametricAnalysisComputeButton.Enable = 'off';
            app.ParametricAnalysisComputeButton.Text = 'Compute';
            app.InputfilewithaccelerationandfillingratiodataLabel = uilabel(app.ParamAnTab);
            app.InputfilewithaccelerationandfillingratiodataLabel.Text = 'Input csv data with acceleration (column 1), percent filling ratio (column 2), and optional time (column 3).';
            app.SelectFileButton = uibutton(app.ParamAnTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysiFileSelectPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.ParametricAnalysisExportButton = uibutton(app.ParamAnTab, 'push');
            app.ParametricAnalysisExportButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysisExportButtonPushed, true);
            app.ParametricAnalysisExportButton.Enable = 'off';
            app.ParametricAnalysisExportButton.Text = 'Export';
            app.AdvancedTab = uitab(app.SettingsTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.NSpinnerLabel = uilabel(app.AdvancedTab);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.AdvancedTab);
            app.NSpinner.Limits = [10 Inf];
            app.NSpinner.RoundFractionalValues = 'on';
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @changeN, true);
            app.NSpinner.Value = 10;
            app.MSpinnerLabel = uilabel(app.AdvancedTab);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.AdvancedTab);
            app.MSpinner.Limits = [5 9];
            app.MSpinner.RoundFractionalValues = 'on';
            app.MSpinner.Value = 5;
            app.nSpinnerLabel = uilabel(app.AdvancedTab);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.AdvancedTab);
            app.nSpinner.Limits = [200 Inf];
            app.nSpinner.RoundFractionalValues = 'on';
            app.nSpinner.Value = 200;
            app.ShowModesLabel = uilabel(app.AdvancedTab);
            app.ShowModesLabel.Text = 'Number of Modes';
            app.SpringModeSpinner = uispinner(app.AdvancedTab);
            app.SpringModeSpinner.Limits = [0 5];
            app.SpringModeSpinner.RoundFractionalValues = 'on';
            app.SpringModeSpinner.Value = 3;
            app.RestoreDefaultSettingsButton = uibutton(app.AdvancedTab, 'push');
            app.RestoreDefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @restoreDefaultAdvancedSettings, true);
            app.RestoreDefaultSettingsButton.Text = 'Restore Default Settings';
            app.DisplayButtonGroup = uibuttongroup(app.SLOSHMLv0103UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonChanged, true);
            app.GeometryButton = uitogglebutton(app.DisplayButtonGroup);
            app.GeometryButton.Text = 'Geometry';
            app.GeometryButton.Value = true;
            app.MechanicalButton = uitogglebutton(app.DisplayButtonGroup);
            app.MechanicalButton.Text = 'Mechanical';
            app.ModesButton = uitogglebutton(app.DisplayButtonGroup);
            app.ModesButton.Text = 'Modes';
            app.OutputTabGroup = uitabgroup(app.SLOSHMLv0103UIFigure);
            app.BulkOutputTab = uitab(app.OutputTabGroup);
            app.BulkOutputTab.Title = 'Bulk Output';
            app.eigfntable = uitable(app.BulkOutputTab);
            app.eigfntable.ColumnName = {'1'; '2'; '3'; '4'};
            app.eigfntable.RowName = {'{}'};
            app.eigfntable.ColumnEditable = [true false false false];
            app.freqtable = uitable(app.BulkOutputTab);
            app.freqtable.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable.RowName = {'{}'};
            app.freqtable.ColumnEditable = [true false false false];
            app.EigenvectorsLabel = uilabel(app.BulkOutputTab);
            app.EigenvectorsLabel.Text = 'Eigenvectors';
            app.NaturalFrequenciesLabel = uilabel(app.BulkOutputTab);
            app.NaturalFrequenciesLabel.Text = 'Natural Frequencies';
            app.RegimeLabel = uilabel(app.BulkOutputTab);
            app.RegimeLabel.Text = 'Gravity D. ';
            app.WeEditFieldLabel = uilabel(app.BulkOutputTab);
            app.WeEditFieldLabel.Text = 'We';
            app.WeEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.WeEditField.Limits = [0 Inf];
            app.WeEditField.ValueDisplayFormat = '%.2f';
            app.WeEditField.Editable = 'off';
            app.BoEditFieldLabel = uilabel(app.BulkOutputTab);
            app.BoEditFieldLabel.Text = 'Bo';
            app.BoEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.BoEditField.Limits = [0 Inf];
            app.BoEditField.ValueDisplayFormat = '%.2f';
            app.BoEditField.Editable = 'off';
            app.FrEditFieldLabel = uilabel(app.BulkOutputTab);
            app.FrEditFieldLabel.Text = 'Fr';
            app.FrEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.FrEditField.Limits = [0 Inf];
            app.FrEditField.ValueDisplayFormat = '%.2f';
            app.FrEditField.Editable = 'off';
            app.VcEditFieldLabel = uilabel(app.BulkOutputTab);
            app.VcEditFieldLabel.Text = 'Vc';
            app.VcEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.VcEditField.Limits = [0 Inf];
            app.VcEditField.ValueDisplayFormat = '%.2f';
            app.VcEditField.Editable = 'off';
            app.msLabel = uilabel(app.BulkOutputTab);
            app.msLabel.Text = 'm/s';
            app.ModesTab = uitab(app.OutputTabGroup);
            app.ModesTab.Title = 'Modes';
            app.freqtable_2 = uitable(app.ModesTab);
            app.freqtable_2.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable_2.RowName = {};
            app.ModalCoefficientsLabel = uilabel(app.ModesTab);
            app.ModalCoefficientsLabel.Text = 'Modal Coefficients';
            app.ModeLabel = uilabel(app.ModesTab);
            app.ModeLabel.Text = 'Mode:';
            app.ModeSpinner = uispinner(app.ModesTab);
            app.ModeSpinner.Limits = [1 10];
            app.ModeSpinner.ValueChangedFcn = createCallbackFcn(app, @displayModeData, true);
            app.ModeSpinner.Enable = 'off';
            app.ModeSpinner.Value = 1;
            app.NaturalFrequencyLabel = uilabel(app.ModesTab);
            app.NaturalFrequencyLabel.Text = 'Natural Frequency:';
            app.NondimFrequencyLabel = uilabel(app.ModesTab);
            app.NondimFrequencyLabel.Text = 'Nondim. Frequency:';
            app.modes_natfreq = uilabel(app.ModesTab);
            app.modes_natfreq.Text = '0';
            app.modes_nondfreq = uilabel(app.ModesTab);
            app.modes_nondfreq.Text = '0';
            app.RadsLabel = uilabel(app.ModesTab);
            app.RadsLabel.Text = 'Rad/s';
            app.PendulumTab = uitab(app.OutputTabGroup);
            app.PendulumTab.Title = 'Pendulum';
            app.PendulumTable = uitable(app.PendulumTab);
            app.PendulumTable.ColumnName = {'Mi'; 'Li'; 'Hi'; 'CMi'};
            app.PendulumTable.RowName = {'{}'};
            app.SpringTab = uitab(app.OutputTabGroup);
            app.SpringTab.Title = 'Spring';
            app.SpringTable = uitable(app.SpringTab);
            app.SpringTable.ColumnName = {'Mi'; 'Hi'; 'Ki'};
            app.SpringTable.RowName = {'{}'};
            app.Tab = uitab(app.OutputTabGroup);
            app.Tab.Title = 'Tab';
            app.DropDownLabel = uilabel(app.Tab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Tab);
            app.EditFieldLabel_2 = uilabel(app.Tab);
            app.EditFieldLabel_2.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.CALCULATE = uibutton(app.SLOSHMLv0103UIFigure, 'push');
            app.CALCULATE.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CALCULATE.Enable = 'off';
            app.CALCULATE.Text = 'CALCULATE';
            app.DisplayGeometryPanel = uipanel(app.SLOSHMLv0103UIFigure);
            app.TankGeometryCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.TankGeometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeTankGeometryCheckBox, true);
            app.TankGeometryCheckBox.Enable = 'off';
            app.TankGeometryCheckBox.Text = 'Tank Geometry';
            app.TankGeometryCheckBox.Value = true;
            app.CenterofMassCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.CenterofMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeCenterofMassCheckBox, true);
            app.CenterofMassCheckBox.Enable = 'off';
            app.CenterofMassCheckBox.Text = 'Center of Mass';
            app.CenterofMassCheckBox.Value = true;
            app.LiquidFillLineCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.LiquidFillLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeLiquidFillLineCheckBox, true);
            app.LiquidFillLineCheckBox.Enable = 'off';
            app.LiquidFillLineCheckBox.Text = 'Liquid Fill Line';
            app.LiquidFillLineCheckBox.Value = true;
            app.DisplayModesPanel = uipanel(app.SLOSHMLv0103UIFigure);
            app.NumberofModesSpinner_2Label = uilabel(app.DisplayModesPanel);
            app.NumberofModesSpinner_2Label.Text = 'Number of Modes';
            app.NumberofModesSpinner = uispinner(app.DisplayModesPanel);
            app.NumberofModesSpinner.ValueChangedFcn = createCallbackFcn(app, @changeNumberofModesSpinner, true);
            app.NumberofModesSpinner.Editable = 'off';
            app.NumberofModesSpinner.Enable = 'off';
            app.MultiplicationFactorLabel = uilabel(app.DisplayModesPanel);
            app.MultiplicationFactorLabel.Text = 'Multiplication Factor';
            app.MultiplicationFactorEditField = uieditfield(app.DisplayModesPanel, 'numeric');
            app.MultiplicationFactorEditField.Limits = [0 Inf];
            app.MultiplicationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @changeMultiplicationFactorEditField, true);
            app.MultiplicationFactorEditField.Enable = 'off';
            app.MultiplicationFactorEditField.Value = 100;
            app.Label_9 = uilabel(app.DisplayModesPanel);
            app.Label_9.Text = '%';
            app.NormalizeCheckBox = uicheckbox(app.DisplayModesPanel);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxChanged, true);
            app.NormalizeCheckBox.Enable = 'off';
            app.NormalizeCheckBox.Text = 'Normalize';
            app.DisplayMechanicalPanel = uipanel(app.SLOSHMLv0103UIFigure);
            app.MechanicalAnalogyDropDownLabel = uilabel(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyDropDownLabel.Visible = 'off';
            app.MechanicalAnalogyDropDownLabel.Text = 'Mechanical Analogy';
            app.MechanicalAnalogyDropDown = uidropdown(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyDropDown.Items = {'None', 'Spring', 'Pendulum'};
            app.MechanicalAnalogyDropDown.ValueChangedFcn = createCallbackFcn(app, @changeMechanicalAnalogyDropDown, true);
            app.MechanicalAnalogyDropDown.Enable = 'off';
            app.MechanicalAnalogyDropDown.Visible = 'off';
            app.MechanicalAnalogyDropDown.Value = 'Spring';
            app.NumberofModesSpinner_2Label_2 = uilabel(app.DisplayMechanicalPanel);
            app.NumberofModesSpinner_2Label_2.Text = 'Number of Modes';
            app.MechanicalAnalogyCountSpinner = uispinner(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyCountSpinner.ValueChangedFcn = createCallbackFcn(app, @changeMechanicalAnalogyCountSpinner, true);
            app.MechanicalAnalogyCountSpinner.Enable = 'off';
            app.WelcomeImage = uiimage(app.SLOSHMLv0103UIFigure);
            app.WelcomeImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'SLOSH-ML_welcome_screen_img.png');
            % Show the figure after all components are created
            app.SLOSHMLv0103UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLOSH_ML

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLOSHMLv0103UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLOSHMLv0103UIFigure)
        end
    end
end