classdef ContourDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLOSHMLDesignContourUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        GeometryView                  matlab.ui.container.Tab
        plotgrid                      matlab.ui.container.GridLayout
        LeftGrid                      matlab.ui.container.GridLayout
        LoadImagePanel                matlab.ui.container.Panel
        ClearImageButton              matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        ResizeAxesPanel               matlab.ui.container.Panel
        ResizeViewLabel               matlab.ui.control.Label
        zmaxEditField                 matlab.ui.control.NumericEditField
        zmaxEditFieldLabel            matlab.ui.control.Label
        zminEditField                 matlab.ui.control.NumericEditField
        zminEditFieldLabel            matlab.ui.control.Label
        rmaxEditField                 matlab.ui.control.NumericEditField
        rmaxEditFieldLabel            matlab.ui.control.Label
        axes                          matlab.ui.control.UIAxes
        LogView                       matlab.ui.container.Tab
        LogTextBox                    matlab.ui.control.TextArea
        InputPanel                    matlab.ui.container.Panel
        StartingPointPanel            matlab.ui.container.Panel
        StartUndoButton               matlab.ui.control.Button
        LoadSTLButton                 matlab.ui.control.Button
        LoadSTLLabel                  matlab.ui.control.Label
        zStartEditField               matlab.ui.control.NumericEditField
        rLabel_2                      matlab.ui.control.Label
        rStartEditField               matlab.ui.control.NumericEditField
        rLabel                        matlab.ui.control.Label
        StartInputPromptLabel         matlab.ui.control.Label
        startContinueButton           matlab.ui.control.Button
        AddSegmentPanel               matlab.ui.container.Panel
        drawModeCheckBox              matlab.ui.control.CheckBox
        UndoButton                    matlab.ui.control.Button
        SegmentWarning                matlab.ui.control.Label
        FinishContourButton           matlab.ui.control.Button
        AddEllipsePanel               matlab.ui.container.Panel
        EllipseInvertCheckBox         matlab.ui.control.CheckBox
        EllipserendOutLabel           matlab.ui.control.Label
        EllipserendLabel              matlab.ui.control.Label
        EllipseZEndEditField          matlab.ui.control.NumericEditField
        zendEditFieldLabel_3          matlab.ui.control.Label
        AddEllipticalSegmentButton    matlab.ui.control.Button
        EllipseNumberofPointsSpinner  matlab.ui.control.Spinner
        NumberofPointsSpinnerLabel_2  matlab.ui.control.Label
        EllipseRadiusREditField       matlab.ui.control.NumericEditField
        radiusrLabel                  matlab.ui.control.Label
        EllipseRadiusZEditField       matlab.ui.control.NumericEditField
        radiuszLabel                  matlab.ui.control.Label
        EllipseZCenterEditField       matlab.ui.control.NumericEditField
        zcenterEditFieldLabel_2       matlab.ui.control.Label
        EllipseRCenterEditField       matlab.ui.control.NumericEditField
        rcenterEditFieldLabel_2       matlab.ui.control.Label
        AddLinePanel                  matlab.ui.container.Panel
        AddLineSegmentButton          matlab.ui.control.Button
        LineZEndEditField             matlab.ui.control.NumericEditField
        zendEditFieldLabel            matlab.ui.control.Label
        LineREndEditField             matlab.ui.control.NumericEditField
        rendEditFieldLabel            matlab.ui.control.Label
        AddCirclePanel                matlab.ui.container.Panel
        CircleRadiusLabel             matlab.ui.control.Label
        CircleRadiusOutLabel          matlab.ui.control.Label
        CirclerendOutLabel            matlab.ui.control.Label
        CirclerendLabel               matlab.ui.control.Label
        CircleInvertCheckBox          matlab.ui.control.CheckBox
        AddCircularSegmentButton      matlab.ui.control.Button
        CircleNumberOfPointsSpinner   matlab.ui.control.Spinner
        NumberofPointsSpinnerLabel    matlab.ui.control.Label
        CircleZEndEditField           matlab.ui.control.NumericEditField
        zendEditFieldLabel_2          matlab.ui.control.Label
        CircleZCenterEditField        matlab.ui.control.NumericEditField
        zcenterEditFieldLabel         matlab.ui.control.Label
        CircleRCenterEditField        matlab.ui.control.NumericEditField
        rcenterEditFieldLabel         matlab.ui.control.Label
        SegmentTypeDropDown           matlab.ui.control.DropDown
        SegmentTypeDropDownLabel      matlab.ui.control.Label
        AddSegmentPromptLabel         matlab.ui.control.Label
        ImportSTLPanel                matlab.ui.container.Panel
        STLLabel                      matlab.ui.control.Label
        STLWarningLabel               matlab.ui.control.Label
        CommentsLabel                 matlab.ui.control.Label
        CommentTextArea               matlab.ui.control.TextArea
        SaveandLoadButton             matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        saveload_pushed = false % checks if save/load button was pushed
    end

    properties (Access = private)
        MainApp                 % main SLOSH-ML app
        inner_segments          % cell array of structures with inner contour information
        outer_segments          % cell array of structures with outer contour information
        outer_contour_finished  % true if outer contour is finished
        inner_contour_finished  % true if inner contour is finished
        segment_types           % stores enumerations of segment types
        next_segment            % stores next segment to add to list
        last_id                 % last used id number for tank segments
        image_file              % filename of contour image
        image_origin            % origin of contour image
        image_reference         % coordinate of contour image reference point
        reference_distance      % reference measurement between origin and reference point
        drawn_segment           % stores drawn points to add to list
        draw_tolerance          % tolerance allowed before grid snapping
    end
    
    methods (Access = private)
        
        function [segment] = get_last_segment(app)
            if app.outer_contour_finished
                segment = app.inner_segments{end};
            else
                segment = app.outer_segments{end};
            end
        end
        
        function add_next_segment(app)            
            app.last_id = app.last_id + 1;
            app.next_segment.id = app.last_id;
            if app.outer_contour_finished
                app.inner_segments{end+1} = app.next_segment;
            else
                app.outer_segments{end+1} = app.next_segment;
            end
            r_max = max(app.next_segment.r);
            z_max = max(app.next_segment.z);
            app.rmaxEditField.Value = max(app.rmaxEditField.Value,r_max);
            app.zmaxEditField.Value = max(app.zmaxEditField.Value,z_max);
            update_log(app,"add");
            if ~app.drawModeCheckBox.Value && ~app.inner_contour_finished
                generate_default_segment_inputs(app);
                generate_next_segment(app);
            end
            if (~app.inner_contour_finished && app.outer_contour_finished && length(app.inner_segments) > 1) ...
               || (~app.outer_contour_finished && length(app.outer_segments) > 1)
                app.FinishContourButton.Enable = true;
            end
        end
        
        function update_plot(app)
            cla(app.axes);
            hold(app.axes,"on");
            axis(app.axes,"equal");
            def_color = "k";
            figure('Visible','off');
            fig = ancestor(app.axes, 'figure');
            if all(fig.Color < 0.5)
                def_color = "w";
            end
            
            % show image on plot
            if ~isempty(app.image_file)
                img = imread(app.image_file);
                img(:,1:app.image_origin(1),:) = [];
                [r,c,~] = size(img);
                pixel_distance = norm(app.image_reference - app.image_origin);
                scale = app.reference_distance ./ pixel_distance;
                r_min = (app.image_origin(2)-r)*scale;
                r_max = app.image_origin(2)*scale;
                img = image(app.axes, [0 c*scale], [r_min r_max], flip(img,1), 'HitTest', 'off');
                img.AlphaData = 0.6;
                uistack(img,'bottom')
                app.rmaxEditField.Value = c*scale;
                app.zminEditField.Value = r_min;
                app.zmaxEditField.Value = r_max;
                resize_axes(app);
                if isempty(app.outer_segments) && isempty(app.inner_segments)
                    app.draw_tolerance = (app.axes.XLim(2) - app.axes.XLim(1)) * 0.0125;
                    return
                end
            end

            % plot finished segments
            r_outer = [];
            z_outer = [];
            for i = 1:length(app.outer_segments)
                r_outer = [r_outer app.outer_segments{i}.r];
                z_outer = [z_outer app.outer_segments{i}.z];
            end
            r_inner = [];
            z_inner = [];           
            for i = 1:length(app.inner_segments)
                r_inner = [r_inner app.inner_segments{i}.r];
                z_inner = [z_inner app.inner_segments{i}.z];
            end
            plot(app.axes,real(r_outer),z_outer,"Color",def_color,'HitTest','off');
            plot(app.axes,real(r_inner),z_inner,"Color",def_color,'HitTest','off');

            % plot next segment
            r_next = [];
            z_next = [];
            if ~app.inner_contour_finished && length(app.outer_segments) > 0 && ~(app.outer_contour_finished && length(app.inner_segments)==0)
                r_next = [get_last_segment(app).r(end)];
                z_next = [get_last_segment(app).z(end)];
                if ~isempty(app.next_segment)
                    r_next = [r_next app.next_segment.r];
                    z_next = [z_next app.next_segment.z];
                end
                plot(app.axes,real(r_next),z_next,"Color","r","Marker",'.',"MarkerSize",10,'HitTest','off');
            else
                [z_max_inner,i_z_max_inner] = max(z_inner);
                [z_max_outer,i_z_max_outer] = max(z_outer);
                [z_min_inner,i_z_min_inner] = min(z_inner);
                [z_min_outer,i_z_min_outer] = min(z_outer);
                plot(app.axes,real([r_inner(i_z_max_inner) r_outer(i_z_max_outer)]),[z_max_inner z_max_outer],"Color",def_color,'HitTest','off');
                plot(app.axes,real([r_inner(i_z_min_inner) r_outer(i_z_min_outer)]),[z_min_inner z_min_outer],"Color",def_color,'HitTest','off');
            end
            r_max = max([r_inner r_outer r_next]);
            r_min = min([r_inner r_outer r_next]);
            z_max = max([z_inner z_outer z_next]);
            z_min = min([z_inner z_outer z_next]);
            tank_width = r_max - 0;
            tank_height = z_max - z_min;
            if max(size(tank_width)) == 0
                tank_width = 0;
                tank_height = 0;
            end
            app.draw_tolerance = (app.axes.XLim(2) - app.axes.XLim(1)) * 0.0125;
            resize_axes(app);
            rlims = app.axes.XLim;
            zlims = app.axes.YLim;
            if length(r_max) > 0
                axis(app.axes,[-0.1*r_max, max(rlims(end),r_max*1.1), min(zlims(1),z_min-tank_height*0.1), max(zlims(end),z_max+tank_height*0.1)]);
            end
        end
        
        function generate_next_segment(app)
            if (~app.outer_contour_finished && length(app.outer_segments)==0) || (app.outer_contour_finished && length(app.inner_segments)==0)
                update_plot(app);
                return;
            end
            last_segment = get_last_segment(app);
            r_last = last_segment.r(end);
            z_last = last_segment.z(end);
            app.next_segment.r = [];
            app.next_segment.z = [];
            app.next_segment.valid = true;
            app.SegmentWarning.Visible = false;
            if app.drawModeCheckBox.Value
                app.AddLineSegmentButton.Enable = false;
                app.AddCircularSegmentButton.Enable = false;
            else
                app.AddLineSegmentButton.Enable = true;
                app.AddCircularSegmentButton.Enable = true;
            end
            app.AddEllipticalSegmentButton.Enable = true;
            try
                if app.SegmentTypeDropDown.Value == "Line"
                    if app.drawModeCheckBox.Value
                        r_end = app.drawn_segment(1);
                        z_end = app.drawn_segment(2);
                    else
                        r_end = app.LineREndEditField.Value;
                        z_end = app.LineZEndEditField.Value;
                    end
                    app.next_segment.type = app.segment_types.line;
                    app.next_segment.r = r_end;
                    app.next_segment.z = z_end;
                    app.next_segment.r_end = r_end;
                    app.next_segment.z_end = z_end;
                    if z_end <= z_last
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                 "z values must be increasing";
                                                 "Change segment parameters to continue"];
                        app.AddLineSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    if r_end < 0
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                 "r values must be positive";
                                                 "Change segment parameters to continue"];
                        app.AddLineSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    if app.outer_contour_finished == true
                        r_outer = [];
                        z_outer = [];
                        for i = 1:length(app.outer_segments)
                            r_outer = [r_outer app.outer_segments{i}.r];
                            z_outer = [z_outer app.outer_segments{i}.z];
                        end
                        for outc = 1:length(r_outer)
                            rin = interp1([z_last, z_end], [r_last, r_end], z_outer(outc), 'pchip', 'extrap'); 
                            if r_outer(outc) < rin && z_outer(outc) < z_end && z_outer(outc) > z_last
                                    app.SegmentWarning.Visible = true;
                                    app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                             "Inner contour must not cross outer contour";
                                                             "Change segment parameters to continue"];
                                    app.AddLineSegmentButton.Enable = false;
                                    app.next_segment.valid = false;
                            end
                            if z_outer(outc) > z_end
                                break;
                            end
                        end
                    end
                elseif app.SegmentTypeDropDown.Value == "Circle"
                    if app.drawModeCheckBox.Value
                        % Calculate center and radius
                        r_mid = app.drawn_segment(1);
                        z_mid = app.drawn_segment(2);
                        r_end = app.drawn_segment(3);
                        z_end = app.drawn_segment(4);
                        p_1 = ([r_last z_last] + [r_mid z_mid])./2;
                        p_2 = ([r_mid z_mid] + [r_end z_end])./2;
                        m_1 = -(r_mid-r_last)./(z_mid-z_last);
                        m_2 = -(r_end-r_mid)./(z_end-z_mid);
                        r_center = (m_1*p_1(1)-m_2*p_2(1)-p_1(2)+p_2(2))/(m_1-m_2);
                        z_center = m_1*(r_center-p_1(1))+p_1(2);
                        app.CircleRCenterEditField.Value = r_center;
                        app.CircleZCenterEditField.Value = z_center;
                        n_points = app.drawn_segment(5);
                        app.CircleNumberOfPointsSpinner.Value = n_points;
                        app.CircleZEndEditField.Value = z_end;
                        theta_start = atan2(z_last-z_center,r_last-r_center);
                        beta_mid = wrapTo2Pi(atan2(z_mid-z_center,r_mid-r_center)-theta_start);
                        beta_end = wrapTo2Pi(atan2(z_end-z_center,r_end-r_center)-theta_start);
                        if beta_mid < beta_end
                            app.CircleInvertCheckBox.Value = false;
                            theta = linspace(0,beta_end,n_points+1) + theta_start;
                        else
                            app.CircleInvertCheckBox.Value = true;
                            theta = linspace(2*pi,beta_end,n_points+1) + theta_start;
                        end
                    end
                    r_center = app.CircleRCenterEditField.Value;
                    z_center = app.CircleZCenterEditField.Value;
                    z_end = app.CircleZEndEditField.Value;
                    n_points = app.CircleNumberOfPointsSpinner.Value;
                    inverted = app.CircleInvertCheckBox.Value;
                    radius = sqrt((r_last - r_center)^2 + (z_last - z_center)^2);

                    % Check for valid circle geometry
                    maxz = radius + z_center;
                    if z_end > maxz
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                   "z(end) value is too large to create a valid circle";
                                                   "Change segment parameters to continue"];
                        app.AddCircularSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end

                    if ~app.drawModeCheckBox.Value
                        r_start = sqrt(radius^2 - (z_last - z_center)^2) + r_center;
                        theta_start = atan2((z_last - z_center),(r_start-r_center));
                        r_end = sqrt(radius^2 - (z_end-z_center)^2) + r_center;
                        theta_end = atan2((z_end - z_center),(r_end-r_center));
                        theta = linspace(theta_start, theta_end, n_points+1);
                        
                    end
                    theta = theta(2:end);
                    r = r_center + radius*cos(theta);
                    if inverted && ~app.drawModeCheckBox.Value
                        r = 2*r_center - r;
                    end
                    if sum(r < 0) > 0
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                 "r values must be positive";
                                                 "Change segment parameters to continue"];
                        app.AddCircularSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    z = z_center + radius*sin(theta);
                    if app.outer_contour_finished == true
                        r_outer = [];
                        z_outer = [];
                        for i = 1:length(app.outer_segments)
                            r_outer = [r_outer app.outer_segments{i}.r];
                            z_outer = [z_outer app.outer_segments{i}.z];
                        end
                        for outc = 1:length(r_outer)
                            rin = interp1([z_last z], [r_last r], z_outer(outc), 'pchip', 'extrap'); 
                            if r_outer(outc) <= rin && z_outer(outc) < z(end) && z_outer(outc) > z_last
                                    app.SegmentWarning.Visible = true;
                                    app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                             "Inner contour must not cross outer contour";
                                                             "Change segment parameters to continue"];
                                    app.AddCircularSegmentButton.Enable = false;
                                    app.next_segment.valid = false;
                            end
                        end
                    end
                    app.next_segment.type = app.segment_types.circle;
                    app.next_segment.r = r;
                    app.next_segment.z = z;
                    app.next_segment.n_points = n_points;
                    app.next_segment.z_end = z_end;
                    app.next_segment.z_center = z_center;
                    app.next_segment.r_center = r_center;
                    app.next_segment.inverted = inverted;
                    app.CircleRadiusOutLabel.Text = num2str(radius);
                    app.CirclerendOutLabel.Text = num2str(r_end);
                    if sum((z(3:end) - z(2:end-1)) <= 0) > 0
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                   "z values must be increasing";
                                                   "Change segment parameters to continue"];
                        app.AddCircularSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                elseif app.SegmentTypeDropDown.Value == "Ellipse"
                    r_center = app.EllipseRCenterEditField.Value;
                    z_center = app.EllipseZCenterEditField.Value;
                    radius_r = app.EllipseRadiusREditField.Value;
                    radius_z = app.EllipseRadiusZEditField.Value;
                    z_end = app.EllipseZEndEditField.Value;
                    maxz = z_center + radius_r;
                    if z_end > maxz
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                   "z(end) value is too large to create a valid ellipse";
                                                   "Change segment parameters to continue"];
                        app.AddEllipticalSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    n_points = app.EllipseNumberofPointsSpinner.Value;
                    inverted = app.EllipseInvertCheckBox.Value;
                                        
                    a = radius_r; 
                    b = radius_z; 
                    
                    maxz = z_center + a;
                    if z_end > maxz
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                   "z(end) value is too large to create a valid ellipse";
                                                   "Change segment parameters to continue"];
                        app.AddEllipticalSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    
                    n_points = app.EllipseNumberofPointsSpinner.Value;
                    inverted = app.EllipseInvertCheckBox.Value;
                    
                    z_rel_start = (z_last - z_center) / a;
                    z_rel_end = (z_end - z_center) / a;
                    
                    z_rel_start = min(max(z_rel_start, -1), 1);
                    z_rel_end = min(max(z_rel_end, -1), 1);
                    
                    theta_start = acos(z_rel_start);
                    theta_end = acos(z_rel_end);
                    
                    theta = linspace(theta_start, theta_end, n_points + 1);
                    theta = theta(2:end); 
                    
                    z = z_center + a * cos(theta);
                    r = r_center + b * sin(theta);
                    
                    if inverted
                        r = r_center - b * sin(theta);
                    end 
                    if sum(r < 0) > 0
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                 "r values must be positive";
                                                 "Change segment parameters to continue"];
                        app.AddEllipticalSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                    if app.outer_contour_finished == true
                        r_outer = [];
                        z_outer = [];
                        for i = 1:length(app.outer_segments)
                            r_outer = [r_outer app.outer_segments{i}.r];
                            z_outer = [z_outer app.outer_segments{i}.z];
                        end
                        for outc = 1:length(r_outer)
                            rin = interp1([z_last z], [r_last r], z_outer(outc), 'pchip', 'extrap'); 
                            if r_outer(outc) <= rin && z_outer(outc) < z(end) && z_outer(outc) > z_last
                                    app.SegmentWarning.Visible = true;
                                    app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                             "Inner contour must not cross outer contour";
                                                             "Change segment parameters to continue"];
                                    app.AddEllipticalSegmentButton.Enable = false;
                                    app.next_segment.valid = false;
                            end
                        end
                    end
                    app.next_segment.type = app.segment_types.ellipse;
                    app.next_segment.r = r;
                    app.next_segment.z = z;
                    app.next_segment.n_points = n_points;
                    app.next_segment.z_end = z_end;
                    app.next_segment.radius_r = radius_r;
                    app.next_segment.radius_z = radius_z;
                    app.next_segment.z_center = z_center;
                    app.next_segment.r_center = r_center;
                    app.next_segment.inverted = inverted;
                    app.EllipserendOutLabel.Text = num2str(r(end));
                    if sum((z(3:end) - z(2:end-1)) <= 0) > 0
                        app.SegmentWarning.Visible = true;
                        app.SegmentWarning.Text = ["WARNING: SEGMENT IS INVALID";
                                                   "z values must be increasing";
                                                   "Change segment parameters to continue"];
                        app.AddEllipticalSegmentButton.Enable = false;
                        app.next_segment.valid = false;
                    end
                end
            catch

            end
            update_plot(app);
        end

        function save_contour(app)
            % This function saves the contour design to a .txt file

            % Generate inner and outer contour points
            r_outer = [];
            z_outer = [];
            for i = 1:length(app.outer_segments)
                r_outer = [r_outer app.outer_segments{i}.r];
                z_outer = [z_outer app.outer_segments{i}.z];
            end
            r_inner = [];
            z_inner = [];           
            for i = 1:length(app.inner_segments)
                r_inner = [r_inner app.inner_segments{i}.r];
                z_inner = [z_inner app.inner_segments{i}.z];
            end

            % File name prompt popup
            [file, path] = uiputfile('*.txt', 'Select Contour File', '.\contours\Untitled.txt');
            if max(path ~= 0)
                try
                    fileHandle = fopen([path, file], 'w');
        
                    % Save comments to file
                    comments = app.CommentTextArea.Value;
                    for i = 1:length(comments)
                        fprintf(fileHandle, '%% %s\n', comments{i});
                    end
        
                    % Save contour points to file
                    for i = 1:max([length(z_inner), length(z_outer)])
                        % Format contour points
                        if length(z_inner) < i
                            fprintf(fileHandle, 'NaN,NaN,%g,%g\n', z_outer(i), r_outer(i));
                        elseif length(z_outer) < i
                            fprintf(fileHandle, '%g,%g,NaN,NaN\n', z_inner(i), r_inner(i));
                        else
                            fprintf(fileHandle, '%g,%g,%g,%g\n', z_inner(i), r_inner(i), z_outer(i), r_outer(i));
                        end
                    end

                    fclose(fileHandle);
                catch
                    fclose(fileHandle);
                    uialert(app.SLOSHMLDesignContourUIFigure,'Failed to save contour data', 'Failed', 'Icon','Error');
                    return;
                end
            end
        end
        
        function stl_function(app)
            [app_file_directory,~,~] = fileparts(mfilename('fullpath')); 
            [file,path] = uigetfile('*.stl', 'Select contour file', [app_file_directory '\contours']);
            if max(path == 0)
                return;
            end
            tr = stlread([path file]);
            %Get points
            points = tr.Points - mean(tr.Points);
            triangles = tr.ConnectivityList;
            
            % get axis of symmetry
            xpoints = [points(:,1)];
            ypoints = [points(:,2)];
            zpoints = [points(:,3)];
            k1 = boundary(xpoints,ypoints,0);
            k2 = boundary(xpoints,zpoints,0);
            k3 = boundary(ypoints,zpoints,0);
            %Get Standard Deviation of outher contour
            std1 = std(sqrt(xpoints(k1).^2+ypoints(k1).^2));
            std2 = std(sqrt(xpoints(k2).^2+zpoints(k2).^2));
            std3 = std(sqrt(zpoints(k3).^2+ypoints(k3).^2));
            
            if std1 < std2 & std1 < std3
                axis = [0 0 1];
            elseif std2 < std3
                axis = [0 1 0];
            else
                axis = [1 0 0];
            end
            % get vector normal to plane of symmetry
            
            rand_plane_generator = rand(1,3);
            while cross(axis,rand_plane_generator) ~= [0 0 0]
                rand_plane_generator = rand(1,3);
            end
            vector_in_plane = cross(axis,rand_plane_generator);
            plane_normal = cross(axis,vector_in_plane);
            
            % remove triangles not intersecting plane
            keep_triangles = false(size(triangles,1),1);
            for i = 1:size(triangles,1)
                i1 = triangles(i,1);
                i2 = triangles(i,2);
                i3 = triangles(i,3);
                p1 = points(i1,:);
                p2 = points(i2,:);
                p3 = points(i3,:);
                side1 = dot(plane_normal,p1) > 0;
                side2 = dot(plane_normal,p2) > 0;
                side3 = dot(plane_normal,p3) > 0;
                
                if (side1 ~= side2 || side2 ~= side3 || side1 ~= side3)
                    keep_triangles(i) = true;
                end
            end
            triangles = triangles(keep_triangles,:);
            
            % place triangles in order of connectivity
            triangles_ordered = triangles(1,:);
            triangles = triangles(2:end,:);
            while size(triangles,1) > 0
                item_removed = false;
                for i = 1:size(triangles,1)
                    i_end = triangles_ordered(end,:);
                    i_start = triangles_ordered(1,:);
                    j = triangles(i,:);
                    if length(intersect(i_end,j)) == 2
                        triangles_ordered(end+1,:) = j;
                        triangles(i,:) = [];
                        item_removed = true;
                        break;
                    elseif length(intersect(i_start,j)) == 2
                        triangles_ordered = [j; triangles_ordered];
                        triangles(i,:) = [];
                        item_removed = true;
                        break;
                    end
                end
                if ~item_removed
                    break;
                end
            end
            triangles = triangles_ordered;
            triangles = sort(triangles,2);
            looped = length(intersect(triangles(end,:),triangles(1,:))) == 2;
            
            % Find line segments in order
            segments = [];
            for i = 1:size(triangles,1)
                i_next = i + 1;
                if i_next > size(triangles,1)
                    i_next = 1;
                end
                if length( intersect(triangles(i,[1 2]), triangles(i_next,:)) ) == 2
                    segments = [segments; triangles(i,[1 2])];
                elseif length( intersect(triangles(i,[2 3]), triangles(i_next,:)) ) == 2
                    segments = [segments; triangles(i,[2 3])];
                elseif length( intersect(triangles(i,[3 1]), triangles(i_next,:)) ) == 2
                    segments = [segments; triangles(i,[3 1])];
                end
            end
            
            % Find points where segments intersect plane
            p = zeros(0,3);
            for i = 1:size(segments,1)
                p(end+1,:) = line_plane_intersection(app,points(segments(i,1),:),points(segments(i,2),:),plane_normal); 
            end
            % Check if points cross midplane
            int_plane = cross(plane_normal,axis);
            int_plane = int_plane./norm(int_plane);
            is_torus = true;
            if any(dot(repmat(int_plane,size(p,1)-1,1),p(1:end-1,:),2) >= 0 & dot(repmat(int_plane,size(p,1)-1,1),p(2:end,:),2) < 0) ...
                    |any(dot(repmat(int_plane,size(p,1)-1,1),p(1:end-1,:),2) <= 0 & dot(repmat(int_plane,size(p,1)-1,1),p(2:end,:),2) > 0)
                is_torus = false;
            end
            if ~is_torus
                p(dot(repmat(int_plane,size(p,1),1),p,2) < 0,:) = [];
            end
            % convert to cylindrical coordinates
            r = NaN(size(p,1),1)';
            z = NaN(size(p,1),1)';
            for i = 1:size(p,1)%size(segments,1)
                r(i) = norm(cross(p(i,:),axis))/norm(axis);
                z(i) = dot(p(i,:),axis)/norm(axis);
            end
            r = r./1000;
            z = z./1000;
            
           
            % Get Inner and Outer Contour
            %Outer Contour
            z = z-min(z);
            maxx = max(z);
            minn = min(z);
            marginn = 0.001*min(sqrt(diff(z).^2+diff(r).^2));
            idx = 1:length(r);
            maxMask = z >= maxx-marginn;
            idx = idx(maxMask);
            [~,indmax] = max(r(idx));
            ind = idx(indmax);

            r = [r(ind:end) r(1:ind-1)];
            z = [z(ind:end) z(1:ind-1)];
            app.rmaxEditField.Value = max(r);
            app.zmaxEditField.Value = max(z);
            app.zminEditField.Value = min(z);
 
            if z(1) <= z(2) %Reorder if going counter clockwise
                r = r(end:-1:1);
                z = z(end:-1:1);
               
                idx = 1:length(r);
                maxMask = z >= maxx;
                idx = idx(maxMask);
                r(idx) = r(idx);
                [~,indmax] = max(r(idx));
                ind = idx(indmax);
                r = [r(ind:end) r(1:ind-1)];
                z = [z(ind:end) z(1:ind-1)];
            end
            
            ind = 1;
            outerPoints = [{r(1)},{z(1)}];
            
            while z(ind) > minn+marginn
                ind = ind+1;
                temp = [{r(ind)},{z(ind)}];
                outerPoints = [outerPoints; temp];
                
            end
            innerPoints = [];

            if is_torus
                while z(ind)+1 <= minn+marginn
                    ind = ind+1;
                end
                ind = ind-1;
                innerPoints = [{r(ind)},{z(ind)}];
                while ind < length(z) && z(ind) < maxx-marginn
                    ind = ind+1;
                    temp = [{r(ind)},{z(ind)}];
                    innerPoints = [innerPoints; temp];
                    
                    
                end
            else
                innerPoints = [{0,min(z)};{0,max(z)}];
            end
            %Turn points into contour file points
            innerPoints = innerPoints(end:-1:1,:);
            outerPoints = outerPoints(end:-1:1,:);
            
            segmentWarning = false;
            for i = 1:size(innerPoints,1)-1
                if innerPoints{i,2} < innerPoints{i+1,2}
                    innerPoints{i+1,2} = innerPoints{i,2};
                    segmentWarning = true;
                end
            end
            for i = 1:size(outerPoints,1)-1
                if outerPoints{i,2} > outerPoints{i+1,2}
                    outerPoints{i+1,2} = outerPoints{i,2};
                    segmentWarning = true;
                end
            end

            %Clear App Segments
            app.outer_segments = [];
            app.inner_segments = [];
            %Export to app variables
            
            for i = 1:size(innerPoints,1)
                app.inner_segments{i}.r = innerPoints{i,1};
                app.inner_segments{i}.z = innerPoints{i,2};
            end
            for i = 1:size(outerPoints,1)
                app.outer_segments{i}.r = outerPoints{i,1};
                app.outer_segments{i}.z = outerPoints{i,2};
            end
            app.outer_contour_finished = true;
            app.inner_contour_finished = true;
            update_plot(app);
            app.SaveButton.Enable = true;
            app.SaveandLoadButton.Enable = true;
            

            app.StartingPointPanel.Visible = false;
            app.StartingPointPanel.Enable = false;
            app.AddSegmentPanel.Visible = false;
            app.AddSegmentPanel.Enable = true;
            app.ImportSTLPanel.Enable = true;
            app.ImportSTLPanel.Visible = true;
            app.ClearButton.Enable = true;
            if segmentWarning
                app.STLWarningLabel.Visible=true;
            else
                app.STLWarningLabel.Visible=false;
            end
            update_log(app,"STL");
            
        end

        
        function p = line_plane_intersection(app,p1,p2,n)
            l = (p2-p1)/norm(p2-p1);
            d = -dot(p1,n)/dot(l,n);
            p = p1 + l*d;
        end

        function undo(app)
             % case where contour not started
            if length(app.outer_segments) == 0
                return;
            end
            % origin of outer contour specified but no segments added
            if length(app.outer_segments) == 1 && ~app.outer_contour_finished
                app.StartingPointPanel.Visible = true;
                app.StartingPointPanel.Enable = true;
                app.AddSegmentPanel.Visible = false;
                app.AddSegmentPanel.Enable = false;
                app.LoadSTLLabel.Visible = true;
                app.LoadSTLButton.Visible = true;
                segment = app.outer_segments{1};
                app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> Deleted origin [%d]",segment.id)];
                app.StartInputPromptLabel.Text = "Enter or click starting point for outer contour:";
                app.outer_segments = [];
                generate_next_segment(app);
                return;
            end
            % outer contour finished, inner contour not started
            if length(app.inner_segments) == 0 && app.outer_contour_finished
                app.AddSegmentPromptLabel.Text = "Add segments to outer contour:";
                app.StartingPointPanel.Visible = false;
                app.StartingPointPanel.Enable = false;
                app.AddSegmentPanel.Visible = true;
                app.AddSegmentPanel.Enable = true;
                app.outer_contour_finished = false;
                generate_next_segment(app);
                return;
            end
            % origin of inner contour specified but no segments added 
            if length(app.inner_segments) == 1 && app.outer_contour_finished
                app.StartInputPromptLabel.Text = "Enter or click starting point for inner contour:";
                app.StartingPointPanel.Visible = true;
                app.StartingPointPanel.Enable = true;
                app.AddSegmentPanel.Visible = false;
                app.AddSegmentPanel.Enable = false;
                segment = app.inner_segments{1};
                app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> Deleted origin [%d]",segment.id)];
                app.inner_segments = [];
                generate_next_segment(app);
                return;
            end
            % tank geometry finished
            if app.inner_contour_finished
                app.inner_contour_finished = false;
                app.SaveButton.Enable = false;
                app.SaveandLoadButton.Enable = false;
                app.LogTextBox.Value = [app.LogTextBox.Value; ">>> Editing inner contour"];
            end
            last_segment = get_last_segment(app);
            app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> Deleted segment [%d]", last_segment.id)];
            if app.outer_contour_finished
                app.inner_segments(end) = [];
                if length(app.inner_segments) < 2
                    app.FinishContourButton.Enable = false;
                end
            else
                app.outer_segments(end) = [];
                if length(app.outer_segments) < 2
                    app.FinishContourButton.Enable = false;
                end
            end
            app.AddLinePanel.Visible = false;
            app.AddCirclePanel.Visible = false;
            app.AddEllipsePanel.Visible = false;
            app.AddLinePanel.Enable = false;
            app.AddCirclePanel.Enable = false;
            app.AddEllipsePanel.Enable = false;
            switch last_segment.type
                case app.segment_types.line
                    app.SegmentTypeDropDown.Value = "Line";
                    app.AddLinePanel.Visible = true;
                    app.AddLinePanel.Enable = true;
                    app.LineZEndEditField.Value = last_segment.z_end;
                    app.LineREndEditField.Value = last_segment.r_end;
                case app.segment_types.circle
                    app.SegmentTypeDropDown.Value = "Circle";
                    app.AddCirclePanel.Visible = true;
                    app.AddCirclePanel.Enable = true;
                    app.CircleInvertCheckBox.Value = last_segment.inverted;
                    app.CircleNumberOfPointsSpinner.Value = last_segment.n_points;
                    app.CircleZEndEditField.Value = last_segment.z_end;
                    app.CircleZCenterEditField.Value = last_segment.z_center;
                    app.CircleRCenterEditField.Value = last_segment.r_center;
                case app.segment_types.ellipse
                    app.SegmentTypeDropDown.Value = "Ellipse";
                    app.AddEllipsePanel.Visible = true;
                    app.AddEllipsePanel.Enable = true;
                    app.EllipseNumberofPointsSpinner.Value = last_segment.n_points;
                    app.EllipseZEndEditField.Value = last_segment.z_end;
                    app.EllipseRadiusREditField.Value = last_segment.radius_r;
                    app.EllipseRadiusZEditField.Value = last_segment.radius_z;
                    app.EllipseZCenterEditField.Value = last_segment.z_center;
                    app.EllipseRCenterEditField.Value = last_segment.r_center;
                    app.EllipseInvertCheckBox.Value = last_segment.inverted;
            end
            generate_next_segment(app);
        end
        
        
        function update_log(app,action)
            last_segment = get_last_segment(app);
            if strcmp(action,"add")
                switch last_segment.type
                    case app.segment_types.circle
                        app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Added circle (r_center=%f, z_center=%f, z_end=%f, points=%d)",last_segment.id,last_segment.r_center, last_segment.z_center, last_segment.z_end, last_segment.n_points)];
                    case app.segment_types.line
                        app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Added line (r_end=%f, z_end=%f)", last_segment.id,last_segment.r_end, last_segment.z_end)];
                    case app.segment_types.ellipse
                        app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Added ellipse (r_radius=%f, z_radius=%f, r_center=%f, z_center=%f, z_end=%f, points=%d)", last_segment.id,last_segment.radius_r, last_segment.radius_z, last_segment.r_center, last_segment.z_center, last_segment.z_end, last_segment.n_points)];
                end
            elseif strcmp(action,"STL")
                app.LogTextBox.Value = [app.LogTextBox.Value; ">>> Successfully Imported STL File"];
            end
        end
        
        function generate_default_segment_inputs(app)
            last_segment = get_last_segment(app);
            rlast = last_segment.r(end);
            zlast = last_segment.z(end);
            app.EllipseInvertCheckBox.Value = false;
            app.EllipseZEndEditField.Value = zlast + 1;
            app.EllipseRadiusZEditField.Value = 2;
            app.EllipseRadiusREditField.Value = 1;
            app.EllipseZCenterEditField.Value = zlast + 1;
            app.EllipseRCenterEditField.Value = rlast;
            app.CircleInvertCheckBox.Value = false;
            app.CircleZEndEditField.Value = zlast + 1;
            app.CircleZCenterEditField.Value = zlast + 1;
            app.CircleRCenterEditField.Value = rlast;
            app.LineZEndEditField.Value = zlast + 1;
            app.LineREndEditField.Value = rlast;
        end
        
        function resize_axes(app)
            rmax = app.rmaxEditField.Value;
            zmin = app.zminEditField.Value;
            zmax = app.zmaxEditField.Value;
            curr_zlim = app.axes.YLim;
            if zmin >= zmax
                zmin = curr_zlim(1);
                zmax = curr_zlim(2);
            end
            height = zmax - zmin;
            axis(app.axes,"equal");
            axis(app.axes,[-0.1*rmax 1.1*rmax (zmin-height*0.1) (zmax+height*0.1)]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, MainApp)
            if exist('MainApp', 'var')
                app.MainApp = MainApp;
            end
            app.inner_segments = {};
            app.outer_segments = {};
            app.segment_types.start   = 0;
            app.segment_types.line    = 1;
            app.segment_types.circle  = 2;
            app.segment_types.ellipse = 3;
            app.outer_contour_finished = false;
            app.inner_contour_finished = false;
            app.image_file = [];
            app.image_origin = [];
            app.image_reference = [];
            app.drawn_segment = [];
            app.draw_tolerance = 0.0125;

            app.AddSegmentPanel.Visible = false;
            app.AddSegmentPanel.Enable = false;
            app.AddCirclePanel.Visible = false;
            app.AddCirclePanel.Enable = false;
            app.AddEllipsePanel.Visible = false;
            app.AddEllipsePanel.Enable = false;
            rlim = app.axes.XLim;
            zlim = app.axes.YLim;
            app.zmaxEditField.Value = zlim(end);
            app.zminEditField.Value = zlim(1);
            app.rmaxEditField.Value = rlim(end);

            app.last_id = 0;
        end

        % Close request function: SLOSHMLDesignContourUIFigure
        function CloseRequestFcn(app, event)
            try
                app.MainApp.DesignContourButton.Enable = true;
            end
            delete(app)
        end

        % Button pushed function: startContinueButton
        function startContinueButtonClicked(app, event)
            app.next_segment.type = app.segment_types.start;
            app.next_segment.r = app.rStartEditField.Value;
            app.next_segment.z = app.zStartEditField.Value;
            add_next_segment(app);
            app.StartingPointPanel.Visible = false;
            app.StartingPointPanel.Enable = false;
            app.AddSegmentPanel.Visible = true;
            app.AddSegmentPanel.Enable = true;
            app.LoadSTLLabel.Visible = false;
            app.LoadSTLButton.Visible = false;
            if app.outer_contour_finished
                app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Started inner contour at (%f,%f)",app.last_id,app.rStartEditField.Value,app.zStartEditField.Value)];
            else
                app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Started outer contour at (%f,%f)",app.last_id,app.rStartEditField.Value,app.zStartEditField.Value)];
            end
        end

        % Button pushed function: AddLineSegmentButton
        function AddLineSegmentButtonClicked(app, event)
            add_next_segment(app);
        end

        % Button pushed function: AddCircularSegmentButton
        function AddCircularSegmentButtonClicked(app, event)
            add_next_segment(app);
        end

        % Button pushed function: AddEllipticalSegmentButton
        function AddEllipticalSegmentButtonClicked(app, event)
            add_next_segment(app);
        end

        % Value changed function: SegmentTypeDropDown
        function SegmentTypeChanged(app, event)
            value = app.SegmentTypeDropDown.Value;
            app.AddLinePanel.Visible = false;
            app.AddLinePanel.Enable = false;
            app.AddCirclePanel.Visible = false;
            app.AddCirclePanel.Enable = false;
            app.AddEllipsePanel.Visible = false;
            app.AddEllipsePanel.Enable = false;
            if value == "Line"
                app.AddLinePanel.Visible = true;
                app.AddLinePanel.Enable = true;
                app.drawModeCheckBox.Visible = true;
            elseif value == "Circle"
                app.AddCirclePanel.Visible = true;
                app.AddCirclePanel.Enable = true;
                app.drawModeCheckBox.Visible = true;
            elseif value == "Ellipse"
                app.AddEllipsePanel.Visible = true;
                app.AddEllipsePanel.Enable = true;
                app.drawModeCheckBox.Visible = false;
            end
            generate_next_segment(app);
        end

        % Value changed function: LineREndEditField
        function LineREndChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: LineZEndEditField
        function LineZEndChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: CircleNumberOfPointsSpinner
        function CircleNumberOfPointsChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: CircleZEndEditField
        function CircleZEndChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: CircleZCenterEditField
        function CircleZCenterChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: CircleRCenterEditField
        function CircleRCenterChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseRCenterEditField
        function EllipseRCenterChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseZCenterEditField
        function EllipseZCenterChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseRadiusZEditField
        function EllipseRadiusZChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseRadiusREditField
        function EllipseRadiusRChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseNumberofPointsSpinner
        function EllipseNumberOfPointsChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseZEndEditField
        function EllipseZEndChanged(app, event)
            generate_next_segment(app);
        end

        % Value changed function: EllipseInvertCheckBox
        function EllipseInvertedChanged(app, event)
            generate_next_segment(app);
        end

        % Button pushed function: FinishContourButton
        function FinishContourClicked(app, event)
            app.SegmentWarning.Visible = false; 
            if app.outer_contour_finished
                app.inner_contour_finished = true;
                app.LogTextBox.Value = [app.LogTextBox.Value; ">>> Finished inner contour"];
                r_outer = [];
                z_outer = [];
                for i = 1:length(app.outer_segments)
                    r_outer = [r_outer app.outer_segments{i}.r];
                    z_outer = [z_outer app.outer_segments{i}.z];
                end
                r_inner = [];
                z_inner = [];  
                for i = 1:length(app.inner_segments)
                    r_inner = [r_inner app.inner_segments{i}.r];
                    z_inner = [z_inner app.inner_segments{i}.z];
                end
                [z_min_inner, i_z_min_inner] = min(z_inner);
                [z_max_inner, i_z_max_inner] = max(z_inner);
                [z_min_outer, i_z_min_outer] = min(z_outer);
                [z_max_outer, i_z_max_outer] = max(z_outer);
                % add vertical line segments if inner and outer contours do
                % not line up
                app.next_segment.type = app.segment_types.start;
                if z_min_inner < z_min_outer
                    app.next_segment.r = r_outer(i_z_min_outer);
                    app.next_segment.z = z_min_inner;
                    app.last_id = app.last_id + 1;
                    app.next_segment.id = app.last_id;
                    app.next_segment.type = app.segment_types.line;
                    if i_z_min_outer == 1
                        app.outer_segments = [{app.next_segment} app.outer_segments];
                    else
                        app.outer_segments{end+1} = app.next_segment;
                    end
                    app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Outer contour z_min extended",app.next_segment.id)];
                elseif z_min_inner > z_min_outer
                    app.next_segment.r = r_inner(i_z_min_inner);
                    app.next_segment.z = z_min_outer;
                    app.last_id = app.last_id + 1;
                    app.next_segment.id = app.last_id;
                    app.next_segment.type = app.segment_types.line;
                    if i_z_min_inner == 1
                        app.inner_segments = [{app.next_segment} app.inner_segments];
                    else
                        app.inner_segments{end+1} = app.next_segment;
                    end
                    app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Inner contour z_min extended",app.next_segment.id)];
                end
                if z_max_inner < z_max_outer
                    app.next_segment.r = r_inner(i_z_max_inner);
                    app.next_segment.z = z_max_outer;
                    app.last_id = app.last_id + 1;
                    app.next_segment.id = app.last_id;
                    app.next_segment.type = app.segment_types.line;
                    if i_z_max_inner == 1
                        app.inner_segments = [app.inner_segments {app.next_segment}];
                    else
                        app.inner_segments{end+1} = app.next_segment;
                    end
                    app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Inner contour z_max extended",app.next_segment.id)];
                elseif z_max_inner > z_max_outer
                    app.next_segment.r = r_outer(i_z_max_outer);
                    app.next_segment.z = z_max_inner;
                    app.last_id = app.last_id + 1;
                    app.next_segment.id = app.last_id;
                    app.next_segment.type = app.segment_types.line;
                    if i_z_max_outer == 1
                        app.outer_segments = [app.outer_segments {app.next_segment}];
                    else
                        app.outer_segments{end+1} = app.next_segment;
                    end
                    app.LogTextBox.Value = [app.LogTextBox.Value; sprintf(">>> [%d] Outer contour z_max extended",app.next_segment.id)];
                end
                update_plot(app);
                app.SaveButton.Enable = true;
                if ~isempty(app.MainApp)
                    app.SaveandLoadButton.Enable = true;
                end
            else
                if length(app.outer_segments) > 1
                    app.outer_contour_finished = true;
                    app.InputPanel.Title = "Design Inner Contour";
                    app.AddSegmentPromptLabel.Text = "Add segments to inner contour:";
                    app.StartInputPromptLabel.Text = "Enter or click starting point for inner contour:";
                    app.FinishContourButton.Text = "Finish Inner Contour";
                    app.StartingPointPanel.Visible = true;
                    app.StartingPointPanel.Enable = true;
                    app.AddSegmentPanel.Visible = false;
                    app.AddSegmentPanel.Enable = false;
                    app.LogTextBox.Value = [app.LogTextBox.Value; ">>> Finished outer contour"];
                else
                    uialert(app.SLOSHMLDesignContourUIFigure,'No segments were added', 'Could not finish outer contour', 'Icon','Error');
                end
            end
            app.FinishContourButton.Enable = false;
            generate_next_segment(app);
        end

        % Value changed function: CircleInvertCheckBox
        function CircleInvertChanged(app, event)
            generate_next_segment(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            save_contour(app);
        end

        % Button pushed function: SaveandLoadButton
        function SaveAndLoadButtonPushed(app, event)
            % Save contour
            save_contour(app);

            % Tell main app to load contour
            try
                app.MainApp.get_contour();
                app.MainApp.DesignContourButton.Enable = true;
            end
            delete(app)
        end

        % Button down function: axes
        function axesButtonDown(app, event)
            pt = get(app.axes,'CurrentPoint');
            if app.StartingPointPanel.Visible
                app.rStartEditField.Value = pt(1,1);
                app.zStartEditField.Value = pt(1,2);
                startContinueButtonClicked(app);
                return;
            end
            if app.drawModeCheckBox.Value && strcmp(app.SegmentTypeDropDown.Value,'Line')
                app.drawn_segment = [pt(1,1) pt(1,2)];
                app.drawn_segment(1) = app.drawn_segment(1)*(abs(app.drawn_segment(1))>app.draw_tolerance);                    
                generate_next_segment(app);
                if app.next_segment.valid
                    add_next_segment(app);
                end
                app.drawn_segment = [];

            elseif app.drawModeCheckBox.Value && strcmp(app.SegmentTypeDropDown.Value,'Circle')
                if isempty(app.drawn_segment)
                    app.drawn_segment = [pt(1,1) pt(1,2)];
                    app.drawn_segment(1) = app.drawn_segment(1)*(abs(app.drawn_segment(1))>app.draw_tolerance);
                    generate_next_segment(app);

                elseif length(app.drawn_segment) == 2
                    app.drawn_segment = [app.drawn_segment pt(1,1) pt(1,2)];
                    app.drawn_segment(3) = app.drawn_segment(3)*(abs(app.drawn_segment(3))>app.draw_tolerance);
                    def = {num2str(app.CircleNumberOfPointsSpinner.Value)};
                    opts.WindowStyle = 'modal';
                    num_points = inputdlg('Enter number of points:', '', [1 45], def, opts);
                    while isempty(str2double(num_points{1})) || mod(str2double(num_points{1}),1)>0
                        num_points = inputdlg('Invalid input. Number of points must be an integer. Enter number of points:', '', [1 45], def, opts);
                    end
                    app.drawn_segment = [app.drawn_segment str2double(num_points{1})];

                    % Check valid circle geometry
                    try
                        generate_next_segment(app);
                        if app.next_segment.valid
                            add_next_segment(app);
                            app.next_segment.r = app.next_segment.r(end);
                            app.next_segment.z = app.next_segment.z(end);
                        end
                        app.drawn_segment = [];
                    catch
                        app.drawn_segment = [];
                        if app.outer_contour_finished
                            app.inner_segments(end) = [];
                        else
                            app.outer_segments(end) = [];
                        end
                        uialert(app.SLOSHMLDesignContourUIFigure,'Invalid Circle Geometry', 'Failed', 'Icon','Error');
                        return;
                    end
                end
            end
            update_plot(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonClicked(app, event)

            selection = uiconfirm(app.SLOSHMLDesignContourUIFigure,"Clear geometry?","Clear","Icon","warning","Options",["Cancel","Clear"]);
            if strcmp(selection,"Clear")
                app.outer_segments = {};
                app.inner_segments = {};
                app.next_segment = [];
                app.inner_contour_finished = false;
                app.outer_contour_finished = false;
                app.StartingPointPanel.Enable = true;
                app.StartingPointPanel.Visible = true;
                app.AddSegmentPanel.Enable = false;
                app.AddSegmentPanel.Visible = false;
                app.ImportSTLPanel.Enable = false;
                app.ImportSTLPanel.Visible = false;
                app.STLWarningLabel.Visible = false;
                app.LoadSTLLabel.Visible = true;
                app.LoadSTLButton.Visible = true;
                app.AddLineSegmentButton.Enable = true;
                app.AddCircularSegmentButton.Enable = true;
                app.drawModeCheckBox.Value = false;
                app.SegmentTypeDropDown.Enable = true;
                app.rmaxEditField.Value = 1;
                app.zminEditField.Value = 0;
                app.zmaxEditField.Value = 1;
                app.StartInputPromptLabel.Text = "Enter or click starting point for outer contour:";
                app.update_plot();
                cla(app.axes);
            end
        end

        % Value changed function: rmaxEditField
        function rmaxChanged(app, event)
            update_plot(app);
        end

        % Value changed function: zminEditField
        function zminChanged(app, event)
            update_plot(app);
        end

        % Value changed function: zmaxEditField
        function zmaxChanged(app, event)
            update_plot(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageClicked(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg;*.img;*.tif;*.tiff'}, 'Select image file');
            if max(path ~= 0)
                % Process file (read image, set reference distance)
                try
                    app.image_file = [path,file];
                    [~] = uiconfirm(app.SLOSHMLDesignContourUIFigure, "Select origin point (must be on the axis of symmetry)", '', 'Options',"OK", 'Icon', '');
                    imshow(app.image_file);
                    drawnow
                    [app.image_origin(1),app.image_origin(2)] = ginput(1);
                    close(figure(1))
                    [~] = uiconfirm(app.SLOSHMLDesignContourUIFigure, "Select reference point", '', 'Options',"OK", 'Icon', '');
                    imshow(app.image_file);
                    drawnow
                    [app.image_reference(1),app.image_reference(2)] = ginput(1);
                    close(figure(1))
                    opts.WindowStyle = 'modal';
                    ref = inputdlg('Distance from origin to reference point:', '', [1 45], {'1'}, opts);
                    while isempty(str2double(ref{1})) || str2double(ref{1}) <= 0
                        ref = inputdlg('Invalid input. Reference distance must be a positive number. Enter reference distance:', '', [1 45], {'1'}, opts);
                    end
                    app.reference_distance = str2double(ref{1});
                    app.ClearImageButton.Enable = true;
                    update_plot(app);
                catch
                    uialert(app.SLOSHMLDesignContourUIFigure,'Failed to read image from file', 'Failed', 'Icon','Error');
                    return;
                end
            end
        end

        % Button pushed function: ClearImageButton
        function ClearImageClicked(app, event)
            if ~isempty(app.image_file)
                app.image_file = [];
                if isempty(app.outer_segments) && isempty(app.inner_segments)
                    cla(app.axes)
                else
                    update_plot(app)
                end
                app.ClearImageButton.Enable = false;
            end
        end

        % Button pushed function: UndoButton
        function UndoClicked(app, event)
            undo(app);
        end

        % Button pushed function: LoadSTLButton
        function LoadSTLClicked(app, event)
            stl_function(app);
        end

        % Value changed function: drawModeCheckBox
        function drawModeChanged(app, event)
            app.drawn_segment = [];
            if app.drawModeCheckBox.Value
                app.LineZEndEditField.Enable = false;
                app.LineREndEditField.Enable = false;
                app.CircleInvertCheckBox.Enable = false;
                app.CircleNumberOfPointsSpinner.Enable = false;
                app.CircleZEndEditField.Enable = false;
                app.CircleZCenterEditField.Enable = false;
                app.CircleRCenterEditField.Enable = false;
                app.SegmentTypeDropDown.Enable = false;
                app.SegmentWarning.Visible = false;
                app.AddLineSegmentButton.Enable = false;
                app.AddCircularSegmentButton.Enable = false;
                app.next_segment = [];
                update_plot(app);
            else
                app.LineZEndEditField.Enable = true;
                app.LineREndEditField.Enable = true;
                app.CircleInvertCheckBox.Enable = true;
                app.CircleNumberOfPointsSpinner.Enable = true;
                app.CircleZEndEditField.Enable = true;
                app.CircleZCenterEditField.Enable = true;
                app.CircleRCenterEditField.Enable = true;
                app.SegmentTypeDropDown.Enable = true;
                app.AddLineSegmentButton.Enable = true;
                app.AddCircularSegmentButton.Enable = true;
                generate_default_segment_inputs(app);
                generate_next_segment(app);
            end
        end

        % Button pushed function: StartUndoButton
        function StartUndoClicked(app, event)
            undo(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SLOSHMLDesignContourUIFigure = uifigure('Visible', 'off');
            app.SLOSHMLDesignContourUIFigure.Name = 'SLOSH-ML - Design Contour';
            app.SLOSHMLDesignContourUIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'logo@0.5x.png');
            app.SLOSHMLDesignContourUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.GridLayout = uigridlayout(app.SLOSHMLDesignContourUIFigure);
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Design Outer Contour';
            app.ClearButton = uibutton(app.InputPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonClicked, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.InputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SaveandLoadButton = uibutton(app.InputPanel, 'push');
            app.SaveandLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndLoadButtonPushed, true);
            app.SaveandLoadButton.Enable = 'off';
            app.SaveandLoadButton.Text = 'Save and Load';
            app.CommentTextArea = uitextarea(app.InputPanel);
            app.CommentsLabel = uilabel(app.InputPanel);
            app.CommentsLabel.Text = 'Comments';
            app.ImportSTLPanel = uipanel(app.InputPanel);
            app.ImportSTLPanel.Enable = 'off';
            app.ImportSTLPanel.Visible = 'off';
            app.STLWarningLabel = uilabel(app.ImportSTLPanel);
            app.STLWarningLabel.Visible = 'off';
            app.STLWarningLabel.Text = {'WARNING: Segment Geometry invalid.'; ' Some segments were altered.'};
            app.STLLabel = uilabel(app.ImportSTLPanel);
            app.STLLabel.Text = 'STL Successfully Imported';
            app.AddSegmentPanel = uipanel(app.InputPanel);
            app.AddSegmentPanel.Enable = 'off';
            app.AddSegmentPromptLabel = uilabel(app.AddSegmentPanel);
            app.AddSegmentPromptLabel.Text = 'Add segments to outer contour:';
            app.SegmentTypeDropDownLabel = uilabel(app.AddSegmentPanel);
            app.SegmentTypeDropDownLabel.Text = 'Segment Type';
            app.SegmentTypeDropDown = uidropdown(app.AddSegmentPanel);
            app.SegmentTypeDropDown.Items = {'Line', 'Circle', 'Ellipse'};
            app.SegmentTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentTypeChanged, true);
            app.SegmentTypeDropDown.Value = 'Line';
            app.AddCirclePanel = uipanel(app.AddSegmentPanel);
            app.AddCirclePanel.Enable = 'off';
            app.rcenterEditFieldLabel = uilabel(app.AddCirclePanel);
            app.rcenterEditFieldLabel.Text = 'r (center)';
            app.CircleRCenterEditField = uieditfield(app.AddCirclePanel, 'numeric');
            app.CircleRCenterEditField.ValueChangedFcn = createCallbackFcn(app, @CircleRCenterChanged, true);
            app.zcenterEditFieldLabel = uilabel(app.AddCirclePanel);
            app.zcenterEditFieldLabel.Text = 'z (center)';
            app.CircleZCenterEditField = uieditfield(app.AddCirclePanel, 'numeric');
            app.CircleZCenterEditField.ValueChangedFcn = createCallbackFcn(app, @CircleZCenterChanged, true);
            app.zendEditFieldLabel_2 = uilabel(app.AddCirclePanel);
            app.zendEditFieldLabel_2.Text = 'z (end)';
            app.CircleZEndEditField = uieditfield(app.AddCirclePanel, 'numeric');
            app.CircleZEndEditField.ValueChangedFcn = createCallbackFcn(app, @CircleZEndChanged, true);
            app.NumberofPointsSpinnerLabel = uilabel(app.AddCirclePanel);
            app.NumberofPointsSpinnerLabel.Text = 'Number of Points';
            app.CircleNumberOfPointsSpinner = uispinner(app.AddCirclePanel);
            app.CircleNumberOfPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @CircleNumberOfPointsChanged, true);
            app.CircleNumberOfPointsSpinner.Value = 50;
            app.AddCircularSegmentButton = uibutton(app.AddCirclePanel, 'push');
            app.AddCircularSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddCircularSegmentButtonClicked, true);
            app.AddCircularSegmentButton.Text = 'Add Circular Segment';
            app.CircleInvertCheckBox = uicheckbox(app.AddCirclePanel);
            app.CircleInvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @CircleInvertChanged, true);
            app.CircleInvertCheckBox.Text = 'invert';
            app.CirclerendLabel = uilabel(app.AddCirclePanel);
            app.CirclerendLabel.Text = 'r (end)';
            app.CirclerendOutLabel = uilabel(app.AddCirclePanel);
            app.CirclerendOutLabel.Text = '';
            app.CircleRadiusOutLabel = uilabel(app.AddCirclePanel);
            app.CircleRadiusOutLabel.Text = '';
            app.CircleRadiusLabel = uilabel(app.AddCirclePanel);
            app.CircleRadiusLabel.Text = 'Radius';
            app.AddLinePanel = uipanel(app.AddSegmentPanel);
            app.rendEditFieldLabel = uilabel(app.AddLinePanel);
            app.rendEditFieldLabel.Text = 'r (end)';
            app.LineREndEditField = uieditfield(app.AddLinePanel, 'numeric');
            app.LineREndEditField.ValueChangedFcn = createCallbackFcn(app, @LineREndChanged, true);
            app.zendEditFieldLabel = uilabel(app.AddLinePanel);
            app.zendEditFieldLabel.Text = 'z (end)';
            app.LineZEndEditField = uieditfield(app.AddLinePanel, 'numeric');
            app.LineZEndEditField.ValueChangedFcn = createCallbackFcn(app, @LineZEndChanged, true);
            app.AddLineSegmentButton = uibutton(app.AddLinePanel, 'push');
            app.AddLineSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddLineSegmentButtonClicked, true);
            app.AddLineSegmentButton.Text = 'Add Line Segment';
            app.AddEllipsePanel = uipanel(app.AddSegmentPanel);
            app.AddEllipsePanel.Enable = 'off';
            app.rcenterEditFieldLabel_2 = uilabel(app.AddEllipsePanel);
            app.rcenterEditFieldLabel_2.Text = 'r (center)';
            app.EllipseRCenterEditField = uieditfield(app.AddEllipsePanel, 'numeric');
            app.EllipseRCenterEditField.ValueChangedFcn = createCallbackFcn(app, @EllipseRCenterChanged, true);
            app.zcenterEditFieldLabel_2 = uilabel(app.AddEllipsePanel);
            app.zcenterEditFieldLabel_2.Text = 'z (center)';
            app.EllipseZCenterEditField = uieditfield(app.AddEllipsePanel, 'numeric');
            app.EllipseZCenterEditField.ValueChangedFcn = createCallbackFcn(app, @EllipseZCenterChanged, true);
            app.radiuszLabel = uilabel(app.AddEllipsePanel);
            app.radiuszLabel.Text = 'radius (z)';
            app.EllipseRadiusZEditField = uieditfield(app.AddEllipsePanel, 'numeric');
            app.EllipseRadiusZEditField.ValueChangedFcn = createCallbackFcn(app, @EllipseRadiusZChanged, true);
            app.radiusrLabel = uilabel(app.AddEllipsePanel);
            app.radiusrLabel.Text = 'radius (r)';
            app.EllipseRadiusREditField = uieditfield(app.AddEllipsePanel, 'numeric');
            app.EllipseRadiusREditField.ValueChangedFcn = createCallbackFcn(app, @EllipseRadiusRChanged, true);
            app.NumberofPointsSpinnerLabel_2 = uilabel(app.AddEllipsePanel);
            app.NumberofPointsSpinnerLabel_2.Text = 'Number of Points';
            app.EllipseNumberofPointsSpinner = uispinner(app.AddEllipsePanel);
            app.EllipseNumberofPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @EllipseNumberOfPointsChanged, true);
            app.EllipseNumberofPointsSpinner.Value = 50;
            app.AddEllipticalSegmentButton = uibutton(app.AddEllipsePanel, 'push');
            app.AddEllipticalSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddEllipticalSegmentButtonClicked, true);
            app.AddEllipticalSegmentButton.Text = 'Add Elliptical Segment';
            app.zendEditFieldLabel_3 = uilabel(app.AddEllipsePanel);
            app.zendEditFieldLabel_3.Text = 'z (end)';
            app.EllipseZEndEditField = uieditfield(app.AddEllipsePanel, 'numeric');
            app.EllipseZEndEditField.ValueChangedFcn = createCallbackFcn(app, @EllipseZEndChanged, true);
            app.EllipserendLabel = uilabel(app.AddEllipsePanel);
            app.EllipserendLabel.Text = 'r (end)';
            app.EllipserendOutLabel = uilabel(app.AddEllipsePanel);
            app.EllipserendOutLabel.Text = '';
            app.EllipseInvertCheckBox = uicheckbox(app.AddEllipsePanel);
            app.EllipseInvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipseInvertedChanged, true);
            app.EllipseInvertCheckBox.Text = 'invert';
            app.FinishContourButton = uibutton(app.AddSegmentPanel, 'push');
            app.FinishContourButton.ButtonPushedFcn = createCallbackFcn(app, @FinishContourClicked, true);
            app.FinishContourButton.Enable = 'off';
            app.FinishContourButton.Text = 'Finish Outer Contour';
            app.SegmentWarning = uilabel(app.AddSegmentPanel);
            app.SegmentWarning.Visible = 'off';
            app.SegmentWarning.Text = {'WARNING: SEGMENT IS INVALID'; 'z values must be increasing'; 'Change segment parameters to continue'};
            app.UndoButton = uibutton(app.AddSegmentPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoClicked, true);
            app.UndoButton.Text = 'Undo';
            app.drawModeCheckBox = uicheckbox(app.AddSegmentPanel);
            app.drawModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @drawModeChanged, true);
            app.drawModeCheckBox.Text = 'draw mode';
            app.StartingPointPanel = uipanel(app.InputPanel);
            app.startContinueButton = uibutton(app.StartingPointPanel, 'push');
            app.startContinueButton.ButtonPushedFcn = createCallbackFcn(app, @startContinueButtonClicked, true);
            app.startContinueButton.Text = 'continue';
            app.StartInputPromptLabel = uilabel(app.StartingPointPanel);
            app.StartInputPromptLabel.Text = 'Enter or click starting point for outer contour:';
            app.rLabel = uilabel(app.StartingPointPanel);
            app.rLabel.Text = 'r';
            app.rStartEditField = uieditfield(app.StartingPointPanel, 'numeric');
            app.rLabel_2 = uilabel(app.StartingPointPanel);
            app.rLabel_2.Text = 'z';
            app.zStartEditField = uieditfield(app.StartingPointPanel, 'numeric');
            app.LoadSTLLabel = uilabel(app.StartingPointPanel);
            app.LoadSTLLabel.Text = 'Load from STL (must be axisymmetric):';
            app.LoadSTLButton = uibutton(app.StartingPointPanel, 'push');
            app.LoadSTLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTLClicked, true);
            app.LoadSTLButton.Text = 'Load STL';
            app.StartUndoButton = uibutton(app.StartingPointPanel, 'push');
            app.StartUndoButton.ButtonPushedFcn = createCallbackFcn(app, @StartUndoClicked, true);
            app.StartUndoButton.Text = 'Undo';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeometryView = uitab(app.TabGroup);
            app.GeometryView.Title = 'Geometry View';
            app.plotgrid = uigridlayout(app.GeometryView);
            app.LeftGrid = uigridlayout(app.plotgrid);
            app.axes = uiaxes(app.LeftGrid);
            xlabel(app.axes, 'r')
            ylabel(app.axes, 'z')
            zlabel(app.axes, 'Z')
            app.axes.ButtonDownFcn = createCallbackFcn(app, @axesButtonDown, true);
            app.ResizeAxesPanel = uipanel(app.LeftGrid);
            app.rmaxEditFieldLabel = uilabel(app.ResizeAxesPanel);
            app.rmaxEditFieldLabel.Text = 'r (max)';
            app.rmaxEditField = uieditfield(app.ResizeAxesPanel, 'numeric');
            app.rmaxEditField.ValueChangedFcn = createCallbackFcn(app, @rmaxChanged, true);
            app.zminEditFieldLabel = uilabel(app.ResizeAxesPanel);
            app.zminEditFieldLabel.Text = 'z (min)';
            app.zminEditField = uieditfield(app.ResizeAxesPanel, 'numeric');
            app.zminEditField.ValueChangedFcn = createCallbackFcn(app, @zminChanged, true);
            app.zmaxEditFieldLabel = uilabel(app.ResizeAxesPanel);
            app.zmaxEditFieldLabel.Text = 'z (max)';
            app.zmaxEditField = uieditfield(app.ResizeAxesPanel, 'numeric');
            app.zmaxEditField.ValueChangedFcn = createCallbackFcn(app, @zmaxChanged, true);
            app.ResizeViewLabel = uilabel(app.ResizeAxesPanel);
            app.ResizeViewLabel.Text = 'Resize View:';
            app.LoadImagePanel = uipanel(app.LeftGrid);
            app.LoadImageButton = uibutton(app.LoadImagePanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageClicked, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClearImageButton = uibutton(app.LoadImagePanel, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageClicked, true);
            app.ClearImageButton.Enable = 'off';
            app.ClearImageButton.Text = 'Clear Image';
            app.LogView = uitab(app.TabGroup);
            app.LogView.Title = 'Log';
            app.LogTextBox = uitextarea(app.LogView);
            % Show the figure after all components are created
            app.SLOSHMLDesignContourUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContourDesigner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLOSHMLDesignContourUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLOSHMLDesignContourUIFigure)
        end
    end
end