classdef SLOSH_ML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLOSHMLv10UIFigure              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        ExportPlotMenu                  matlab.ui.container.Menu
        UnitsMenu                       matlab.ui.container.Menu
        MetricmkgsMenu                  matlab.ui.container.Menu
        ImperialftslugsMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        VersionMenu                     matlab.ui.container.Menu
        ReportBugMenu                   matlab.ui.container.Menu
        WelcomeImage                    matlab.ui.control.Image
        DisplayMechanicalPanel          matlab.ui.container.Panel
        MechanicalAnalogyCountSpinner   matlab.ui.control.Spinner
        NumberofModesSpinner_2Label_2   matlab.ui.control.Label
        MechanicalAnalogyDropDown       matlab.ui.control.DropDown
        MechanicalAnalogyDropDownLabel  matlab.ui.control.Label
        DisplayModesPanel               matlab.ui.container.Panel
        NormalizeCheckBox               matlab.ui.control.CheckBox
        Label_9                         matlab.ui.control.Label
        MultiplicationFactorEditField   matlab.ui.control.NumericEditField
        MultiplicationFactorLabel       matlab.ui.control.Label
        NumberofModesSpinner            matlab.ui.control.Spinner
        NumberofModesSpinner_2Label     matlab.ui.control.Label
        DisplayGeometryPanel            matlab.ui.container.Panel
        LiquidFillLineCheckBox          matlab.ui.control.CheckBox
        CenterofMassCheckBox            matlab.ui.control.CheckBox
        TankGeometryCheckBox            matlab.ui.control.CheckBox
        CALCULATE                       matlab.ui.control.Button
        OutputTabGroup                  matlab.ui.container.TabGroup
        BulkOutputTab                   matlab.ui.container.Tab
        msLabel                         matlab.ui.control.Label
        VcEditField                     matlab.ui.control.NumericEditField
        VcEditFieldLabel                matlab.ui.control.Label
        FrEditField                     matlab.ui.control.NumericEditField
        FrEditFieldLabel                matlab.ui.control.Label
        BoEditField                     matlab.ui.control.NumericEditField
        BoEditFieldLabel                matlab.ui.control.Label
        WeEditField                     matlab.ui.control.NumericEditField
        WeEditFieldLabel                matlab.ui.control.Label
        RegimeLabel                     matlab.ui.control.Label
        NaturalFrequenciesLabel         matlab.ui.control.Label
        EigenvectorsLabel               matlab.ui.control.Label
        freqtable                       matlab.ui.control.Table
        eigfntable                      matlab.ui.control.Table
        ModesTab                        matlab.ui.container.Tab
        RadsLabel                       matlab.ui.control.Label
        modes_nondfreq                  matlab.ui.control.Label
        modes_natfreq                   matlab.ui.control.Label
        NondimFrequencyLabel            matlab.ui.control.Label
        NaturalFrequencyLabel           matlab.ui.control.Label
        ModeSpinner                     matlab.ui.control.Spinner
        ModeLabel                       matlab.ui.control.Label
        ModalCoefficientsLabel          matlab.ui.control.Label
        freqtable_2                     matlab.ui.control.Table
        PendulumTab                     matlab.ui.container.Tab
        PendulumTable                   matlab.ui.control.Table
        SpringTab                       matlab.ui.container.Tab
        SpringTable                     matlab.ui.control.Table
        DampingTab                      matlab.ui.container.Tab
        AngleValidityLabel              matlab.ui.control.Label
        ConeAngleEditFieldLabel         matlab.ui.control.Label
        DampingRadiusLabel              matlab.ui.control.Label
        DampingFillHeightEditField      matlab.ui.control.NumericEditField
        FillHeightLabel                 matlab.ui.control.Label
        DampingRadiusEditField          matlab.ui.control.NumericEditField
        DampingConeAngleEditField       matlab.ui.control.NumericEditField
        DampingToroidMinorRadiusEditField  matlab.ui.control.NumericEditField
        DampingConeAngleUnitLabel       matlab.ui.control.Label
        DampingRatioLabel               matlab.ui.control.Label
        BaffleDampingRatioLabel         matlab.ui.control.Label
        DampingAccelerationUnitLabel    matlab.ui.control.Label
        DampingBaffleSpacingUnitLabel   matlab.ui.control.Label
        DampingFillHeightUnitLabel      matlab.ui.control.Label
        DampingBaffleDepthUnitLabel     matlab.ui.control.Label
        DampingBaffleWidthUnitLabel     matlab.ui.control.Label
        DampingRadiusUnitLabel          matlab.ui.control.Label
        DampingViscosityUnitLabel       matlab.ui.control.Label
        SubmergedBafflesLabel           matlab.ui.control.Label
        TopBaffleDepthLabel             matlab.ui.control.Label
        BaffleSpacingLabel              matlab.ui.control.Label
        ScalingFactorLabel              matlab.ui.control.Label
        BaffleWidthLabel                matlab.ui.control.Label
        SubmergedBafflesSpinner         matlab.ui.control.Spinner
        TopBaffleDepthEditField         matlab.ui.control.NumericEditField
        BaffleSpacingEditField          matlab.ui.control.NumericEditField
        ScalingFactorEditField          matlab.ui.control.NumericEditField
        BaffleWidthEditField            matlab.ui.control.NumericEditField
        DampinghRValue                  matlab.ui.control.Label
        DampinghRLabel                  matlab.ui.control.Label
        DampingValidityText             matlab.ui.control.Label
        DampingRatioOutput              matlab.ui.control.Label
        DampingAccelerationOutput       matlab.ui.control.Label
        DampingAccelerationLabel        matlab.ui.control.Label
        DampingViscosityOutput          matlab.ui.control.Label
        DampingViscosityLabel           matlab.ui.control.Label
        CorrelationReferenceHyperlink   matlab.ui.control.Hyperlink
        CorrelationReferenceLabel       matlab.ui.control.Label
        DampingGeometryDropDown         matlab.ui.control.DropDown
        SelectGeometryDropDownLabel     matlab.ui.control.Label
        DampingCorrelationDropDown      matlab.ui.control.DropDown
        SelectCorrelationDropDownLabel  matlab.ui.control.Label
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        ModesButton                     matlab.ui.control.ToggleButton
        MechanicalButton                matlab.ui.control.ToggleButton
        GeometryButton                  matlab.ui.control.ToggleButton
        SettingsTabGroup                matlab.ui.container.TabGroup
        GeometryTab                     matlab.ui.container.Tab
        DesignContourButton             matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ZF                              matlab.ui.control.Spinner
        ZFactorLabel                    matlab.ui.control.Label
        RF                              matlab.ui.control.Spinner
        RFactorLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LiquidFillRatio                 matlab.ui.control.Spinner
        FillRatioLabel                  matlab.ui.control.Label
        LoadContourButton               matlab.ui.control.Button
        PhysicsTab                      matlab.ui.container.Tab
        nuEditField                     matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        m2sLabel                        matlab.ui.control.Label
        rhoEditField                    matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        gEditField                      matlab.ui.control.NumericEditField
        gEditFieldLabel                 matlab.ui.control.Label
        ParamAnTab                      matlab.ui.container.Tab
        ParametricAnalysisExportButton  matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        InputfilewithaccelerationandfillingratiodataLabel  matlab.ui.control.Label
        ParametricAnalysisComputeButton  matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        RestoreDefaultSettingsButton    matlab.ui.control.Button
        SpringModeSpinner               matlab.ui.control.Spinner
        ShowModesLabel                  matlab.ui.control.Label
        nSpinner                        matlab.ui.control.Spinner
        nSpinnerLabel                   matlab.ui.control.Label
        MSpinner                        matlab.ui.control.Spinner
        MSpinnerLabel                   matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        NSpinnerLabel                   matlab.ui.control.Label
        mainaxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Initializing necessary parameters for the app components 
        % Contours
        IC_original         % Inner contour function handle, original contour file scale and origin
        OC_original         % Outer contour function handle, original contour file scale and origin
        IC_scaled_origin    % Inner contour, scaled, centered at file origin
        OC_scaled_origin    % Outer contour, scaled, centered at file origin
        IC_scaled_CM        % Inner contour, scaled, centered at CM
        OC_scaled_CM        % Outer contour, scaled, centered at CM
        IC_dimensionless    % Dimensionless inner contour function handle
        OC_dimensionless    % Dimensionless outer contour function handle

        % Geometry
        bIC                     % Boundaries of IC data ([rmin,rmax,zmin,zmax]) unscaled
        bOC                     % Boundaries of OC data ([rmin,rmax,zmin,zmax]) unscaled
        epsilon                 % Dimensionless inner surface contour (epsilon)
        L_a                     % Dimensionless fluid surface height wrt CM (L/a)
        L                       % Dimensional fluid surface height wrt CM
        a                       % Fluid surface external radius
        bens                    % Region boundaries, scaled
        flvol                   % Fluid volume
        H_origin                % Fluid height above original contour file origin
        H_tank                  % Fluid height from the bottom of the tank
        CM_pos                  % Fluid Center of Mass position (z)
        geometry_loaded         % True if there is a geometry loaded

        % Eigenvalue calculation
        Nm                      % N employed during last calculation
        Mm                      % M employed during last calculation
        gm                      % g employed during last calculation
        c_k                     % Eigenvectors
        lambda_k                % Eigenvalues
        Km                      % Dimensionless natural frequencies
        omega_k                 % Natural frequencies
        s_n                     % Intermediate parameter for the calculations
        phi_a                   % Intermediate parameter for the calculations
        Bm                      % Intermediate parameter for the calculations
        Bzerosm                 % dBessel zeros
        I_modeSorting           % Order of mode sorting after last calculation

        % Mechanical analogy
        H_k                     % Modal mass heights
        K_k                     % Modal spring stiffnesses
        H_k_spring              % Spring mass hinge point
        M_k                     % Modal masses
        L_k                     % Pendulum length
        CM_k                    % Ratio of slosh wave amplitude to mass amplitude
        characteristic_velocity % Characteristic velocity
        modes_calculated        % True if calculate() has run at least once since last call to get_geometry()

        % Parametric analysis
        parametric_analysis_fill_ratios
        parametric_analysis_accelerations
        parametric_analysis_times
        parametric_analysis_output_matrix

        % Units
        distance_unit
        mass_unit
        force_unit
        distance_factor
        mass_factor
        force_factor
        last_distance_factor
        last_mass_factor
        last_force_factor

        % Misc
        app_file_directory
        contour_designer_app
    end

    methods (Access = public)

        function get_contour (app)
            % This function processes the contour file

            % Inputs:
            % file          Contour file

            % Outputs:
            % IC_original   Inner contour function handle, original contour file scale and origin
            % bIC           Boundaries of IC data 
            % OC_original   Outer contour function handle, original contour file scale and origin
            % bOC           Boundaries of OC data 

            [file,path] = uigetfile('*.txt', 'Select contour file', [app.app_file_directory '\contours']);
            if max(path ~= 0)
                % Process file (read columns, remove NaN elements, sort)
                try
                    fileHandle = fopen([path,file], 'r');
                    line = fgetl(fileHandle);
                    ZRic_ZRoc = [];
                    while ischar(line)
                        line = strtok(line,'%');
                        if length(strfind(line,",")) == 3
                            for i = 1:4
                                [num, line] = strtok(line, ',');
                                numVec(i) = str2num(num);
                            end
                            ZRic_ZRoc = [ZRic_ZRoc; numVec];
                        end
                        line = fgetl(fileHandle);
                    end
                    fclose(fileHandle);

                    % ZRic_ZRoc   = readcell([path,file]); 
                    % 
                    % ZRic_ZRoc  = cell2mat(ZRic_ZRoc);
                    if size(ZRic_ZRoc,2) ~= 4
                        error('');
                    end
                    Z_ic         = ZRic_ZRoc(:,1);
                    Z_ic         = Z_ic(~isnan(Z_ic));
                    R_ic         = ZRic_ZRoc(:,2);
                    R_ic         = R_ic(~isnan(R_ic));
                    [Z_ic,I]     = sort(Z_ic);
                    R_ic         = R_ic(I);
                    Z_oc         = ZRic_ZRoc(:,3);
                    Z_oc         = Z_oc(~isnan(Z_oc));
                    R_oc         = ZRic_ZRoc(:,4);
                    R_oc         = R_oc(~isnan(R_oc));
                    [Z_oc,I]     = sort(Z_oc);
                    R_oc         = R_oc(I);
                catch
                    uialert(app.SLOSHMLv10UIFigure,'Failed to read contour data from file', 'Failed', 'Icon','Error');
                    return;
                end

                % Interpolation function
                r_ic             = @(z) interp1(Z_ic,R_ic,z);
                inner_boundaries = [min(R_ic),max(R_ic),min(Z_ic),max(Z_ic)];
                r_oc             = @(z) interp1(Z_oc,R_oc,z);
                outer_boundaries = [min(R_oc),max(R_oc),min(Z_oc),max(Z_oc)];
                
                % Save to app
                app.IC_original  = r_ic;
                app.bIC          = inner_boundaries;
                app.OC_original  = r_oc;
                app.bOC          = outer_boundaries;
                get_geometry(app);
            end
        end
    end

    methods (Access = private)

        function get_geometry (app)
            % This function uses the contours of the geometry to scale it
            % and center it around the center of mass

            % Inputs:
            % r_ic_file     Inner contour, unscaled, in file coordinate system
            % r_oc_file     Outer contour, unscaled, in file coordinate system
            % b_ic_file     Inner contour r and z boundaries, unscaled, in file coordinate system
            % b_oc_file     Outer contour r and z boundaries, unscaled, in file coordinate system
            % LFR           Liquid fill ratio (percent)
            % RFac          Factor to scale radius by (percent)
            % ZFac          Factor to scale z by (percent)

            % Outputs:
            % IC_dimensionless      Dimensionless inner contour function handle
            % OC_dimensionless      Dimensionless outer contour function handle
            % IC_scaled_CM          Inner contour, scaled, centered at CM
            % OC_scaled_CM          Outer contour, scaled, centered at CM
            % IC_scaled_origin      Inner contour, scaled, centered at file origin
            % OC_scaled_origin      Outer contour, scaled, centered at file origin
            % epsilon               Dimensionless inner surface contour (epsilon)
            % a                     Fluid surface external radius
            % flvol                 Fluid volume;
            % L_a                   Dimensionless fluid surface height wrt CM 
            % L                     Dimensional fluid surface height wrt CM
            % bens                  Region boundaries, scaled
            % H_tank                Fluid height from the bottom of the tank
            % H_origin              Fluid height above original contour file 
            % CM_pos                Fluid Center of Mass position (z)
 
            r_ic_file = app.IC_original;
            r_oc_file = app.OC_original;
            b_ic_file = app.bIC; 
            b_oc_file = app.bOC;
            LFR = app.LiquidFillRatio.Value/100;
            RFac = app.RF.Value/100;
            ZFac = app.ZF.Value/100;
            if isa(r_ic_file, 'function_handle') && isa(r_oc_file, 'function_handle') && numel(b_ic_file) == 4 && numel(b_oc_file) == 4
                % Scale geometry by ZFac and RFac
                b_ens_scaled_origin = [min(b_ic_file(1),b_oc_file(1))*RFac, max(b_ic_file(2),b_oc_file(2))*RFac, ...
                                       max(b_ic_file(3),b_oc_file(3))*ZFac, min(b_ic_file(4),b_oc_file(4))*ZFac]; % Tank boundaries, scaled, wrt original origin
                r_ic_scaled_origin  = @(z) r_ic_file(z/ZFac)*RFac; % Inner contour, scaled, wrt original origin
                r_oc_scaled_origin  = @(z) r_oc_file(z/ZFac)*RFac; % Inner contour, scaled, wrt original origin
    
                H_tank   = LFR*(b_ens_scaled_origin(4)-b_ens_scaled_origin(3)); % Fluid height, wrt tank bottom
                H_origin = LFR*(b_ens_scaled_origin(4)-b_ens_scaled_origin(3)) + b_ens_scaled_origin(3); % Fluid height, wrt original origin
    
                % Calculate fluid volume and center of mass location
                fluid_volume = integral(@(z) pi*((r_oc_scaled_origin(z).^2)-(r_ic_scaled_origin(z).^2)),b_ens_scaled_origin(3),H_origin);
                z_CM         = integral(@(z) z.*((r_oc_scaled_origin(z).^2) -(r_ic_scaled_origin(z).^2)),b_ens_scaled_origin(3),H_origin) ...
                               / integral(@(z) r_oc_scaled_origin(z).^2 -r_ic_scaled_origin(z).^2 ,b_ens_scaled_origin(3),H_origin);
    
                % Shift geometry to new origin (centered on the center of mass)
                b_ens_scaled_CM    = b_ens_scaled_origin - [0,0,z_CM,z_CM];
                r_ic_scaled_CM     = @(z) r_ic_scaled_origin(z + z_CM);
                r_oc_scaled_CM     = @(z) r_oc_scaled_origin(z + z_CM);
    
                L = H_tank-z_CM;
                
                % Generate relevant geometrical parameters
                a        = r_oc_scaled_CM(L);
                epsilon  = r_ic_scaled_CM(L)/a;
                L_a      = L/a;

                % Compute nondimensional contours
                R_ic_CM  = @(Z) r_ic_scaled_CM(Z*a)/a; 
                R_oc_CM  = @(Z) r_oc_scaled_CM(Z*a)/a;
                
                % Save results
                app.IC_dimensionless = R_ic_CM;
                app.OC_dimensionless = R_oc_CM;
                app.IC_scaled_CM     = r_ic_scaled_CM;
                app.OC_scaled_CM     = r_oc_scaled_CM;
                app.IC_scaled_origin = r_ic_scaled_origin;
                app.OC_scaled_origin = r_oc_scaled_origin;
                app.epsilon          = epsilon;
                app.a                = a;
                app.flvol            = fluid_volume;
                app.L_a              = L_a;
                app.L                = L;
                app.bens             = b_ens_scaled_CM;
                app.H_tank           = H_tank;
                app.H_origin         = H_origin;
                app.CM_pos           = z_CM;
                app.geometry_loaded  = true;
                
                % Enable calculate and geometry plot settings
                app.CALCULATE.Enable              = true;
                app.LiquidFillLineCheckBox.Enable = true;
                app.CenterofMassCheckBox.Enable   = true;
                app.TankGeometryCheckBox.Enable   = true;

                % Disable post-calculation controls
                app.MechanicalAnalogyDropDown.Enable     = false;
                app.MechanicalAnalogyCountSpinner.Enable = false;
                app.MultiplicationFactorEditField.Enable = false;
                app.NumberofModesSpinner.Enable          = false;
                app.NormalizeCheckBox.Enable             = false;
                app.modes_calculated                     = false;

                % Plot
                change_plot(app);
            end
        end

        function tf = isDarkMode(app)
            fig = ancestor(app.mainaxes, 'figure');
            tf = all(fig.Color < 0.5);
        end

        function plot_geometry (app)
            % This function plots the geometry and fluid level with the 
            % origin centered at the center of mass.
           
            if check_consistency(app)
                zv    = linspace(app.bens(3),app.bens(4),1000);
                hold(app.mainaxes, 'on')
                axis(app.mainaxes,'equal')
                % Add 10% margins around the contour
                gapr  = (app.bens(2)-app.bens(1))/10;
                gapz  = (app.bens(4)-app.bens(3))/10;
                gap   = mean([gapr,gapz]);
                axis(app.mainaxes, app.bens + [-app.bens(1) gap -gap gap])
                darkmode = isDarkMode(app);
                if darkmode  
                    contourColor = "w";
                    fluidColor = "#5ddffc";
                    massColor = "#30fc6a";
                else                    
                    contourColor = "k";
                    fluidColor = "b";
                    massColor = "#02bf2e";
                end
    
                % Plot fuel tank with saved results from get_geometry
                if app.TankGeometryCheckBox.Value
                    plot(app.mainaxes,app.IC_scaled_CM(zv),zv,contourColor,'LineWidth',2) % plot inner contour
                    plot(app.mainaxes,app.OC_scaled_CM(zv),zv,contourColor,'LineWidth',2) % plot outer contour
                    plot(app.mainaxes,[app.IC_scaled_CM(app.bens(3)*0.99999),app.OC_scaled_CM(app.bens(3)*0.99999)],app.bens(3)*[1,1],contourColor,'LineWidth',2)
                    plot(app.mainaxes,[app.IC_scaled_CM(app.bens(4)*0.99999),app.OC_scaled_CM(app.bens(4)*0.99999)],app.bens(4)*[1,1],contourColor,'LineWidth',2)
                end
    
                % Fluid level
                if app.LiquidFillLineCheckBox.Value
                    plot(app.mainaxes,[app.IC_scaled_CM(app.L),app.OC_scaled_CM(app.L)],[app.L,app.L],'Color',fluidColor,'LineWidth',1.2)
                end
    
                % Center of mass height
                if app.CenterofMassCheckBox.Value
                    plot(app.mainaxes,0,0,'ko','Markersize',10)
                    plot(app.mainaxes,[0,1.5*app.OC_scaled_CM(0)], [0,0],'--','Color',massColor,'LineWidth',1.2)
                    plot(app.mainaxes,[app.IC_scaled_CM(0),app.OC_scaled_CM(0)], [0,0],'Color',massColor,'LineWidth',1.2)
                end
            end
        end

        % Checks that inner and outer countours are consistent
        function [bool] = check_consistency(app)
            bool = 0;
            c1 = isa(app.IC_original, 'function_handle');
            c2 = isa(app.OC_original, 'function_handle');
            if c1 && c2 && length(app.bIC) == 4 && length(app.bOC) == 4
                c3 = max(app.bIC(3),app.bOC(3)) < min(app.bIC(4),app.bOC(4));
                if c1 && c2 && c3
                    bool = 1;
                end
            end
        end
        
        function plot_springs(app)
            % This function plots the springs on the graph

            % Inputs:
            % number_of_springs_to_plot     Number of springs to plot
            % H_k_spring                    Spring mass hinge point
            % b_ens                         Region boundaries, scaled

            darkmode = isDarkMode(app);
            facecolor = [0.8, 0.8, 0.8];
            color = [0.2, 0.2, 0.2];
            if darkmode  
                facecolor = 1-facecolor;
                color = 1-color;
            end

            if strcmpi(app.MechanicalAnalogyDropDown.Value, 'spring') && app.modes_calculated
                number_of_springs_to_plot = app.MechanicalAnalogyCountSpinner.Value+1;
                M_k = app.M_k;
                H_k_spring  = app.H_k_spring;
                b_ens       = app.bens;
                maxMass = max(M_k(1:number_of_springs_to_plot));
                for n = 1:number_of_springs_to_plot
                    scale = M_k(n)/maxMass;   % between 0 and 1
                    H_spring = H_k_spring(n)+b_ens(3);
                    if n == 1
                        xunit              = abs(b_ens(2) - b_ens(1)) ./ 15;
                        yunit              = abs(b_ens(4) - b_ens(3)) ./ 10;
                        rectangle(app.mainaxes, 'Position', [0, H_spring-yunit, xunit.*2, yunit.*2], 'FaceColor',facecolor, ...
                            'LineWidth', 1.25)
                        text(app.mainaxes, xunit, H_spring, ['m_', num2str(n-1)], 'HorizontalAlignment','center')
                    else
                        if H_spring >= b_ens(4) || H_spring <= b_ens(3)
                            out_of_bounds = true;
                            left = 0;
                            right = b_ens(2);
                        else
                            out_of_bounds = false;
                            left = 0;
                            right = app.OC_scaled_CM(H_spring);
                        end
                        mid                = (left + right) ./ 2;
                        baseX              = abs(b_ens(2) - b_ens(1)) ./ 10;
                        baseY              = abs(b_ens(4) - b_ens(3)) ./ 25;
                        xunit = baseX * (0.6 + 0.4*scale);
                        yunit = baseY * (0.6 + 0.4*scale);
                        [springx, springy] = horizspring(mid./4, H_spring, mid./4);
                        plot(app.mainaxes, [left,mid./4], [H_spring, H_spring], 'Color', color);
                        plot(app.mainaxes, springx, springy, 'Color', color);
                        if ~out_of_bounds
                            plot(app.mainaxes, [mid./2, right], [H_spring, H_spring], 'Color', color);
                        else
                            plot(app.mainaxes, [mid./2, mid], [H_spring, H_spring], 'Color', color);
                        end
                        rectangle(app.mainaxes, 'Position', [mid-xunit, H_spring-yunit, xunit.*2, yunit.*2], 'FaceColor',facecolor)
                        text(app.mainaxes, mid, H_spring, ['m_', num2str(n-1)], 'HorizontalAlignment','center')
                    end
                end
            end
        end
        
        function plot_pendulums(app)
            % This function plots the springs on the graph

            % Inputs:
            % number_of_pendulums_to_plot     Number of springs to plot
            % H_k                           Pendulum mass hinge point
            % L_k                           Pendulum lengths
            % b_ens                         Region boundaries, scaled

            darkmode = isDarkMode(app);
            facecolor = [0.8, 0.8, 0.8];
            color = [0.2, 0.2, 0.2];
            if darkmode  
                facecolor = 1-facecolor;
                color = 1-color;
            end

            if strcmpi(app.MechanicalAnalogyDropDown.Value, 'pendulum') && app.modes_calculated
                number_of_pendulums_to_plot = app.MechanicalAnalogyCountSpinner.Value+1;
                M_k = app.M_k;
                H_k  = app.H_k;
                L_k = app.L_k;
                b_ens       = app.bens;
                maxMass = max(M_k(1:number_of_pendulums_to_plot));
                for n = 1:number_of_pendulums_to_plot
                    scale = M_k(n)/maxMass;   % between 0 and 1
                    H_pendulum = H_k(n)+b_ens(3);
                    % if H_pendulum >= b_ens(4) || H_pendulum <= b_ens(3)
                    %     continue
                    % end
                    if n == 1
                        xunit              = abs(b_ens(2) - b_ens(1)) ./ 15;
                        yunit              = abs(b_ens(4) - b_ens(3)) ./ 10;
                        rectangle(app.mainaxes, 'Position', [0, H_pendulum-yunit, xunit.*2, yunit.*2], 'FaceColor',facecolor, ...
                            'LineWidth', 1.25)
                        text(app.mainaxes, xunit, H_pendulum, ['m_', num2str(n-1)], 'HorizontalAlignment','center')
                    else
                        baseR = min(abs(b_ens(2) - b_ens(1)) ./ 10, abs(b_ens(4) - b_ens(3)) ./ 10);
                        r = baseR * (0.6 + 0.4*scale);  % prevents near-zero size
                        plot(app.mainaxes,[0,cosd(60)*L_k(n)],[H_pendulum, H_pendulum-sind(60)*L_k(n)],'Color', color)
                        rectangle(app.mainaxes, 'Position', ...
                            [cosd(60)*L_k(n)-r, H_pendulum-sind(60)*L_k(n)-r, r*2, r*2], ...
                            'FaceColor',facecolor, 'Curvature',[1 1])
                        text(app.mainaxes, cosd(60)*L_k(n), H_pendulum-sind(60)*L_k(n), ['m_', num2str(n-1)], 'HorizontalAlignment','center')
                    end
                end
            end
        end
        
        function compute_mechanical_analogies(app)
            % This function calculates the values that are necessary to
            % model the system using mechanical analogies
            
            % Inputs:
            % epsilon       Dimensionless inner surface contour (epsilon)
            % L_a           Dimensionless fluid surface height wrt CM
            % a             Fluid surface external radius
            % R_ic          Inner contour (scaled and centered at center of mass)
            % R_oc          Outer contour (scaled and centered at center of mass)
            % intb          Region boundaries, scaled
            % N             N value
            % M             M value
            % g             Gravity value (from Physics tab)
            % rho           Fluid density
            % flvol         Fluid volume
            % H             Fluid height from the bottom of the tank
            % selm          Spring mode number
            % lambda_k      Eigenvalues
            % c_k           Eigenvectors
            % Bzeros        dBessel zeros

            % Outputs:
            % c_k                       Eigenvectors
            % lambda_k                  Eigenvalues
            % Km                        Dimensionless natural frequencies
            % omega_k                   Natural frequencies
            % Bzerosm                   dBessel zero employed during last calculation
            % Nm                        N employed during last calculation
            % Mm                        M employed during last calculation
            % gm                        Gravity employed during last calculation
            % s_n                       Intermediate parameter for the calculations
            % phi_a                     Intermediate parameter for the calculations
            % Bm                        Intermediate parameter for the calculations
            % H_k                       Modal mass heights
            % K_k                       Modal stiffnesses
            % M_k                       Modal masses
            % L_k                       Pendulum length
            % H_k_spring                Spring mass hinge point
            % CM_k                      Ratio of slosh wave amplitude to mass 
            % characteristic_velocity   Characteristic velocity
            % I_modeSorting             Order of mode sorting after last 
           
            % Load parameters
            epsilon      = app.epsilon;
            L_a          = app.L_a;
            a            = app.a;
            R_ic         = app.IC_dimensionless;
            R_oc         = app.OC_dimensionless;
            intb         = app.bens;
            N            = app.NSpinner.Value;
            M            = app.MSpinner.Value;
            g            = app.gEditField.Value;
            rho          = app.rhoEditField.Value;
            flvol        = app.flvol;
            H            = app.H_tank;
            selm         = app.SpringModeSpinner.Value;
            lambda_k     = app.lambda_k;
            c_k          = app.c_k;
            Bzeros       = app.Bzerosm;

            % Derived parameters pre-calculation
            B            = zeros(N,N);
            s_n          = zeros(N,1);
            phi_a        = zeros(N,1);
            for n = 1:N
                s_n(n,1)   = integral(@(z) (z-intb(3)/a).*R_oc(z).*phi(n,M,R_oc(z),z,Bzeros,L_a),intb(3)/a,L_a) - ...
                    integral(@(z) (z-intb(3)/a).*R_ic(z).*phi(n,M,R_ic(z),z,Bzeros,L_a),intb(3)/a,L_a);
                phi_a(n,1) = 0;
                for k=1:N
                    phi_a(n,1) =  phi_a(n,1) + c_k(k,n)*phi(k,M,R_oc(L_a),L_a,Bzeros,L_a);
                end

                for m = n:N
                    B(n,m) = Bint(n,m,M,Bzeros,epsilon);
                end
            end

            % Symmetry of B
            for m = 1:N
                for n = 1:N
                    B(n,m) = B(m,n);
                end
            end

            % Derived pendulum parameters
            [K,omega_k,Vc,M_k,L_k,H_k,CM_k,fluid_mass,c_k,lambda_k,I_sort] = derivedparams(rho,flvol,H,c_k,lambda_k,g,L_a*a,a,B,s_n,phi_a,intb,selm);

            % Derived spring parameters
            [K_k,H_k_spring] = pendulum2spring(M_k, L_k, g, H_k);

            % Save
            app.c_k           = c_k;
            app.lambda_k      = lambda_k;
            app.Km            = K;
            app.omega_k       = omega_k;
            app.Bzerosm       = Bzeros;
            app.Nm            = N;
            app.Mm            = M;
            app.gm            = g;
            app.s_n           = s_n;
            app.phi_a         = phi_a;
            app.Bm            = B;
            app.H_k           = H_k;
            app.K_k           = K_k;
            app.M_k           = M_k;
            app.L_k           = L_k;
            app.H_k_spring    = H_k_spring;
            app.CM_k          = CM_k;
            app.characteristic_velocity = Vc;
            app.I_modeSorting           = I_sort;

            % Set maximum springs to plot based on length of Hmods
            if app.MechanicalAnalogyCountSpinner.Value > length(H_k)
                app.MechanicalAnalogyCountSpinner.Value = length(H_k);
            end
            app.MechanicalAnalogyCountSpinner.Limits = [-1, length(H_k)];
        end

        function display_tables (app)
            % This function displays the calculated values that are used
            % to model the system using mechanical analogies 

            Vc             = app.characteristic_velocity;
            K              = app.Km;
            omega_k        = app.omega_k;
            H_k            = app.H_k;
            H_k_spring     = app.H_k_spring;
            K_k            = app.K_k;
            M_k            = app.M_k;
            L_k            = app.L_k;
            CM_k           = app.CM_k;
            rho            = app.rhoEditField.Value;
            sigma          = 1e-3 * app.sigmaEditField.Value;
            a              = app.a;
            g              = app.gEditField.Value;
            pendulum_count = app.SpringModeSpinner.Value;
            lambda_k       = app.lambda_k;
            c_k            = app.c_k;

            % Dimensionless numbers and regimes
            We             = rho*Vc^2*a/sigma;
            Fr             = Vc^2/g/a;
            Bo             = We/Fr;

            if We >= 1 && We >= Bo
                regime = 'Inertia D.';
                color  = [0,1,0];
            elseif Bo >= 1 && Bo > We
                regime = 'Gravity D.';
                color  = [0,1,0];
            else
                regime = 'Capillary D.';
                color  = [1,0,0];
            end


            % Display Regime information
            app.VcEditField.Value      = (Vc);
            app.WeEditField.Value      = (We);
            app.FrEditField.Value      = (Fr);
            app.BoEditField.Value      = (Bo);
            app.RegimeLabel.Text       = regime;
            app.RegimeLabel.FontColor  = color;

            % Display Frequencies
            lambda_diag = diag(lambda_k);
            disp_modes = 1:app.SpringModeSpinner.Value;
            data                       = [lambda_diag(disp_modes)';K(disp_modes)';omega_k(disp_modes)'];
            app.freqtable.Data         = data;
            app.freqtable.ColumnName   = 'numbered';
            app.freqtable.ColumnWidth  = repmat({60},1,length(K));
            app.freqtable.ColumnFormat = repmat({'short'},1,length(K));
            app.freqtable.RowName      = {'lambda';'K';'wn'};

            % Display Eigenvectors
            app.eigfntable.Data        = c_k(:,disp_modes);
            app.eigfntable.RowName     = 'numbered';
            app.eigfntable.ColumnName  = 'numbered';
            app.eigfntable.ColumnWidth = repmat({60},1,length(K));
            app.eigfntable.ColumnFormat= repmat({'short'},1,length(K));

            mechanical_analogy_row_names = {'Fixed Mass'};
            for i = 1:pendulum_count+1
                mechanical_analogy_row_names{end+1} = num2str(i);
            end
                
            % Display Pendulum Properties
            app.PendulumTable.Data        = [M_k(1:pendulum_count+1),L_k(1:pendulum_count+1),H_k(1:pendulum_count+1),CM_k(1:pendulum_count+1)];
            app.PendulumTable.RowName     = mechanical_analogy_row_names;
            app.PendulumTable.ColumnWidth = repmat({80},1,length(K));
            app.PendulumTable.ColumnFormat= repmat({'short'},1,length(K));

            % Display Spring Properties
            app.SpringTable.Data        = [M_k(1:pendulum_count+1),H_k_spring(1:pendulum_count+1),K_k(1:pendulum_count+1)];
            app.SpringTable.RowName     = mechanical_analogy_row_names;
            app.SpringTable.ColumnWidth = repmat({80},1,length(K));
            app.SpringTable.ColumnFormat= repmat({'short'},1,length(K));
        end

        function plot_modes(app)
            % This function plots the modes

            numberOfModesToPlot  = app.NumberofModesSpinner.Value;
            if check_consistency(app) && app.modes_calculated
                for i_mode = 1:numberOfModesToPlot
                    L               = app.L;
                    L_a             = app.L_a;
                    a               = app.a;
                    Bzeros          = app.Bzerosm;
                    r_ic_scaled_CM  = app.IC_scaled_CM;
                    r_oc_scaled_CM  = app.OC_scaled_CM;
                    omega_k         = app.omega_k;
                    g               = app.gEditField.Value;
                    N               = app.Nm;
                    M               = app.Mm;
                    c_k             = app.c_k;
                    multiplication_factor = app.MultiplicationFactorEditField.Value/100;
                    z_vec           = zeros(1,200);
                    r_vec           = linspace(r_ic_scaled_CM(L),r_oc_scaled_CM(L),200);
                    for  n = 1:N
                        z_vec = z_vec + c_k(n,i_mode)*phi(n,M,r_vec/a,L_a,Bzeros,L_a);
                    end
                    z_vec = z_vec*omega_k(i_mode)*a/g;
                    if app.NormalizeCheckBox.Value
                        z_vec = z_vec/max(abs(z_vec)) * L/2;
                    end
                    plot(app.mainaxes,r_vec,z_vec*multiplication_factor+L,'r');
                end
            end
        end

        function change_plot(app)
            app.WelcomeImage.Visible = false;
            app.mainaxes.Visible = true;
            app.ExportPlotMenu.Enable = true;
            cla(app.mainaxes); % clear plot
            plot_geometry(app);
            plot_springs(app);
            plot_pendulums(app); % will be implemented in a later version
            plot_modes(app);
        end
        
        function compute_damping_ratio(app)
            % This function calculates the damping ratio according to the
            % chosen correlation

            % Inputs: 
            % R         Radius of the tank
            % H         Fluid height from the bottom of the tank
            % g         Gravity value (from Physics tab)
            % nu        Kinematic viscosity (from Physics tab)

            % Outputs:
            % dampingRatio      Damping ratio

            if ~app.geometry_loaded
                return;
            end

            correlationName = app.DampingCorrelationDropDown.Value;
            geometryName = app.DampingGeometryDropDown.Value;

            % Load inputs
            R = app.DampingRadiusEditField.Value;
            h = app.DampingFillHeightEditField.Value;
            g = app.gEditField.Value; 
            nu = app.nuEditField.Value;
            a_cone = tand(app.DampingConeAngleEditField.Value)*h;
            baffle_width = app.BaffleWidthEditField.Value;
            scaling_factor = app.ScalingFactorEditField.Value;
            baffle_spacing = app.BaffleSpacingEditField.Value;
            n_submerged = app.SubmergedBafflesSpinner.Value;
            top_depth = app.TopBaffleDepthEditField.Value;
            toroid_minor_radius = app.DampingToroidMinorRadiusEditField.Value;

            if strcmp(geometryName, "Cylindrical")
                dampingRatio = damping_ratio_cylinder(correlationName, h, R, g, nu);
            elseif strcmp(geometryName, "Spherical")
                dampingRatio = damping_ratio_sphere(correlationName, h, R, g, nu);
            elseif strcmp(geometryName, "Cylindrical with Baffles")
                dampingRatio = damping_ratio_cylinder_baffles(correlationName, h, R, g, nu, ...
                               baffle_width, scaling_factor, baffle_spacing, n_submerged, top_depth);
            elseif strcmp(geometryName, "Conical")
                dampingRatio = damping_ratio_conical(correlationName, h, a_cone, g, nu);
            elseif strcmp(geometryName, "Toroidal")
                dampingRatio = damping_ratio_toroidal(correlationName, h, toroid_minor_radius, g, nu);
            else
                dampingRatio = NaN;
            end
            
            prev_format = format;
            format shortE
            app.DampingRatioOutput.Text = num2str(dampingRatio);
            format(prev_format);

            display_damping_information(app);
        end
        
        function display_damping_information(app)
            % This function displays references for the various
            % correlations in the app.

            correlation = app.DampingCorrelationDropDown.Value;
            if app.modes_calculated
                prev_format = format;
                format shortE
                h_R = app.DampingFillHeightEditField.Value/app.DampingRadiusEditField.Value;
                app.DampinghRValue.Text = num2str(h_R);
                if strcmp(app.DampingGeometryDropDown.Value, 'Conical')
                    app.DampinghRValue.Text = num2str(app.DampingConeAngleEditField.Value);
                end
                format(prev_format);

                % update validity information
                app.DampinghRValue.FontColor = [0 0.7 0];
                app.DampingValidityText.Text = "";
                if strcmp(correlation, 'Spherical (Mikishev, NASA SP-106, (h/R) <= 1)')
                    app.DampingValidityText.Text = "This correlation is valid for 0.1 < (h/R) <= 1";
                    if h_R < 0.1 || h_R > 1
                        app.DampinghRValue.FontColor = [1 0 0];
                    end
                elseif strcmp(correlation, 'Spherical (Mikishev, NASA SP-106, (h/R) >= 1)')
                    app.DampingValidityText.Text = "This correlation is valid for (h/R) >= 1";
                    if h_R < 1 || h_R > 2
                        app.DampinghRValue.FontColor = [1 0 0];
                    end
                elseif strcmp(correlation, 'Conical (NASA SP-106)')
                    app.DampingValidityText.Text = "This correlation is valid for cone half angle < 20 degrees";
                    alpha = app.DampingConeAngleEditField.Value;
                    if alpha > 20
                        app.DampinghRValue.FontColor = [1 0 0];
                    end
                end
            end

            % update correlation reference information
            if  (strcmp(correlation, 'Cylindrical (Stephens)'))
                % Reference; pp. 111 of NASA SP-106
                app.CorrelationReferenceLabel.Text = "Reference: D. G. Stephens, L. H. Wayne, and T. W. Perry, Investigation of the damping of " +...
                    "liquids in right-circular cylindrical tanks, including the effects of a time-variant liquid depth, " +...
                    "pp. 1, Tech. Rep. TN-D-1367, N62-14069, NASA (July 1962).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19620004069";
            elseif (strcmp(correlation, "Cylindrical (Mikishev, NASA SP-106)"))
                app.CorrelationReferenceLabel.Text = "Reference: H. Abramson, The dynamic behavior of liquids in moving containers, with " + ...
                    "applications to space vehicle technology, pp. 109, Tech. Rep. SP-106, N67-15884, NASA (August 1966).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19670006555";          
            elseif (strcmp(correlation, 'Spherical (Mikishev, NASA SP-106, (h/R) <= 1)') || +...
                strcmp(correlation, 'Spherical (Mikishev, NASA SP-106, (h/R) >= 1)'))
                % Reference; pp. 112 of NASA SP-106
                app.CorrelationReferenceLabel.Text = "Reference: H. Abramson, The dynamic behavior of liquids in moving containers, with " + ...
                    "applications to space vehicle technology, pp. 112, Tech. Rep. SP-106, N67-15884, NASA (August 1966).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19670006555";          
            elseif (strcmp(correlation, 'Cylindrical w/ Baffles (Bauer)'))
                app.CorrelationReferenceLabel.Text = "Reference: H. Bauer, The damping factor provided by flat annular ring baffles for " + ...
                    "free fluid surface oscillations, pp. 13, Tech. Rep. TM-X-50183, X63-14246, NASA (November 1962).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19630034299";
            elseif (strcmp(correlation, 'Cylindrical w/ Baffles (Coogan)'))
                app.CorrelationReferenceLabel.Text = "Reference: H. Coogan and S. Green, Critical review of damping prediction methods for " + ...
                    "annular right slosh baffles, AIAA 2019-4436, AIAA Propulsion and Energy 2019 Forum, AIAA (August 2019)";
                app.CorrelationReferenceHyperlink.URL = "https://arc.aiaa.org/doi/10.2514/6.2019-4436";
            elseif (strcmp(correlation, 'Conical (NASA SP-106)'))
                % Reference; pp. 114 of NASA SP-106
                app.CorrelationReferenceLabel.Text = "Reference: H. Abramson, The dynamic behavior of liquids in moving containers, with " + ...
                    "applications to space vehicle technology, pp. 114, Tech. Rep. SP-106, N67-15884, NASA (August 1966).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19670006555";
            elseif (strcmp(correlation, 'Toroidal (NASA SP-106)'))
                % Reference; pp. 114 of NASA SP-106
                app.CorrelationReferenceLabel.Text = "Reference: H. Abramson, The dynamic behavior of liquids in moving containers, with " + ...
                    "applications to space vehicle technology, pp. 114, Tech. Rep. SP-106, N67-15884, NASA (August 1966).";
                app.CorrelationReferenceHyperlink.URL = "https://ntrs.nasa.gov/citations/19670006555";
            else
                app.CorrelationReferenceLabel.Text = "MISSING";
            end


        end
        
        function update_input_units(app)
            app.gEditField.Value = app.gEditField.Value/(app.last_distance_factor);
            app.sigmaEditField.Value = app.sigmaEditField.Value/(app.last_force_factor/app.last_distance_factor);
            app.rhoEditField.Value = app.rhoEditField.Value/(app.last_mass_factor/app.last_distance_factor^3.0);
            app.nuEditField.Value = app.nuEditField.Value/(app.last_distance_factor^2.0);
            app.ms2Label.Text = app.distance_unit + "/s2";
            app.m2sLabel.Text = app.distance_unit + "2/s";
            app.NmLabel.Text = app.force_unit + "/" + app.distance_unit;
            app.kgm3Label.Text = app.mass_unit + "/" + app.distance_unit + "3";
            app.gEditField.Value = app.gEditField.Value*(app.distance_factor);
            app.sigmaEditField.Value = app.sigmaEditField.Value*(app.force_factor/app.distance_factor);
            app.rhoEditField.Value = app.rhoEditField.Value*(app.mass_factor/app.distance_factor^3.0);
            app.nuEditField.Value = app.nuEditField.Value*(app.distance_factor^2.0);
            app.mainaxes.XLabel.String = "r (" + app.distance_unit + ")";
            app.mainaxes.YLabel.String = "z (" + app.distance_unit + ")";
        end

        function update_output_units(app)
            app.msLabel.Text = app.distance_unit + "/s";
            app.DampingViscosityUnitLabel.Text = app.distance_unit + "2/s";
            app.DampingAccelerationUnitLabel.Text = app.distance_unit + "/s2";
            app.DampingRadiusUnitLabel.Text = app.distance_unit;
            app.DampingFillHeightUnitLabel.Text = app.distance_unit;
            app.DampingBaffleWidthUnitLabel.Text = app.distance_unit;
            app.DampingBaffleSpacingUnitLabel.Text = app.distance_unit;
            app.DampingBaffleDepthUnitLabel.Text = app.distance_unit;
        end
        
        function set_damping_inputs(app)
            app.DampingAccelerationOutput.Text = num2str(app.gEditField.Value);
            app.DampingFillHeightEditField.Value = app.H_tank;
            app.DampingRadiusEditField.Value = app.bens(2);
            app.DampingViscosityOutput.Text = num2str(app.nuEditField.Value);
            app.DampingConeAngleEditField.Value = atand(app.a/app.H_tank);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~ismember('Optimization Toolbox', {ver().Name})
                uialert(app.SLOSHMLv10UIFigure,'Missing Required Dependency: Optimization Toolbox', 'Warning', 'Icon', 'warning');
            end

            [app_file_directory,~,~] = fileparts(mfilename('fullpath')); 
            app.app_file_directory = app_file_directory;
            addpath([app.app_file_directory '/functions'])
            movegui(app.SLOSHMLv10UIFigure,'center')
            app.modes_calculated = false;
            app.geometry_loaded = false;
            app.distance_factor = 1.0;
            app.mass_factor = 1.0;
            app.force_factor = 1.0;
            metricmkgsSelected(app,0)
            DisplayButtonChanged(app);
        end

        % Button pushed function: CALCULATE
        function calculate(app, event)
            % This function calculates the eigenvectors, eigenvalues,
            % Bessel zeros, spring and pendulum parameters, and modes when
            % the calculate button is pressed. It also displays the
            % calculated modes in the app.

            % Inputs:
            % e         Epsilon []
            % L_a       Dimensionless fluid surface height (L/a) []
            % a         External radius at fluid surface [length]
            % R_ic      Inner contour (scaled and centered at center of mass)
            % R_oc      Outer contour (scaled and centered at center of mass)
            % intb      Region boundaries, scaled
            % nsteps    
            % N         
            % M         

            % Parameters
            e      = app.epsilon;
            L_a    = app.L_a;
            a      = app.a;
            R_ic   = app.IC_dimensionless;
            R_oc   = app.OC_dimensionless;
            intb   = app.bens;
            nsteps = app.nSpinner.Value;
            N      = app.NSpinner.Value;
            M      = app.MSpinner.Value;

            % Calculation
            [c_k,lambda_k,Bzeros]  = core(N,M,nsteps,intb/a,e,L_a,R_ic,R_oc,event);
            app.c_k                = c_k;
            app.lambda_k           = lambda_k;
            app.Bzerosm            = Bzeros;

            % Compute spring and pendulum parameters
            compute_mechanical_analogies(app);

            % Update maximum modes to plot
            if app.NumberofModesSpinner.Value > length(c_k)
                app.NumberofModesSpinner.Value = length(c_k);
            end
            app.NumberofModesSpinner.Limits = [0 length(c_k)];

            %Update Number of Mechanical Nodes to Mechanical Node Spinner
            app.MechanicalAnalogyCountSpinner.Limits = [-1 app.SpringModeSpinner.Value];
            
            % Enable post-calculation controls
            app.modes_calculated                     = true;
            app.MechanicalAnalogyCountSpinner.Enable = true;
            app.MechanicalAnalogyDropDown.Enable     = true;
            app.MultiplicationFactorEditField.Enable = true;
            app.NumberofModesSpinner.Enable          = true;
            app.NormalizeCheckBox.Enable             = true;

            % Display tables
            display_tables(app);
            displayModeData(app,0);
            set_damping_inputs(app);
            display_damping_information(app);
            compute_damping_ratio(app);
            app.ModeSpinner.Enable = true;
            update_output_units(app);

            % Plot
            change_plot(app);
        end

        % Value changed function: LiquidFillRatio
        function LiquidFillRationChanged(app, event)
            get_geometry(app);
        end

        % Value changed function: NSpinner
        function changeN(app, event)
            N                       = app.NSpinner.Value;
            app.MSpinner.Limits     = [5,N-1];
            app.ModeSpinner.Limits  = [1,N];
        end

        % Value changed function: RF
        function changeRF(app, event)
            get_geometry(app);
        end

        % Value changed function: ZF
        function changeZF(app, event)
            get_geometry(app);
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonChanged(app, event)
            % This function detects which button was selected and makes 
            % the corresponding settings panel visible

            selectedButton                      = app.DisplayButtonGroup.SelectedObject;
            app.DisplayGeometryPanel.Visible    = false;
            app.DisplayMechanicalPanel.Visible  = false;
            app.DisplayModesPanel.Visible       = false;
            if strcmp(selectedButton.Text, app.GeometryButton.Text)
                app.DisplayGeometryPanel.Visible = true;
            elseif strcmp(selectedButton.Text, app.MechanicalButton.Text)
                app.DisplayMechanicalPanel.Visible  = true;
            elseif strcmp(selectedButton.Text, app.ModesButton.Text)
                app.DisplayModesPanel.Visible       = true;
            end
        end

        % Value changed function: TankGeometryCheckBox
        function changeTankGeometryCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: CenterofMassCheckBox
        function changeCenterofMassCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: LiquidFillLineCheckBox
        function changeLiquidFillLineCheckBox(app, event)
            change_plot(app);
        end

        % Value changed function: MechanicalAnalogyCountSpinner
        function changeMechanicalAnalogyCountSpinner(app, event)
            change_plot(app);
        end

        % Value changed function: MechanicalAnalogyDropDown
        function changeMechanicalAnalogyDropDown(app, event)
            change_plot(app);
        end

        % Value changed function: MultiplicationFactorEditField
        function changeMultiplicationFactorEditField(app, event)
            change_plot(app);
        end

        % Value changed function: NumberofModesSpinner
        function changeNumberofModesSpinner(app, event)
            change_plot(app);
        end

        % Button pushed function: RestoreDefaultSettingsButton
        function restoreDefaultAdvancedSettings(app, event)
            app.SpringModeSpinner.Value = 3;
            app.nSpinner.Value          = 200;
            app.NSpinner.Value          = 10;
            app.MSpinner.Value          = 5;
        end

        % Value changed function: ModeSpinner
        function displayModeData(app, event)
            % This function updates the interface using the eigenmodes

            % Inputs:
            % c_k           Eigenvectors
            % omega_k       Natural frequencies
            % mode_number   Mode number
            % K             Dimensionless natural frequencies

            % Load parameters
            c_k          = app.c_k;
            omega_k      = app.omega_k;
            mode_number  = app.ModeSpinner.Value;
            K = app.Km;

            if ~isempty(c_k)
                % Update interface
                app.modes_natfreq.Text      = num2str(omega_k(mode_number));
                app.modes_nondfreq.Text     = num2str(K(mode_number));
                app.freqtable_2.Data = c_k(:,mode_number)';
                app.freqtable_2.ColumnWidth = repmat({60},1,length(K));
                app.freqtable_2.ColumnName  = 'numbered';
                app.freqtable_2.ColumnFormat= repmat({'short'},1,length(K));
            end
        end

        % Menu selected function: SaveMenu
        function SaveSessionClicked(app, event)
            % This function saves the various app parameters

            [file, path] = uiputfile('*.SLOSHML', 'Save session to file', [app.app_file_directory '\saves']);
            if max(path ~= 0)
                try
                    save_data = struct();
                    save_data.IC_original = app.IC_original;
                    save_data.OC_original = app.OC_original;
                    save_data.IC_scaled_origin = app.IC_scaled_origin;
                    save_data.OC_scaled_origin = app.OC_scaled_origin;
                    save_data.IC_scaled_CM = app.IC_scaled_CM;
                    save_data.OC_scaled_CM = app.OC_scaled_CM;
                    save_data.IC_dimensionless = app.IC_dimensionless;
                    save_data.OC_dimensionless = app.OC_dimensionless;
                    save_data.bIC = app.bIC;
                    save_data.bOC = app.bOC;
                    save_data.epsilon = app.epsilon;
                    save_data.L_a = app.L_a;
                    save_data.L = app.L;
                    save_data.a = app.a;
                    save_data.bens = app.bens;
                    save_data.flvol = app.flvol;
                    save_data.H_origin = app.H_origin;
                    save_data.H_tank = app.H_tank;
                    save_data.CM_pos = app.CM_pos;
                    save_data.Nm = app.Nm;
                    save_data.Mm = app.Mm;
                    save_data.gm = app.gm;
                    save_data.c_k = app.c_k;
                    save_data.lambda_k = app.lambda_k;
                    save_data.Km = app.Km;
                    save_data.omega_k = app.omega_k;
                    save_data.s_n = app.s_n;
                    save_data.phi_a = app.phi_a;
                    save_data.Bm = app.Bm;
                    save_data.Bzerosm = app.Bzerosm;
                    save_data.I_modeSorting = app.I_modeSorting;
                    save_data.H_k = app.H_k;
                    save_data.K_k = app.K_k;
                    save_data.H_k_spring = app.H_k_spring;
                    save_data.M_k = app.M_k;
                    save_data.L_k = app.L_k;
                    save_data.CM_k = app.CM_k;
                    save_data.characteristic_velocity = app.characteristic_velocity;
                    save_data.modes_calculated = app.modes_calculated;
                    save_data.parametric_analysis_fill_ratios = app.parametric_analysis_fill_ratios;
                    save_data.parametric_analysis_accelerations = app.parametric_analysis_accelerations;
                    save_data.parametric_analysis_times = app.parametric_analysis_times;
                    save_data.parametric_analysis_output_matrix = app.parametric_analysis_output_matrix;
                    save_data.distance_unit = app.distance_unit;
                    save_data.mass_unit = app.mass_unit;
                    save_data.force_unit = app.force_unit;
                    save_data.distance_factor = app.distance_factor;
                    save_data.mass_factor = app.mass_factor;
                    save_data.force_factor = app.force_factor;
                    save_data.last_distance_factor = app.last_distance_factor;
                    save_data.last_mass_factor = app.last_mass_factor;
                    save_data.last_force_factor = app.last_force_factor;
                    save_data.app_file_directory = app.app_file_directory;
                    save_data.WelcomeImage.Visible = app.WelcomeImage.Visible;
                    save_data.DisplayMechanicalPanel.Visible = app.DisplayMechanicalPanel.Visible;
                    save_data.MechanicalAnalogyCountSpinner.Enable = app.MechanicalAnalogyCountSpinner.Enable;
                    save_data.MechanicalAnalogyCountSpinner.Value = app.MechanicalAnalogyCountSpinner.Value;
                    save_data.MechanicalAnalogyCountSpinner.Limits = app.MechanicalAnalogyCountSpinner.Limits;
                    save_data.MechanicalAnalogyDropDown.Enable = app.MechanicalAnalogyDropDown.Enable;
                    save_data.MechanicalAnalogyDropDown.Value = app.MechanicalAnalogyDropDown.Value;
                    save_data.DisplayModesPanel.Visible = app.DisplayModesPanel.Visible;
                    save_data.NormalizeCheckBox.Enable = app.NormalizeCheckBox.Enable;
                    save_data.NormalizeCheckBox.Value = app.NormalizeCheckBox.Value;
                    save_data.MultiplicationFactorEditField.Enable = app.MultiplicationFactorEditField.Enable;
                    save_data.MultiplicationFactorEditField.Value = app.MultiplicationFactorEditField.Value;
                    save_data.NumberofModesSpinner.Enable = app.NumberofModesSpinner.Enable;
                    save_data.NumberofModesSpinner.Value = app.NumberofModesSpinner.Value;
                    save_data.NumberofModesSpinner.Limits = app.NumberofModesSpinner.Limits;
                    save_data.DisplayGeometryPanel.Visible = app.DisplayGeometryPanel.Visible;
                    save_data.LiquidFillLineCheckBox.Enable = app.LiquidFillLineCheckBox.Enable;
                    save_data.LiquidFillLineCheckBox.Value = app.LiquidFillLineCheckBox.Value;
                    save_data.CenterofMassCheckBox.Enable = app.CenterofMassCheckBox.Enable;
                    save_data.CenterofMassCheckBox.Value = app.CenterofMassCheckBox.Value;
                    save_data.TankGeometryCheckBox.Enable = app.TankGeometryCheckBox.Enable;
                    save_data.TankGeometryCheckBox.Value = app.TankGeometryCheckBox.Value;
                    save_data.CALCULATE.Enable = app.CALCULATE.Enable;
                    save_data.msLabel.Text = app.msLabel.Text;
                    save_data.VcEditField.Value = app.VcEditField.Value;
                    save_data.FrEditField.Value = app.FrEditField.Value;
                    save_data.BoEditField.Value = app.BoEditField.Value;
                    save_data.WeEditField.Value = app.WeEditField.Value;
                    save_data.RegimeLabel.Text = app.RegimeLabel.Text;
                    save_data.RegimeLabel.FontColor = app.RegimeLabel.FontColor;
                    save_data.freqtable.Data = app.freqtable.Data;
                    save_data.freqtable.ColumnName = app.freqtable.ColumnName;
                    save_data.freqtable.ColumnWidth = app.freqtable.ColumnWidth;
                    save_data.freqtable.ColumnFormat = app.freqtable.ColumnFormat;
                    save_data.freqtable.RowName = app.freqtable.RowName;
                    save_data.eigfntable.Data = app.eigfntable.Data;
                    save_data.eigfntable.RowName = app.eigfntable.RowName;
                    save_data.eigfntable.ColumnName = app.eigfntable.ColumnName;
                    save_data.eigfntable.ColumnWidth = app.eigfntable.ColumnWidth;
                    save_data.eigfntable.ColumnFormat = app.eigfntable.ColumnFormat;
                    save_data.modes_nondfreq.Text = app.modes_nondfreq.Text;
                    save_data.ModeSpinner.Enable = app.ModeSpinner.Enable;
                    save_data.ModeSpinner.Limits = app.ModeSpinner.Limits;
                    save_data.ModeSpinner.Value = app.ModeSpinner.Value;
                    save_data.freqtable_2.Data = app.freqtable_2.Data;
                    save_data.freqtable_2.ColumnWidth = app.freqtable_2.ColumnWidth;
                    save_data.freqtable_2.ColumnName = app.freqtable_2.ColumnName;
                    save_data.freqtable_2.ColumnFormat = app.freqtable_2.ColumnFormat;
                    save_data.PendulumTable.Data = app.PendulumTable.Data;
                    save_data.PendulumTable.RowName = app.PendulumTable.RowName;
                    save_data.PendulumTable.ColumnWidth = app.PendulumTable.ColumnWidth;
                    save_data.PendulumTable.ColumnFormat = app.PendulumTable.ColumnFormat;
                    save_data.SpringTable.Data = app.SpringTable.Data;
                    save_data.SpringTable.RowName = app.SpringTable.RowName;
                    save_data.SpringTable.ColumnWidth = app.SpringTable.ColumnWidth;
                    save_data.SpringTable.ColumnFormat = app.SpringTable.ColumnFormat;
                    save_data.DampingToroidMinorRadiusEditField.Visible = app.DampingToroidMinorRadiusEditField.Visible;
                    save_data.DampingToroidMinorRadiusEditField.Value = app.DampingToroidMinorRadiusEditField.Value;
                    save_data.DampingConeAngleUnitLabel.Visible = app.DampingConeAngleUnitLabel.Visible;
                    save_data.DampingConeAngleEditField.Visible = app.DampingConeAngleEditField.Visible;
                    save_data.DampingConeAngleEditField.Value = app.DampingConeAngleEditField.Value;
                    save_data.BaffleDampingRatioLabel.Visible = app.BaffleDampingRatioLabel.Visible;
                    save_data.BaffleDampingRatioLabel.Visible = app.BaffleDampingRatioLabel.Visible;
                    save_data.DampingAccelerationUnitLabel.Text = app.DampingAccelerationUnitLabel.Text;
                    save_data.DampingBaffleSpacingUnitLabel.Text = app.DampingBaffleSpacingUnitLabel.Text;
                    save_data.DampingBaffleSpacingUnitLabel.Visible = app.DampingBaffleSpacingUnitLabel.Visible;
                    save_data.DampingFillHeightUnitLabel.Text = app.DampingFillHeightUnitLabel.Text;
                    save_data.DampingBaffleDepthUnitLabel.Text = app.DampingBaffleDepthUnitLabel.Text;
                    save_data.DampingBaffleDepthUnitLabel.Visible = app.DampingBaffleDepthUnitLabel.Visible;
                    save_data.DampingBaffleWidthUnitLabel.Text = app.DampingBaffleWidthUnitLabel.Text;
                    save_data.DampingBaffleWidthUnitLabel.Visible = app.DampingBaffleWidthUnitLabel.Visible;
                    save_data.DampingRadiusUnitLabel.Text = app.DampingRadiusUnitLabel.Text;
                    save_data.DampingViscosityUnitLabel.Text = app.DampingViscosityUnitLabel.Text;
                    save_data.SubmergedBafflesLabel.Visible = app.SubmergedBafflesLabel.Visible;
                    save_data.TopBaffleDepthLabel.Visible = app.TopBaffleDepthLabel.Visible;
                    save_data.BaffleSpacingLabel.Visible = app.BaffleSpacingLabel.Visible;
                    save_data.BaffleWidthLabel.Visible = app.BaffleWidthLabel.Visible;
                    save_data.SubmergedBafflesSpinner.Value = app.SubmergedBafflesSpinner.Value;
                    save_data.SubmergedBafflesSpinner.Visible = app.SubmergedBafflesSpinner.Visible;
                    save_data.TopBaffleDepthEditField.Value = app.TopBaffleDepthEditField.Value;
                    save_data.TopBaffleDepthEditField.Visible = app.TopBaffleDepthEditField.Visible;
                    save_data.BaffleSpacingEditField.Value = app.BaffleSpacingEditField.Value;
                    save_data.BaffleSpacingEditField.Visible = app.BaffleSpacingEditField.Visible;
                    save_data.BaffleWidthEditField.Value = app.BaffleWidthEditField.Value;
                    save_data.BaffleWidthEditField.Visible = app.BaffleWidthEditField.Visible;
                    save_data.DampinghRValue.Text = app.DampinghRValue.Text;
                    save_data.DampinghRValue.FontColor = app.DampinghRValue.FontColor;
                    save_data.DampinghRLabel.Visible = app.DampinghRLabel.Visible;
                    save_data.DampingValidityText.Text = app.DampingValidityText.Text;
                    save_data.DampingRatioOutput.Text = app.DampingRatioOutput.Text;
                    save_data.DampingRatioLabel.Visible = app.DampingRatioLabel.Visible;
                    save_data.DampingAccelerationOutput.Text = app.DampingAccelerationOutput.Text;
                    save_data.DampingViscosityOutput.Text = app.DampingViscosityOutput.Text;
                    save_data.DampingFillHeightEditField.Value = app.DampingFillHeightEditField.Value;
                    save_data.DampingRadiusEditField.Value = app.DampingRadiusEditField.Value;
                    save_data.CorrelationReferenceHyperlink.URL = app.CorrelationReferenceHyperlink.URL;
                    save_data.DampingGeometryDropDown.Value = app.DampingGeometryDropDown.Value;
                    save_data.DampingCorrelationDropDown.Value = app.DampingCorrelationDropDown.Value;
                    save_data.DampingCorrelationDropDown.Items = app.DampingCorrelationDropDown.Items;
                    save_data.ModesButton.Value = app.ModesButton.Value;
                    save_data.MechanicalButton.Value = app.MechanicalButton.Value;
                    save_data.GeometryButton.Value = app.GeometryButton.Value;
                    save_data.ZF.Value = app.ZF.Value;
                    save_data.RF.Value = app.RF.Value;
                    save_data.LiquidFillRatio.Value = app.LiquidFillRatio.Value;
                    save_data.nuEditField.Value = app.nuEditField.Value;
                    save_data.m2sLabel.Text = app.m2sLabel.Text;
                    save_data.rhoEditField.Value = app.rhoEditField.Value;
                    save_data.kgm3Label.Text = app.kgm3Label.Text;
                    save_data.sigmaEditField.Value = app.sigmaEditField.Value;
                    save_data.NmLabel.Text = app.NmLabel.Text;
                    save_data.ms2Label.Text = app.ms2Label.Text;
                    save_data.gEditField.Value = app.gEditField.Value;
                    save_data.ParametricAnalysisComputeButton.Enable = app.ParametricAnalysisComputeButton.Enable;
                    save_data.ParametricAnalysisComputeButton.Enable = app.ParametricAnalysisComputeButton.Enable;
                    save_data.SpringModeSpinner.Value = app.SpringModeSpinner.Value;
                    save_data.nSpinner.Value = app.nSpinner.Value;
                    save_data.MSpinner.Value = app.MSpinner.Value;
                    save_data.MSpinner.Limits = app.MSpinner.Limits;
                    save_data.mainaxes.Visible = app.mainaxes.Visible;
                    save_data.mainaxes.XLabel.String = app.mainaxes.XLabel.String;
                    save_data.mainaxes.YLabel.String = app.mainaxes.YLabel.String;
                    save_data.ExportPlotMenu.Enable = app.ExportPlotMenu.Enable;

                    save([path, file], "save_data", "-mat");
                catch
                    uialert(app.SLOSHMLv10UIFigure, 'Failed to save data', '', 'Icon', 'error');
                end
            end
        end

        % Menu selected function: LoadMenu
        function LoadSessionClicked(app, event)
            [file,path] = uigetfile('*.SLOSHML', 'Select App Session', [app.app_file_directory '\saves']);
            if max(path ~= 0)
                try
                    temp = load([path, file], "-mat", "save_data");
                    save_data = temp.save_data;
                    app.IC_original = save_data.IC_original;
                    app.OC_original = save_data.OC_original;
                    app.IC_scaled_origin = save_data.IC_scaled_origin;
                    app.OC_scaled_origin = save_data.OC_scaled_origin;
                    app.IC_scaled_CM = save_data.IC_scaled_CM;
                    app.OC_scaled_CM = save_data.OC_scaled_CM;
                    app.IC_dimensionless = save_data.IC_dimensionless;
                    app.OC_dimensionless = save_data.OC_dimensionless;
                    app.bIC = save_data.bIC;
                    app.bOC = save_data.bOC;
                    app.epsilon = save_data.epsilon;
                    app.L_a = save_data.L_a;
                    app.L = save_data.L;
                    app.a = save_data.a;
                    app.bens = save_data.bens;
                    app.flvol = save_data.flvol;
                    app.H_origin = save_data.H_origin;
                    app.H_tank = save_data.H_tank;
                    app.CM_pos = save_data.CM_pos;
                    app.Nm = save_data.Nm;
                    app.Mm = save_data.Mm;
                    app.gm = save_data.gm;
                    app.c_k = save_data.c_k;
                    app.lambda_k = save_data.lambda_k;
                    app.Km = save_data.Km;
                    app.omega_k = save_data.omega_k;
                    app.s_n = save_data.s_n;
                    app.phi_a = save_data.phi_a;
                    app.Bm = save_data.Bm;
                    app.Bzerosm = save_data.Bzerosm;
                    app.I_modeSorting = save_data.I_modeSorting;
                    app.H_k = save_data.H_k;
                    app.K_k = save_data.K_k;
                    app.H_k_spring = save_data.H_k_spring;
                    app.M_k = save_data.M_k;
                    app.L_k = save_data.L_k;
                    app.CM_k = save_data.CM_k;
                    app.characteristic_velocity = save_data.characteristic_velocity;
                    app.modes_calculated = save_data.modes_calculated;
                    app.parametric_analysis_fill_ratios = save_data.parametric_analysis_fill_ratios;
                    app.parametric_analysis_accelerations = save_data.parametric_analysis_accelerations;
                    app.parametric_analysis_times = save_data.parametric_analysis_times;
                    app.parametric_analysis_output_matrix = save_data.parametric_analysis_output_matrix;
                    app.distance_unit = save_data.distance_unit;
                    app.mass_unit = save_data.mass_unit;
                    app.force_unit = save_data.force_unit;
                    app.distance_factor = save_data.distance_factor;
                    app.mass_factor = save_data.mass_factor;
                    app.force_factor = save_data.force_factor;
                    app.last_distance_factor = save_data.last_distance_factor;
                    app.last_mass_factor = save_data.last_mass_factor;
                    app.last_force_factor = save_data.last_force_factor;
                    app.WelcomeImage.Visible = save_data.WelcomeImage.Visible;
                    app.DisplayMechanicalPanel.Visible = save_data.DisplayMechanicalPanel.Visible;
                    app.MechanicalAnalogyCountSpinner.Enable = save_data.MechanicalAnalogyCountSpinner.Enable;
                    app.MechanicalAnalogyCountSpinner.Value = save_data.MechanicalAnalogyCountSpinner.Value;
                    app.MechanicalAnalogyCountSpinner.Limits = save_data.MechanicalAnalogyCountSpinner.Limits;
                    app.MechanicalAnalogyDropDown.Enable = save_data.MechanicalAnalogyDropDown.Enable;
                    app.MechanicalAnalogyDropDown.Value = save_data.MechanicalAnalogyDropDown.Value;
                    app.DisplayModesPanel.Visible = save_data.DisplayModesPanel.Visible;
                    app.NormalizeCheckBox.Enable = save_data.NormalizeCheckBox.Enable;
                    app.NormalizeCheckBox.Value = save_data.NormalizeCheckBox.Value;
                    app.MultiplicationFactorEditField.Enable = save_data.MultiplicationFactorEditField.Enable;
                    app.MultiplicationFactorEditField.Value = save_data.MultiplicationFactorEditField.Value;
                    app.NumberofModesSpinner.Enable = save_data.NumberofModesSpinner.Enable;
                    app.NumberofModesSpinner.Value = save_data.NumberofModesSpinner.Value;
                    app.NumberofModesSpinner.Limits = save_data.NumberofModesSpinner.Limits;
                    app.DisplayGeometryPanel.Visible = save_data.DisplayGeometryPanel.Visible;
                    app.LiquidFillLineCheckBox.Enable = save_data.LiquidFillLineCheckBox.Enable;
                    app.LiquidFillLineCheckBox.Value = save_data.LiquidFillLineCheckBox.Value;
                    app.CenterofMassCheckBox.Enable = save_data.CenterofMassCheckBox.Enable;
                    app.CenterofMassCheckBox.Value = save_data.CenterofMassCheckBox.Value;
                    app.TankGeometryCheckBox.Enable = save_data.TankGeometryCheckBox.Enable;
                    app.TankGeometryCheckBox.Value = save_data.TankGeometryCheckBox.Value;
                    app.CALCULATE.Enable = save_data.CALCULATE.Enable;
                    app.msLabel.Text = save_data.msLabel.Text;
                    app.VcEditField.Value = save_data.VcEditField.Value;
                    app.FrEditField.Value = save_data.FrEditField.Value;
                    app.BoEditField.Value = save_data.BoEditField.Value;
                    app.WeEditField.Value = save_data.WeEditField.Value;
                    app.RegimeLabel.Text = save_data.RegimeLabel.Text;
                    app.RegimeLabel.FontColor = save_data.RegimeLabel.FontColor;
                    app.freqtable.Data = save_data.freqtable.Data;
                    app.freqtable.ColumnName = save_data.freqtable.ColumnName;
                    app.freqtable.ColumnWidth = save_data.freqtable.ColumnWidth;
                    app.freqtable.ColumnFormat = save_data.freqtable.ColumnFormat;
                    app.freqtable.RowName = save_data.freqtable.RowName;
                    app.eigfntable.Data = save_data.eigfntable.Data;
                    app.eigfntable.RowName = save_data.eigfntable.RowName;
                    app.eigfntable.ColumnName = save_data.eigfntable.ColumnName;
                    app.eigfntable.ColumnWidth = save_data.eigfntable.ColumnWidth;
                    app.eigfntable.ColumnFormat = save_data.eigfntable.ColumnFormat;
                    app.modes_nondfreq.Text = save_data.modes_nondfreq.Text;
                    app.ModeSpinner.Enable = save_data.ModeSpinner.Enable;
                    app.ModeSpinner.Limits = save_data.ModeSpinner.Limits;
                    app.ModeSpinner.Value = save_data.ModeSpinner.Value;
                    app.freqtable_2.Data = save_data.freqtable_2.Data;
                    app.freqtable_2.ColumnWidth = save_data.freqtable_2.ColumnWidth;
                    app.freqtable_2.ColumnName = save_data.freqtable_2.ColumnName;
                    app.freqtable_2.ColumnFormat = save_data.freqtable_2.ColumnFormat;
                    app.PendulumTable.Data = save_data.PendulumTable.Data;
                    app.PendulumTable.RowName = save_data.PendulumTable.RowName;
                    app.PendulumTable.ColumnWidth = save_data.PendulumTable.ColumnWidth;
                    app.PendulumTable.ColumnFormat = save_data.PendulumTable.ColumnFormat;
                    app.SpringTable.Data = save_data.SpringTable.Data;
                    app.SpringTable.RowName = save_data.SpringTable.RowName;
                    app.SpringTable.ColumnWidth = save_data.SpringTable.ColumnWidth;
                    app.SpringTable.ColumnFormat = save_data.SpringTable.ColumnFormat;
                    app.DampingToroidMinorRadiusEditField.Visible = save_data.DampingToroidMinorRadiusEditField.Visible;
                    app.DampingToroidMinorRadiusEditField.Value = save_data.DampingToroidMinorRadiusEditField.Value;
                    app.DampingConeAngleUnitLabel.Visible = save_data.DampingConeAngleUnitLabel.Visible;
                    app.DampingConeAngleEditField.Visible = save_data.DampingConeAngleEditField.Visible;
                    app.DampingConeAngleEditField.Value = save_data.DampingConeAngleEditField.Value;
                    app.BaffleDampingRatioLabel.Visible = save_data.BaffleDampingRatioLabel.Visible;
                    app.BaffleDampingRatioLabel.Visible = save_data.BaffleDampingRatioLabel.Visible;
                    app.DampingAccelerationUnitLabel.Text = save_data.DampingAccelerationUnitLabel.Text;
                    app.DampingBaffleSpacingUnitLabel.Text = save_data.DampingBaffleSpacingUnitLabel.Text;
                    app.DampingBaffleSpacingUnitLabel.Visible = save_data.DampingBaffleSpacingUnitLabel.Visible;
                    app.DampingFillHeightUnitLabel.Text = save_data.DampingFillHeightUnitLabel.Text;
                    app.DampingBaffleDepthUnitLabel.Text = save_data.DampingBaffleDepthUnitLabel.Text;
                    app.DampingBaffleDepthUnitLabel.Visible = save_data.DampingBaffleDepthUnitLabel.Visible;
                    app.DampingBaffleWidthUnitLabel.Text = save_data.DampingBaffleWidthUnitLabel.Text;
                    app.DampingBaffleWidthUnitLabel.Visible = save_data.DampingBaffleWidthUnitLabel.Visible;
                    app.DampingRadiusUnitLabel.Text = save_data.DampingRadiusUnitLabel.Text;
                    app.DampingViscosityUnitLabel.Text = save_data.DampingViscosityUnitLabel.Text;
                    app.SubmergedBafflesLabel.Visible = save_data.SubmergedBafflesLabel.Visible;
                    app.TopBaffleDepthLabel.Visible = save_data.TopBaffleDepthLabel.Visible;
                    app.BaffleSpacingLabel.Visible = save_data.BaffleSpacingLabel.Visible;
                    app.BaffleWidthLabel.Visible = save_data.BaffleWidthLabel.Visible;
                    app.SubmergedBafflesSpinner.Value = save_data.SubmergedBafflesSpinner.Value;
                    app.SubmergedBafflesSpinner.Visible = save_data.SubmergedBafflesSpinner.Visible;
                    app.TopBaffleDepthEditField.Value = save_data.TopBaffleDepthEditField.Value;
                    app.TopBaffleDepthEditField.Visible = save_data.TopBaffleDepthEditField.Visible;
                    app.BaffleSpacingEditField.Value = save_data.BaffleSpacingEditField.Value;
                    app.BaffleSpacingEditField.Visible = save_data.BaffleSpacingEditField.Visible;
                    app.BaffleWidthEditField.Value = save_data.BaffleWidthEditField.Value;
                    app.BaffleWidthEditField.Visible = save_data.BaffleWidthEditField.Visible;
                    app.DampinghRValue.Text = save_data.DampinghRValue.Text;
                    app.DampinghRValue.FontColor = save_data.DampinghRValue.FontColor;
                    app.DampinghRLabel.Visible = save_data.DampinghRLabel.Visible;
                    app.DampingValidityText.Text = save_data.DampingValidityText.Text;
                    app.DampingRatioOutput.Text = save_data.DampingRatioOutput.Text;
                    app.DampingRatioLabel.Visible = save_data.DampingRatioLabel.Visible;
                    app.DampingAccelerationOutput.Text = save_data.DampingAccelerationOutput.Text;
                    app.DampingViscosityOutput.Text = save_data.DampingViscosityOutput.Text;
                    app.DampingFillHeightEditField.Value = save_data.DampingFillHeightEditField.Value;
                    app.DampingRadiusEditField.Value = save_data.DampingRadiusEditField.Value;
                    app.CorrelationReferenceHyperlink.URL = save_data.CorrelationReferenceHyperlink.URL;
                    app.DampingGeometryDropDown.Value = save_data.DampingGeometryDropDown.Value;
                    app.DampingCorrelationDropDown.Items = save_data.DampingCorrelationDropDown.Items;
                    app.DampingCorrelationDropDown.Value = save_data.DampingCorrelationDropDown.Value;
                    app.ModesButton.Value = save_data.ModesButton.Value;
                    app.MechanicalButton.Value = save_data.MechanicalButton.Value;
                    app.GeometryButton.Value = save_data.GeometryButton.Value;
                    app.ZF.Value = save_data.ZF.Value;
                    app.RF.Value = save_data.RF.Value;
                    app.LiquidFillRatio.Value = save_data.LiquidFillRatio.Value;
                    app.nuEditField.Value = save_data.nuEditField.Value;
                    app.m2sLabel.Text = save_data.m2sLabel.Text;
                    app.rhoEditField.Value = save_data.rhoEditField.Value;
                    app.kgm3Label.Text = save_data.kgm3Label.Text;
                    app.sigmaEditField.Value = save_data.sigmaEditField.Value;
                    app.NmLabel.Text = save_data.NmLabel.Text;
                    app.ms2Label.Text = save_data.ms2Label.Text;
                    app.gEditField.Value = save_data.gEditField.Value;
                    app.ParametricAnalysisComputeButton.Enable = save_data.ParametricAnalysisComputeButton.Enable;
                    app.ParametricAnalysisComputeButton.Enable = save_data.ParametricAnalysisComputeButton.Enable;
                    app.SpringModeSpinner.Value = save_data.SpringModeSpinner.Value;
                    app.nSpinner.Value = save_data.nSpinner.Value;
                    app.MSpinner.Value = save_data.MSpinner.Value;
                    app.MSpinner.Limits = save_data.MSpinner.Limits;
                    app.mainaxes.Visible = save_data.mainaxes.Visible;
                    app.mainaxes.XLabel.String = save_data.mainaxes.XLabel.String;
                    app.mainaxes.YLabel.String = save_data.mainaxes.YLabel.String;
                    app.ExportPlotMenu.Enable = save_data.ExportPlotMenu.Enable;

                    app.DesignContourButton.Enable = true; % contour designer not saved, so reactivate button
                    change_plot(app); % plots not saved to file, plot everything again
                catch e
                    uialert(app.SLOSHMLv10UIFigure, "Failed to load data", '', 'Icon', 'error');
                end
            end
        end

        % Menu selected function: ExportPlotMenu
        function ExportPlotMenuSelected(app, event)
            [file, path] = uiputfile({'*.png','PNG file (*.png)'; '*.pdf','PDF file (*.pdf)'; '*.jpg','JPEG file (*.jpg)'},'Export Plot As', [app.app_file_directory '\saves\Untitled.png']);

            if max(path ~= 0)
                try
                    if app.mainaxes.Visible
                        exportgraphics(app.mainaxes, [path, file]);
                    end
                catch
                    uialert(app.SLOSHMLv10UIFigure, "Failed to export plot", '', 'Icon', 'error');
                end
            end
        end

        % Button pushed function: LoadContourButton
        function loadContourButtonClicked(app, event)
            get_contour(app);
        end

        % Button pushed function: SelectFileButton
        function ParametricAnalysiFileSelectPushed(app, event)
            [file,path] = uigetfile('*', 'Select g/FR data');
            if ~(max(path~=0))
                return;
            end
            app.ParametricAnalysisComputeButton.Enable = false;
            app.ParametricAnalysisExportButton.Enable = false;
            try
                data = readcell([path, file]);
                if ischar(data{1,1}) % Remove optional header row from data
                    data = data(2:end, :);
                end
                data = cell2mat(data);
                app.parametric_analysis_accelerations = data(:,1);
                app.parametric_analysis_fill_ratios = data(:,2);
                app.parametric_analysis_times = [];
                if size(data,2) > 2
                    app.parametric_analysis_times = data(:,3);
                end
                app.ParametricAnalysisComputeButton.Enable = true;
                if max(data(:,2)) >= 100 || min(data(:,2)) <= 0 || min(data(:,1)) <= 0
                    uialert(app.SLOSHMLv10UIFigure, 'Invalid parameters for some inputs. Liquid fill ratio ranges from 0 to 100 (exclusive), and acceleration must be greater than 0. Invalid rows will be set to NaN.','Warning','Icon','warning');
                end
                uialert(app.SLOSHMLv10UIFigure,'Inputs loaded successfully', '', 'Icon', 'success');
            catch
                uialert(app.SLOSHMLv10UIFigure, 'Failed to load inputs', '', 'Icon', 'error');
            end
        end

        % Button pushed function: ParametricAnalysisComputeButton
        function ParametricAnalysisComputeButtonPushed(app, event)
            if ~app.CALCULATE.Enable
                uialert(app.SLOSHMLv10UIFigure, 'Tank geometry not specified', '', 'Icon', 'error');
                return;
            end
            
            g_vec = app.parametric_analysis_accelerations;
            FR_vec = app.parametric_analysis_fill_ratios;
            t_vec = app.parametric_analysis_times;
            num_modes = app.SpringModeSpinner.Value;
            
            % Generate header row
            empty_cells = {};
            for i = 1:(num_modes)
                empty_cells{i} = '';
            end
            first_row = [{'acceleration', 'liquid fill ratio (%)', 'We', 'Fr', 'Bo', 'frequencies'} empty_cells(1:end-1) ...
                             {'modal masses'} empty_cells {'pendulum lengths'} empty_cells ...
                             {'pendulum mass heights'} empty_cells {'amplitude ratio'} empty_cells ... 
                             {'spring mass hinge points'} empty_cells {'modal spring stiffnesses'} empty_cells {'damping ratio'}];
            if ~isempty(t_vec)
                first_row = [{'time'} first_row];
            end
            
            % keep track of initial top baffle depth
            baffles_selected = false;
            if strcmp(app.DampingGeometryDropDown.Value, "Cylindrical with Baffles") && strcmp(app.DampingCorrelationDropDown.Value, "Cylindrical w/ Baffles (Bauer)")
                starting_top_depth = app.TopBaffleDepthEditField.Value;
                starting_fill_height = FR_vec(1)*(app.bens(4) - app.bens(3))/100;
                starting_num_submerged = app.SubmergedBafflesSpinner.Value;
                baffles_selected = true;
            elseif strcmp(app.DampingGeometryDropDown.Value, "Cylindrical with Baffles") && strcmp(app.DampingCorrelationDropDown.Value, "Cylindrical w/ Baffles (Coogan)")
                starting_top_depth = app.TopBaffleDepthEditField.Value;
                starting_fill_height = FR_vec(1)*(app.bens(4) - app.bens(3))/100;
                starting_num_submerged = 1;
                baffles_selected = true;
            end

            % Run get_geometry and calculate for each data point
            output_matrix = first_row;
            tic;
            loading_bar = uiprogressdlg(app.SLOSHMLv10UIFigure,'Title','Running Parametric Analysis','Message',' ','Value',0,'Cancelable','on','ShowPercentage','on');
            for i = 1:length(g_vec)
                if loading_bar.CancelRequested
                    close(loading_bar);
                    return;
                end
                if baffles_selected
                    fill_height = FR_vec(i)*(app.bens(4)-app.bens(3))/100;
                    top_depth = starting_top_depth + (fill_height - starting_fill_height);
                    num_submerged = starting_num_submerged;
                    while top_depth < 0
                        top_depth = top_depth + app.BaffleSpacingEditField.Value;
                        num_submerged = num_submerged - 1; 
                    end
                    if num_submerged < 0
                        app.SubmergedBafflesSpinner.Value = 0;
                    else
                        app.SubmergedBafflesSpinner.Value = num_submerged;
                        app.TopBaffleDepthEditField.Value = top_depth;
                    end
                end
                if FR_vec(i) > 1 && FR_vec(i) < 100 && g_vec(i) > 0
                    app.gEditField.Value = g_vec(i);
                    app.LiquidFillRatio.Value = FR_vec(i);
                    get_geometry(app);
                    calculate(app,'suppress waitbar');
                    row = [g_vec(i) FR_vec(i) app.WeEditField.Value app.FrEditField.Value app.BoEditField.Value ...
                           app.omega_k(1:num_modes)' app.M_k(1:num_modes+1)' app.L_k(1:num_modes+1)' app.H_k(1:num_modes+1)' ...
                           app.CM_k(1:num_modes+1)' app.H_k_spring(1:num_modes+1)' app.K_k(1:num_modes+1)' ...
                           str2double(app.DampingRatioOutput.Text)];
                    if ~isempty(t_vec)
                        row = [t_vec(i) row];
                    end
                    
                else
                    row = NaN(size(first_row));
                    if isempty(t_vec)
                        row(1) = g_vec(i); row(2) = FR_vec(i);
                    else
                        row(1) = t_vec(i); row(2) = g_vec(i); row(3) = FR_vec(i);
                    end
                end
                output_matrix = [output_matrix; num2cell(row)];

                % Display time remaining/progress
                time_per_step = toc/i;
                time_remaining = time_per_step*(length(g_vec) - i);
                progress_string = ['Generating Data ... (' num2str(i) '/' num2str(length(g_vec)) ')   est. time remaining: ' datestr(time_remaining/3600/24, 'HH:MM:SS')];
                loading_bar.Message = progress_string;
                loading_bar.Value = i/length(g_vec);
                drawnow;
            end
            close(loading_bar);
            app.parametric_analysis_output_matrix = output_matrix;
            app.ParametricAnalysisExportButton.Enable = true;
            if baffles_selected
                app.TopBaffleDepthEditField.Value = starting_top_depth;
                app.SubmergedBafflesSpinner.Value = starting_num_submerged;
            end
        end

        % Button pushed function: ParametricAnalysisExportButton
        function ParametricAnalysisExportButtonPushed(app, event)
            % Write results to file
            [file,path] = uiputfile('*.csv', 'Select output file');
            if max(path ~= 0)
                try
                    writecell(app.parametric_analysis_output_matrix, [path, file]);
                    uialert(app.SLOSHMLv10UIFigure, 'Results exported successfully', 'Success', 'Icon', 'Success');
                catch
                    uialert(app.SLOSHMLv10UIFigure,'Write to file failed', 'Failed', 'Icon','Error');
                end
            end
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxChanged(app, event)
            change_plot(app);
        end

        % Menu selected function: HelpMenu
        function HelpClicked(app, event)
            web('https://github.com/LGST-LAB/slosh_ml');
        end

        % Menu selected function: VersionMenu
        function VersionClicked(app, event)
            try
                installed_version = 'v1.0';
                tags = webread('https://api.github.com/repos/LGST-LAB/slosh_ml/tags');
                most_recent_version = [0 0];
                major_latest = 0;
                minor_latest = 0;
                for i = 1:length(tags)
                    if length(regexp(tags(i).name,"v\d+[.]\d+.*")) == 0 % version numbers must be in this format: "vX.XX" or "vX.XXothertext" 
                        continue;
                    end
                    split_string = strsplit(tags(i).name, '.');
                    major        = str2num(split_string{1}(2:end));
                    minor        = strsplit(split_string{2},'_');
                    minor        = str2num(minor{1});
                    if major > major_latest || (major == major_latest && minor > minor_latest)
                        major_latest    = major;
                        minor_latest    = minor;
                        latest_version  = tags(i).name;
                    end
                end
                split_string = strsplit(installed_version,'.');
                major_installed = str2num(split_string{1}(2:end));
                minor_installed = strsplit(split_string{2},'_');
                minor_installed = str2num(minor_installed{1});
                if major_latest > major_installed || (major_latest == major_installed && minor_latest > minor_installed)
                    message = ['update available: ' latest_version ' at https://github.com/LGST-LAB/slosh_ml'];
                else
                    message = 'up to date';
                end
                uialert(app.SLOSHMLv10UIFigure, ['Currently Installed Version: ' installed_version ' ... ' message], 'Version', 'Icon', 'info');
            catch
                uialert(app.SLOSHMLv10UIFigure, ['Currently Installed Version: ' installed_version ' ... FAILED TO RETRIEVE MOST RECENT VERSION'], 'Version', 'Icon', 'info');
            end
        end

        % Value changed function: DampingCorrelationDropDown
        function DampingCorrelationChanged(app, event)
            % update damping ratio inputs/outputs
            display_damping_information(app);
            if ~(strcmp(app.DampingAccelerationOutput.Text, '') || +...
                 strcmp(app.DampingViscosityOutput.Text, ''))
                 compute_damping_ratio(app);
            end

            if strcmp(app.DampingCorrelationDropDown.Value, 'Cylindrical w/ Baffles (Coogan)')
                app.BaffleSpacingEditField.Visible = false;
                app.SubmergedBafflesSpinner.Visible = false;
                app.BaffleSpacingLabel.Visible = false;
                app.SubmergedBafflesLabel.Visible = false;
                app.DampingBaffleSpacingUnitLabel.Visible = false;
                app.ScalingFactorEditField.Visible = true;
                app.ScalingFactorLabel.Visible = true;
                compute_damping_ratio(app);
            elseif strcmp(app.DampingCorrelationDropDown.Value, 'Cylindrical w/ Baffles (Bauer)')
                app.ScalingFactorEditField.Visible = false;
                app.ScalingFactorLabel.Visible = false;
                app.BaffleSpacingEditField.Visible = true;
                app.SubmergedBafflesSpinner.Visible = true;
                app.BaffleSpacingLabel.Visible = true;
                app.SubmergedBafflesLabel.Visible = true;
                app.DampingBaffleSpacingUnitLabel.Visible = true;
                compute_damping_ratio(app);
            end
        end

        % Value changed function: DampingGeometryDropDown
        function DampingGeometryChanged(app, event)
            value = app.DampingGeometryDropDown.Value;
            if strcmp(value, 'Cylindrical')
                app.DampingCorrelationDropDown.Items = {'Cylindrical (Stephens)',...
                                                        'Cylindrical (Mikishev, NASA SP-106)'};
            elseif strcmp(value, 'Spherical')
                app.DampingCorrelationDropDown.Items = {'Spherical (Mikishev, NASA SP-106, (h/R) <= 1)',...
                                                        'Spherical (Mikishev, NASA SP-106, (h/R) >= 1)'};
            elseif strcmp(value, 'Cylindrical with Baffles')
                app.DampingCorrelationDropDown.Items = {'Cylindrical w/ Baffles (Bauer)',...
                                                        'Cylindrical w/ Baffles (Coogan)'};
            elseif strcmp(value, 'Conical')
                app.DampingCorrelationDropDown.Items = {'Conical (NASA SP-106)'};
            elseif strcmp(value, 'Toroidal')
                app.DampingCorrelationDropDown.Items = {'Toroidal (NASA SP-106)'};
            end

            if strcmp(value, 'Cylindrical with Baffles')
                app.BaffleWidthEditField.Visible = true;
                app.TopBaffleDepthEditField.Visible = true;
                app.BaffleWidthLabel.Visible = true;
                app.TopBaffleDepthLabel.Visible = true;
                app.DampingBaffleWidthUnitLabel.Visible = true;
                app.DampingBaffleDepthUnitLabel.Visible = true;
                app.BaffleDampingRatioLabel.Visible = true;
                app.DampingRatioLabel.Visible = false;

                if strcmp(app.DampingCorrelationDropDown.Value, 'Cylindrical w/ Baffles (Coogan)')
                    app.BaffleSpacingEditField.Visible = false;
                    app.SubmergedBafflesSpinner.Visible = false;
                    app.BaffleSpacingLabel.Visible = false;
                    app.SubmergedBafflesLabel.Visible = false;
                    app.DampingBaffleSpacingUnitLabel.Visible = false;
                    app.ScalingFactorEditField.Visible = true;
                    app.ScalingFactorLabel.Visible = true;
                    compute_damping_ratio(app);
                elseif strcmp(app.DampingCorrelationDropDown.Value, 'Cylindrical w/ Baffles (Bauer)')
                    app.ScalingFactorEditField.Visible = false;
                    app.ScalingFactorLabel.Visible = false;
                    app.BaffleSpacingEditField.Visible = true;
                    app.SubmergedBafflesSpinner.Visible = true;
                    app.BaffleSpacingLabel.Visible = true;
                    app.SubmergedBafflesLabel.Visible = true;
                    app.DampingBaffleSpacingUnitLabel.Visible = true;
                    compute_damping_ratio(app);
                end
            else
                app.BaffleSpacingEditField.Visible = false;
                app.ScalingFactorEditField.Visible = false;
                app.BaffleWidthEditField.Visible = false;
                app.TopBaffleDepthEditField.Visible = false;
                app.SubmergedBafflesSpinner.Visible = false;
                app.BaffleSpacingLabel.Visible = false;
                app.ScalingFactorLabel.Visible = false;
                app.BaffleWidthLabel.Visible = false;
                app.TopBaffleDepthLabel.Visible = false;
                app.SubmergedBafflesLabel.Visible = false;
                app.DampingBaffleWidthUnitLabel.Visible = false;
                app.DampingBaffleSpacingUnitLabel.Visible = false;
                app.DampingBaffleDepthUnitLabel.Visible = false;
                app.BaffleDampingRatioLabel.Visible = false;
                app.DampingRatioLabel.Visible = true;
            end

            if strcmp(value, 'Conical')
                app.DampingConeAngleEditField.Visible = true;
                app.ConeAngleEditFieldLabel.Visible = true;
                app.DampingConeAngleUnitLabel.Visible = true;
                app.DampinghRLabel.Visible = false;
                app.AngleValidityLabel.Visible = true;
            else
                app.DampingConeAngleEditField.Visible = false;
                app.ConeAngleEditFieldLabel.Visible = false;
                app.DampingConeAngleUnitLabel.Visible = false;
                app.DampinghRLabel.Visible = true;
                app.AngleValidityLabel.Visible = false;
            end

            if strcmp(value, 'Toroidal')
                app.DampingRadiusLabel.Text = "Minor Radius";
                app.DampingToroidMinorRadiusEditField.Visible = true;
                app.DampingRadiusEditField.Visible = false;
            else
                app.DampingRadiusLabel.Text = "Tank Radius";
                app.DampingToroidMinorRadiusEditField.Visible = false;
                app.DampingRadiusEditField.Visible = true;
            end

            app.DampingCorrelationDropDown.Value = app.DampingCorrelationDropDown.Items{1};
            DampingCorrelationChanged(app,event);
        end

        % Value changed function: BaffleWidthEditField
        function BaffleWidthChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: ScalingFactorEditField
        function ScalingFactorChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: BaffleSpacingEditField
        function BaffleSpacingChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: TopBaffleDepthEditField
        function TopBaffleDepthChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: SubmergedBafflesSpinner
        function SubmergedBafflesChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: DampingToroidMinorRadiusEditField
        function ToroidRadiusChanged(app, event)
            compute_damping_ratio(app);
        end

        % Menu selected function: MetricmkgsMenu
        function metricmkgsSelected(app, event)
            app.distance_unit        = "m";
            app.force_unit           = "N";
            app.mass_unit            = "kg";
            app.last_distance_factor = app.distance_factor;
            app.last_mass_factor     = app.mass_factor;
            app.last_force_factor    = app.force_factor;
            app.distance_factor      = 1.0; % [m/m]
            app.mass_factor          = 1.0; % [kg/kg]
            app.force_factor         = 1.0; % [N/N]
            update_input_units(app);
        end

        % Menu selected function: ImperialftslugsMenu
        function imperialftslugsSelected(app, event)
            app.distance_unit        = "ft";
            app.force_unit           = "lb";
            app.mass_unit            = "slug";
            app.last_distance_factor = app.distance_factor;
            app.last_mass_factor     = app.mass_factor;
            app.last_force_factor    = app.force_factor;
            app.distance_factor      = 3.280839895;   % [ft/m]
            app.mass_factor          = 0.0685217659;  % [slug/kg]
            app.force_factor         = 0.2248089431;  % [lb/N]
            update_input_units(app);
        end

        % Button pushed function: DesignContourButton
        function DesignContourButtonClicked(app, event)
            app.DesignContourButton.Enable = false;
            app.contour_designer_app = ContourDesigner(app);
        end

        % Value changed function: DampingConeAngleEditField
        function ConeAngleChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: DampingRadiusEditField
        function DampingRadiusChanged(app, event)
            compute_damping_ratio(app);
        end

        % Value changed function: DampingFillHeightEditField
        function DampingFillHeightChanged(app, event)
            compute_damping_ratio(app);
        end

        % Menu selected function: ReportBugMenu
        function ReportBugMenuSelected(app, event)
            web('https://github.com/LGST-LAB/slosh_ml/issues', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SLOSHMLv10UIFigure = uifigure('Visible', 'off');
            app.SLOSHMLv10UIFigure.Name = 'SLOSH-ML v. 1.0';
            app.SLOSHMLv10UIFigure.Icon = fullfile(pathToMLAPP, 'resources', 'logo@4x.png');
            app.FileMenu = uimenu(app.SLOSHMLv10UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessionClicked, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionClicked, true);
            app.LoadMenu.Text = 'Load';
            app.ExportPlotMenu = uimenu(app.FileMenu);
            app.ExportPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPlotMenuSelected, true);
            app.ExportPlotMenu.Enable = 'off';
            app.ExportPlotMenu.Text = 'Export Plot';
            app.UnitsMenu = uimenu(app.SLOSHMLv10UIFigure);
            app.UnitsMenu.Text = 'Units';
            app.MetricmkgsMenu = uimenu(app.UnitsMenu);
            app.MetricmkgsMenu.MenuSelectedFcn = createCallbackFcn(app, @metricmkgsSelected, true);
            app.MetricmkgsMenu.Text = 'Metric (m, kg, s)';
            app.ImperialftslugsMenu = uimenu(app.UnitsMenu);
            app.ImperialftslugsMenu.MenuSelectedFcn = createCallbackFcn(app, @imperialftslugsSelected, true);
            app.ImperialftslugsMenu.Text = 'Imperial (ft, slug, s)';
            app.AboutMenu = uimenu(app.SLOSHMLv10UIFigure);
            app.AboutMenu.Text = 'About';
            app.HelpMenu = uimenu(app.AboutMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpClicked, true);
            app.HelpMenu.Text = 'Help';
            app.VersionMenu = uimenu(app.AboutMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionClicked, true);
            app.VersionMenu.Text = 'Version';
            app.ReportBugMenu = uimenu(app.AboutMenu);
            app.ReportBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportBugMenuSelected, true);
            app.ReportBugMenu.Text = 'Report Bug';
            app.mainaxes = uiaxes(app.SLOSHMLv10UIFigure);
            xlabel(app.mainaxes, 'r (m)')
            ylabel(app.mainaxes, 'z (m)')
            app.mainaxes.Visible = 'off';
            app.SettingsTabGroup = uitabgroup(app.SLOSHMLv10UIFigure);
            app.GeometryTab = uitab(app.SettingsTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.LoadContourButton = uibutton(app.GeometryTab, 'push');
            app.LoadContourButton.ButtonPushedFcn = createCallbackFcn(app, @loadContourButtonClicked, true);
            app.LoadContourButton.Text = 'Load Contour';
            app.FillRatioLabel = uilabel(app.GeometryTab);
            app.FillRatioLabel.Text = 'Fill Ratio';
            app.LiquidFillRatio = uispinner(app.GeometryTab);
            app.LiquidFillRatio.LowerLimitInclusive = 'off';
            app.LiquidFillRatio.UpperLimitInclusive = 'off';
            app.LiquidFillRatio.Limits = [0 100];
            app.LiquidFillRatio.ValueChangedFcn = createCallbackFcn(app, @LiquidFillRationChanged, true);
            app.LiquidFillRatio.Value = 65;
            app.Label = uilabel(app.GeometryTab);
            app.Label.Text = '%';
            app.RFactorLabel = uilabel(app.GeometryTab);
            app.RFactorLabel.Text = 'R Factor ';
            app.RF = uispinner(app.GeometryTab);
            app.RF.LowerLimitInclusive = 'off';
            app.RF.UpperLimitInclusive = 'off';
            app.RF.Limits = [0 Inf];
            app.RF.ValueChangedFcn = createCallbackFcn(app, @changeRF, true);
            app.RF.Value = 100;
            app.ZFactorLabel = uilabel(app.GeometryTab);
            app.ZFactorLabel.Text = 'Z Factor';
            app.ZF = uispinner(app.GeometryTab);
            app.ZF.LowerLimitInclusive = 'off';
            app.ZF.UpperLimitInclusive = 'off';
            app.ZF.Limits = [0 Inf];
            app.ZF.ValueChangedFcn = createCallbackFcn(app, @changeZF, true);
            app.ZF.Value = 100;
            app.Label_4 = uilabel(app.GeometryTab);
            app.Label_4.Text = '%';
            app.Label_5 = uilabel(app.GeometryTab);
            app.Label_5.Text = '%';
            app.DesignContourButton = uibutton(app.GeometryTab, 'push');
            app.DesignContourButton.ButtonPushedFcn = createCallbackFcn(app, @DesignContourButtonClicked, true);
            app.DesignContourButton.Text = 'Design Contour';
            app.PhysicsTab = uitab(app.SettingsTabGroup);
            app.PhysicsTab.Title = 'Physics';
            app.gEditFieldLabel = uilabel(app.PhysicsTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.gEditField.Limits = [0 Inf];
            app.gEditField.ValueDisplayFormat = '%.3f';
            app.gEditField.Value = 9.807;
            app.ms2Label = uilabel(app.PhysicsTab);
            app.ms2Label.Text = 'm/s2';
            app.NmLabel = uilabel(app.PhysicsTab);
            app.NmLabel.Text = 'N/m';
            app.EditFieldLabel = uilabel(app.PhysicsTab);
            app.EditFieldLabel.Text = 'σ';
            app.sigmaEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.sigmaEditField.Limits = [0 Inf];
            app.sigmaEditField.ValueDisplayFormat = '%.5f';
            app.sigmaEditField.Value = 0.07275;
            app.kgm3Label = uilabel(app.PhysicsTab);
            app.kgm3Label.Text = 'kg/m3';
            app.Label_2 = uilabel(app.PhysicsTab);
            app.Label_2.Text = 'ρ';
            app.rhoEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.rhoEditField.Limits = [0 Inf];
            app.rhoEditField.ValueDisplayFormat = '%.3f';
            app.rhoEditField.Value = 997;
            app.m2sLabel = uilabel(app.PhysicsTab);
            app.m2sLabel.Text = 'm2/s';
            app.EditFieldLabel_2 = uilabel(app.PhysicsTab);
            app.EditFieldLabel_2.Text = '𝜈';
            app.nuEditField = uieditfield(app.PhysicsTab, 'numeric');
            app.nuEditField.Limits = [0 Inf];
            app.nuEditField.ValueDisplayFormat = '%e';
            app.nuEditField.Value = 1.0533e-06;
            app.ParamAnTab = uitab(app.SettingsTabGroup);
            app.ParamAnTab.Title = 'Param. An.';
            app.Label_8 = uilabel(app.ParamAnTab);
            app.Label_8.Text = 'Compute and save results';
            app.ParametricAnalysisComputeButton = uibutton(app.ParamAnTab, 'push');
            app.ParametricAnalysisComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysisComputeButtonPushed, true);
            app.ParametricAnalysisComputeButton.Enable = 'off';
            app.ParametricAnalysisComputeButton.Text = 'Compute';
            app.InputfilewithaccelerationandfillingratiodataLabel = uilabel(app.ParamAnTab);
            app.InputfilewithaccelerationandfillingratiodataLabel.Text = 'Input csv data with acceleration (column 1), percent filling ratio (column 2), and optional time (column 3).';
            app.SelectFileButton = uibutton(app.ParamAnTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysiFileSelectPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.ParametricAnalysisExportButton = uibutton(app.ParamAnTab, 'push');
            app.ParametricAnalysisExportButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysisExportButtonPushed, true);
            app.ParametricAnalysisExportButton.Enable = 'off';
            app.ParametricAnalysisExportButton.Text = 'Export';
            app.AdvancedTab = uitab(app.SettingsTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.NSpinnerLabel = uilabel(app.AdvancedTab);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.AdvancedTab);
            app.NSpinner.Limits = [10 Inf];
            app.NSpinner.RoundFractionalValues = 'on';
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @changeN, true);
            app.NSpinner.Value = 10;
            app.MSpinnerLabel = uilabel(app.AdvancedTab);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.AdvancedTab);
            app.MSpinner.Limits = [5 9];
            app.MSpinner.RoundFractionalValues = 'on';
            app.MSpinner.Value = 5;
            app.nSpinnerLabel = uilabel(app.AdvancedTab);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.AdvancedTab);
            app.nSpinner.Limits = [200 Inf];
            app.nSpinner.RoundFractionalValues = 'on';
            app.nSpinner.Value = 200;
            app.ShowModesLabel = uilabel(app.AdvancedTab);
            app.ShowModesLabel.Text = 'Number of Modes';
            app.SpringModeSpinner = uispinner(app.AdvancedTab);
            app.SpringModeSpinner.Limits = [0 5];
            app.SpringModeSpinner.RoundFractionalValues = 'on';
            app.SpringModeSpinner.Value = 3;
            app.RestoreDefaultSettingsButton = uibutton(app.AdvancedTab, 'push');
            app.RestoreDefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @restoreDefaultAdvancedSettings, true);
            app.RestoreDefaultSettingsButton.Text = 'Restore Default Settings';
            app.DisplayButtonGroup = uibuttongroup(app.SLOSHMLv10UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonChanged, true);
            app.GeometryButton = uitogglebutton(app.DisplayButtonGroup);
            app.GeometryButton.Text = 'Geometry';
            app.GeometryButton.Value = true;
            app.MechanicalButton = uitogglebutton(app.DisplayButtonGroup);
            app.MechanicalButton.Text = 'Mechanical';
            app.ModesButton = uitogglebutton(app.DisplayButtonGroup);
            app.ModesButton.Text = 'Modes';
            app.OutputTabGroup = uitabgroup(app.SLOSHMLv10UIFigure);
            app.BulkOutputTab = uitab(app.OutputTabGroup);
            app.BulkOutputTab.Title = 'Bulk Output';
            app.eigfntable = uitable(app.BulkOutputTab);
            app.eigfntable.ColumnName = {'1'; '2'; '3'; '4'};
            app.eigfntable.RowName = {'{}'};
            app.eigfntable.ColumnEditable = [true false false false];
            app.freqtable = uitable(app.BulkOutputTab);
            app.freqtable.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable.RowName = {'{}'};
            app.freqtable.ColumnEditable = [true false false false];
            app.EigenvectorsLabel = uilabel(app.BulkOutputTab);
            app.EigenvectorsLabel.Text = 'Eigenvectors';
            app.NaturalFrequenciesLabel = uilabel(app.BulkOutputTab);
            app.NaturalFrequenciesLabel.Text = 'Natural Frequencies';
            app.RegimeLabel = uilabel(app.BulkOutputTab);
            app.RegimeLabel.Text = 'Gravity D. ';
            app.WeEditFieldLabel = uilabel(app.BulkOutputTab);
            app.WeEditFieldLabel.Text = 'We';
            app.WeEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.WeEditField.Limits = [0 Inf];
            app.WeEditField.ValueDisplayFormat = '%.2f';
            app.WeEditField.Editable = 'off';
            app.BoEditFieldLabel = uilabel(app.BulkOutputTab);
            app.BoEditFieldLabel.Text = 'Bo';
            app.BoEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.BoEditField.Limits = [0 Inf];
            app.BoEditField.ValueDisplayFormat = '%.2f';
            app.BoEditField.Editable = 'off';
            app.FrEditFieldLabel = uilabel(app.BulkOutputTab);
            app.FrEditFieldLabel.Text = 'Fr';
            app.FrEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.FrEditField.Limits = [0 Inf];
            app.FrEditField.ValueDisplayFormat = '%.2f';
            app.FrEditField.Editable = 'off';
            app.VcEditFieldLabel = uilabel(app.BulkOutputTab);
            app.VcEditFieldLabel.Text = 'Vc';
            app.VcEditField = uieditfield(app.BulkOutputTab, 'numeric');
            app.VcEditField.Limits = [0 Inf];
            app.VcEditField.ValueDisplayFormat = '%.2f';
            app.VcEditField.Editable = 'off';
            app.msLabel = uilabel(app.BulkOutputTab);
            app.msLabel.Text = 'm/s';
            app.ModesTab = uitab(app.OutputTabGroup);
            app.ModesTab.Title = 'Modes';
            app.freqtable_2 = uitable(app.ModesTab);
            app.freqtable_2.ColumnName = {'1'; '2'; '3'; '4'};
            app.freqtable_2.RowName = {};
            app.ModalCoefficientsLabel = uilabel(app.ModesTab);
            app.ModalCoefficientsLabel.Text = 'Modal Coefficients';
            app.ModeLabel = uilabel(app.ModesTab);
            app.ModeLabel.Text = 'Mode:';
            app.ModeSpinner = uispinner(app.ModesTab);
            app.ModeSpinner.Limits = [1 10];
            app.ModeSpinner.ValueChangedFcn = createCallbackFcn(app, @displayModeData, true);
            app.ModeSpinner.Enable = 'off';
            app.ModeSpinner.Value = 1;
            app.NaturalFrequencyLabel = uilabel(app.ModesTab);
            app.NaturalFrequencyLabel.Text = 'Natural Frequency:';
            app.NondimFrequencyLabel = uilabel(app.ModesTab);
            app.NondimFrequencyLabel.Text = 'Nondim. Frequency:';
            app.modes_natfreq = uilabel(app.ModesTab);
            app.modes_natfreq.Text = '0';
            app.modes_nondfreq = uilabel(app.ModesTab);
            app.modes_nondfreq.Text = '0';
            app.RadsLabel = uilabel(app.ModesTab);
            app.RadsLabel.Text = 'Rad/s';
            app.PendulumTab = uitab(app.OutputTabGroup);
            app.PendulumTab.Title = 'Pendulum';
            app.PendulumTable = uitable(app.PendulumTab);
            app.PendulumTable.ColumnName = {'Mi'; 'Li'; 'Hi'; 'CMi'};
            app.PendulumTable.RowName = {'{}'};
            app.SpringTab = uitab(app.OutputTabGroup);
            app.SpringTab.Title = 'Spring';
            app.SpringTable = uitable(app.SpringTab);
            app.SpringTable.ColumnName = {'Mi'; 'Hi'; 'Ki'};
            app.SpringTable.RowName = {'{}'};
            app.DampingTab = uitab(app.OutputTabGroup);
            app.DampingTab.Title = 'Damping';
            app.SelectCorrelationDropDownLabel = uilabel(app.DampingTab);
            app.SelectCorrelationDropDownLabel.Text = 'Select Correlation';
            app.DampingCorrelationDropDown = uidropdown(app.DampingTab);
            app.DampingCorrelationDropDown.Items = {'Cylindrical (Stephens)', 'Cylindrical (Mikishev, NASA SP-106)'};
            app.DampingCorrelationDropDown.ValueChangedFcn = createCallbackFcn(app, @DampingCorrelationChanged, true);
            app.DampingCorrelationDropDown.Value = 'Cylindrical (Stephens)';
            app.SelectGeometryDropDownLabel = uilabel(app.DampingTab);
            app.SelectGeometryDropDownLabel.Text = 'Select Geometry';
            app.DampingGeometryDropDown = uidropdown(app.DampingTab);
            app.DampingGeometryDropDown.Items = {'Cylindrical', 'Spherical', 'Cylindrical with Baffles', 'Toroidal', 'Conical'};
            app.DampingGeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @DampingGeometryChanged, true);
            app.DampingGeometryDropDown.Value = 'Cylindrical';
            app.CorrelationReferenceLabel = uilabel(app.DampingTab);
            app.CorrelationReferenceLabel.Text = 'Reference: D. G. Stephens, L. H. Wayne, and T. W. Perry, Investigation of the damping of liquids in right-circular cylindrical tanks, including the effects of a time-variant liquid depth, pp. 1, Tech. Rep. TN-D-1367, N62-14069, NASA (July 1962).';
            app.CorrelationReferenceHyperlink = uihyperlink(app.DampingTab);
            app.CorrelationReferenceHyperlink.URL = 'https://ntrs.nasa.gov/citations/19620004069';
            app.CorrelationReferenceHyperlink.Text = 'Link to Correlation Reference';
            app.DampingViscosityLabel = uilabel(app.DampingTab);
            app.DampingViscosityLabel.Text = '𝜈';
            app.DampingViscosityOutput = uilabel(app.DampingTab);
            app.DampingViscosityOutput.Text = '';
            app.DampingAccelerationLabel = uilabel(app.DampingTab);
            app.DampingAccelerationLabel.Text = 'g';
            app.DampingAccelerationOutput = uilabel(app.DampingTab);
            app.DampingAccelerationOutput.Text = '';
            app.DampingRatioOutput = uilabel(app.DampingTab);
            app.DampingRatioOutput.Text = '';
            app.DampingValidityText = uilabel(app.DampingTab);
            app.DampingValidityText.Text = '';
            app.DampinghRLabel = uilabel(app.DampingTab);
            app.DampinghRLabel.Text = '(h/R)';
            app.DampinghRValue = uilabel(app.DampingTab);
            app.DampinghRValue.Text = '';
            app.BaffleWidthEditField = uieditfield(app.DampingTab, 'numeric');
            app.BaffleWidthEditField.Limits = [0 Inf];
            app.BaffleWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BaffleWidthChanged, true);
            app.BaffleWidthEditField.Visible = 'off';
            app.ScalingFactorEditField = uieditfield(app.DampingTab, 'numeric');
            app.ScalingFactorEditField.Limits = [0 Inf];
            app.ScalingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingFactorChanged, true);
            app.ScalingFactorEditField.Visible = 'off';
            app.ScalingFactorEditField.Value = 1.55;
            app.BaffleSpacingEditField = uieditfield(app.DampingTab, 'numeric');
            app.BaffleSpacingEditField.Limits = [0 Inf];
            app.BaffleSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @BaffleSpacingChanged, true);
            app.BaffleSpacingEditField.Visible = 'off';
            app.TopBaffleDepthEditField = uieditfield(app.DampingTab, 'numeric');
            app.TopBaffleDepthEditField.Limits = [0 Inf];
            app.TopBaffleDepthEditField.ValueChangedFcn = createCallbackFcn(app, @TopBaffleDepthChanged, true);
            app.TopBaffleDepthEditField.Visible = 'off';
            app.SubmergedBafflesSpinner = uispinner(app.DampingTab);
            app.SubmergedBafflesSpinner.Limits = [0 Inf];
            app.SubmergedBafflesSpinner.RoundFractionalValues = 'on';
            app.SubmergedBafflesSpinner.ValueChangedFcn = createCallbackFcn(app, @SubmergedBafflesChanged, true);
            app.SubmergedBafflesSpinner.Visible = 'off';
            app.SubmergedBafflesSpinner.Value = 1;
            app.BaffleWidthLabel = uilabel(app.DampingTab);
            app.BaffleWidthLabel.Visible = 'off';
            app.BaffleWidthLabel.Text = 'Baffle Width';
            app.ScalingFactorLabel = uilabel(app.DampingTab);
            app.ScalingFactorLabel.Visible = 'off';
            app.ScalingFactorLabel.Text = 'Scaling Factor';
            app.BaffleSpacingLabel = uilabel(app.DampingTab);
            app.BaffleSpacingLabel.Visible = 'off';
            app.BaffleSpacingLabel.Text = 'Baffle Spacing';
            app.TopBaffleDepthLabel = uilabel(app.DampingTab);
            app.TopBaffleDepthLabel.Visible = 'off';
            app.TopBaffleDepthLabel.Text = 'Top Baffle Depth';
            app.SubmergedBafflesLabel = uilabel(app.DampingTab);
            app.SubmergedBafflesLabel.Visible = 'off';
            app.SubmergedBafflesLabel.Text = 'Submerged Baffles';
            app.DampingViscosityUnitLabel = uilabel(app.DampingTab);
            app.DampingViscosityUnitLabel.Text = 'm2/s';
            app.DampingRadiusUnitLabel = uilabel(app.DampingTab);
            app.DampingRadiusUnitLabel.Text = 'm';
            app.DampingBaffleWidthUnitLabel = uilabel(app.DampingTab);
            app.DampingBaffleWidthUnitLabel.Visible = 'off';
            app.DampingBaffleWidthUnitLabel.Text = 'm';
            app.DampingBaffleDepthUnitLabel = uilabel(app.DampingTab);
            app.DampingBaffleDepthUnitLabel.Visible = 'off';
            app.DampingBaffleDepthUnitLabel.Text = 'm';
            app.DampingFillHeightUnitLabel = uilabel(app.DampingTab);
            app.DampingFillHeightUnitLabel.Text = 'm';
            app.DampingBaffleSpacingUnitLabel = uilabel(app.DampingTab);
            app.DampingBaffleSpacingUnitLabel.Visible = 'off';
            app.DampingBaffleSpacingUnitLabel.Text = 'm';
            app.DampingAccelerationUnitLabel = uilabel(app.DampingTab);
            app.DampingAccelerationUnitLabel.Text = 'm2/s';
            app.BaffleDampingRatioLabel = uilabel(app.DampingTab);
            app.BaffleDampingRatioLabel.Visible = 'off';
            app.BaffleDampingRatioLabel.Interpreter = 'latex';
            app.BaffleDampingRatioLabel.Text = '\mathbf{\frac{Damping Ratio}{\sqrt{{Slosh Amplitude} / {Radius}}}}';
            app.DampingRatioLabel = uilabel(app.DampingTab);
            app.DampingRatioLabel.Text = 'Damping Ratio';
            app.DampingConeAngleUnitLabel = uilabel(app.DampingTab);
            app.DampingConeAngleUnitLabel.Visible = 'off';
            app.DampingConeAngleUnitLabel.Text = 'deg';
            app.DampingToroidMinorRadiusEditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingToroidMinorRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ToroidRadiusChanged, true);
            app.DampingToroidMinorRadiusEditField.Visible = 'off';
            app.DampingConeAngleEditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingConeAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ConeAngleChanged, true);
            app.DampingConeAngleEditField.Visible = 'off';
            app.DampingRadiusEditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @DampingRadiusChanged, true);
            app.FillHeightLabel = uilabel(app.DampingTab);
            app.FillHeightLabel.Text = 'Fill Height';
            app.DampingFillHeightEditField = uieditfield(app.DampingTab, 'numeric');
            app.DampingFillHeightEditField.ValueChangedFcn = createCallbackFcn(app, @DampingFillHeightChanged, true);
            app.DampingRadiusLabel = uilabel(app.DampingTab);
            app.DampingRadiusLabel.Text = 'Tank Radius';
            app.ConeAngleEditFieldLabel = uilabel(app.DampingTab);
            app.ConeAngleEditFieldLabel.Visible = 'off';
            app.ConeAngleEditFieldLabel.Text = 'Cone Angle';
            app.AngleValidityLabel = uilabel(app.DampingTab);
            app.AngleValidityLabel.Visible = 'off';
            app.AngleValidityLabel.Text = 'Angle (deg)';
            app.CALCULATE = uibutton(app.SLOSHMLv10UIFigure, 'push');
            app.CALCULATE.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CALCULATE.Enable = 'off';
            app.CALCULATE.Text = 'CALCULATE';
            app.DisplayGeometryPanel = uipanel(app.SLOSHMLv10UIFigure);
            app.TankGeometryCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.TankGeometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeTankGeometryCheckBox, true);
            app.TankGeometryCheckBox.Enable = 'off';
            app.TankGeometryCheckBox.Text = 'Tank Geometry';
            app.TankGeometryCheckBox.Value = true;
            app.CenterofMassCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.CenterofMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeCenterofMassCheckBox, true);
            app.CenterofMassCheckBox.Enable = 'off';
            app.CenterofMassCheckBox.Text = 'Center of Mass';
            app.CenterofMassCheckBox.Value = true;
            app.LiquidFillLineCheckBox = uicheckbox(app.DisplayGeometryPanel);
            app.LiquidFillLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeLiquidFillLineCheckBox, true);
            app.LiquidFillLineCheckBox.Enable = 'off';
            app.LiquidFillLineCheckBox.Text = 'Liquid Fill Line';
            app.LiquidFillLineCheckBox.Value = true;
            app.DisplayModesPanel = uipanel(app.SLOSHMLv10UIFigure);
            app.NumberofModesSpinner_2Label = uilabel(app.DisplayModesPanel);
            app.NumberofModesSpinner_2Label.Text = 'Number of Modes';
            app.NumberofModesSpinner = uispinner(app.DisplayModesPanel);
            app.NumberofModesSpinner.ValueChangedFcn = createCallbackFcn(app, @changeNumberofModesSpinner, true);
            app.NumberofModesSpinner.Editable = 'off';
            app.NumberofModesSpinner.Enable = 'off';
            app.MultiplicationFactorLabel = uilabel(app.DisplayModesPanel);
            app.MultiplicationFactorLabel.Text = 'Multiplication Factor';
            app.MultiplicationFactorEditField = uieditfield(app.DisplayModesPanel, 'numeric');
            app.MultiplicationFactorEditField.Limits = [0 Inf];
            app.MultiplicationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @changeMultiplicationFactorEditField, true);
            app.MultiplicationFactorEditField.Enable = 'off';
            app.MultiplicationFactorEditField.Value = 100;
            app.Label_9 = uilabel(app.DisplayModesPanel);
            app.Label_9.Text = '%';
            app.NormalizeCheckBox = uicheckbox(app.DisplayModesPanel);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxChanged, true);
            app.NormalizeCheckBox.Enable = 'off';
            app.NormalizeCheckBox.Text = 'Normalize';
            app.DisplayMechanicalPanel = uipanel(app.SLOSHMLv10UIFigure);
            app.MechanicalAnalogyDropDownLabel = uilabel(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyDropDownLabel.Text = 'Mechanical Analogy';
            app.MechanicalAnalogyDropDown = uidropdown(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyDropDown.Items = {'None', 'Spring', 'Pendulum'};
            app.MechanicalAnalogyDropDown.ValueChangedFcn = createCallbackFcn(app, @changeMechanicalAnalogyDropDown, true);
            app.MechanicalAnalogyDropDown.Enable = 'off';
            app.MechanicalAnalogyDropDown.Value = 'Spring';
            app.NumberofModesSpinner_2Label_2 = uilabel(app.DisplayMechanicalPanel);
            app.NumberofModesSpinner_2Label_2.Text = 'Number of Modes';
            app.MechanicalAnalogyCountSpinner = uispinner(app.DisplayMechanicalPanel);
            app.MechanicalAnalogyCountSpinner.Limits = [-1 Inf];
            app.MechanicalAnalogyCountSpinner.ValueChangedFcn = createCallbackFcn(app, @changeMechanicalAnalogyCountSpinner, true);
            app.MechanicalAnalogyCountSpinner.Enable = 'off';
            app.MechanicalAnalogyCountSpinner.Value = -1;
            app.WelcomeImage = uiimage(app.SLOSHMLv10UIFigure);
            app.WelcomeImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'welcome-screen.jpg');
            % Show the figure after all components are created
            app.SLOSHMLv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLOSH_ML

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLOSHMLv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLOSHMLv10UIFigure)
        end
    end
end